classdef app_R2_5_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MulticellularModelSimulatorUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SavetrajectoryMenu             matlab.ui.container.Menu
        LoadtrajectoryMenu             matlab.ui.container.Menu
        SavefigurespdfMenu             matlab.ui.container.Menu
        SavefiguresepsMenu             matlab.ui.container.Menu
        SavemovieaviMenu               matlab.ui.container.Menu
        SavesystemstatexlsMenu         matlab.ui.container.Menu
        CloseallfiguresMenu            matlab.ui.container.Menu
        CloseappMenu                   matlab.ui.container.Menu
        SimulationMenu                 matlab.ui.container.Menu
        RunMenu                        matlab.ui.container.Menu
        PauseMenu                      matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        MovetostartMenu                matlab.ui.container.Menu
        BackstepMenu                   matlab.ui.container.Menu
        ForwardstepMenu                matlab.ui.container.Menu
        MovetoendMenu                  matlab.ui.container.Menu
        SkiptotimeMenu                 matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        KymographMenu                  matlab.ui.container.Menu
        MeangeneexpressionptMenu       matlab.ui.container.Menu
        PopulationfractionspijtbinaryMenu  matlab.ui.container.Menu
        SpatialautocorrelationItMenu   matlab.ui.container.Menu
        SpatialautocorrelationThetatMenu  matlab.ui.container.Menu
        SpatialcrosscorrelationMenu    matlab.ui.container.Menu
        DtrajectoryptItMenu            matlab.ui.container.Menu
        DtrajectoryptThetatMenu        matlab.ui.container.Menu
        PseudoenergyhtMenu             matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        ShowIMenu                      matlab.ui.container.Menu
        InitiatewithIt0Menu            matlab.ui.container.Menu
        ManuallyinputinitialstateMenu  matlab.ui.container.Menu
        PropertiesMenu                 matlab.ui.container.Menu
        CircuittopologyMenu            matlab.ui.container.Menu
        PhasediagramMenu               matlab.ui.container.Menu
        StatediagramsinglecellMenu     matlab.ui.container.Menu
        StatediagramfullsystemMenu     matlab.ui.container.Menu
        CellcolorlegendMenu            matlab.ui.container.Menu
        PeriodicitytestMenu            matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        Version25Menu                  matlab.ui.container.Menu
        ButtonPlay                     matlab.ui.control.Button
        ButtonStop                     matlab.ui.control.Button
        ButtonPause                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        signalTab                      matlab.ui.container.Tab
        GridsizeLabel                  matlab.ui.control.Label
        GridsizeEditField              matlab.ui.control.NumericEditField
        a0EditFieldLabel               matlab.ui.control.Label
        a0EditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel                matlab.ui.control.Label
        KEditField                     matlab.ui.control.NumericEditField
        ConEditFieldLabel              matlab.ui.control.Label
        ConEditField                   matlab.ui.control.NumericEditField
        pt0EditFieldLabel              matlab.ui.control.Label
        pt0EditField                   matlab.ui.control.NumericEditField
        NoiseEditFieldLabel            matlab.ui.control.Label
        NoiseEditField                 matlab.ui.control.NumericEditField
        HillcoefficientEditFieldLabel  matlab.ui.control.Label
        HillcoefficientEditField       matlab.ui.control.NumericEditField
        InitialstateLabel              matlab.ui.control.Label
        It0EditFieldLabel              matlab.ui.control.Label
        It0EditField                   matlab.ui.control.NumericEditField
        InteractionDropDownLabel       matlab.ui.control.Label
        InteractionDropDown            matlab.ui.control.DropDown
        ParametersLabel                matlab.ui.control.Label
        CellsizeEditFieldLabel         matlab.ui.control.Label
        CellsizeEditField              matlab.ui.control.NumericEditField
        signalsTab                     matlab.ui.container.Tab
        GridsizeLabel_2                matlab.ui.control.Label
        GridsizeEditField_2            matlab.ui.control.NumericEditField
        a0EditField_2Label             matlab.ui.control.Label
        a0EditField_2                  matlab.ui.control.NumericEditField
        Lambda12EditFieldLabel         matlab.ui.control.Label
        Lambda12EditField              matlab.ui.control.NumericEditField
        InteractionsPanel              matlab.ui.container.Panel
        K21EditFieldLabel              matlab.ui.control.Label
        K21EditField                   matlab.ui.control.NumericEditField
        K11Label                       matlab.ui.control.Label
        K11EditField                   matlab.ui.control.NumericEditField
        K12Label                       matlab.ui.control.Label
        K12EditField                   matlab.ui.control.NumericEditField
        K22Label                       matlab.ui.control.Label
        K22EditField                   matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        DropDown12                     matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        DropDown21                     matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDown22                     matlab.ui.control.DropDown
        Con1EditFieldLabel             matlab.ui.control.Label
        Con1EditField                  matlab.ui.control.NumericEditField
        Con2EditFieldLabel             matlab.ui.control.Label
        Con2EditField                  matlab.ui.control.NumericEditField
        SecretionratesLabel            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DropDown11                     matlab.ui.control.DropDown
        ThresholdsLabel                matlab.ui.control.Label
        NoiseEditField_2Label          matlab.ui.control.Label
        NoiseEditField_2               matlab.ui.control.NumericEditField
        HillcoefficientEditField_2Label  matlab.ui.control.Label
        HillcoefficientEditField_2     matlab.ui.control.NumericEditField
        InitialconditionsPanel         matlab.ui.container.Panel
        p1t0EditFieldLabel             matlab.ui.control.Label
        p1t0EditField                  matlab.ui.control.NumericEditField
        p2t0EditFieldLabel             matlab.ui.control.Label
        p2t0EditField                  matlab.ui.control.NumericEditField
        I1t0EditFieldLabel             matlab.ui.control.Label
        I1t0EditField                  matlab.ui.control.NumericEditField
        I2t0EditFieldLabel             matlab.ui.control.Label
        I2t0EditField                  matlab.ui.control.NumericEditField
        ParametersLabel_2              matlab.ui.control.Label
        CellsizeEditField_2Label       matlab.ui.control.Label
        CellsizeEditField_2            matlab.ui.control.NumericEditField
        MessagesTextAreaLabel          matlab.ui.control.Label
        MessagesTextArea               matlab.ui.control.TextArea
        ButtonToStart                  matlab.ui.control.Button
        ButtonBack                     matlab.ui.control.Button
        ButtonFwd                      matlab.ui.control.Button
        ButtonToEnd                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SpeedSlider                    matlab.ui.control.Slider
        SimulationspeedLabel           matlab.ui.control.Label
        RandomizeLabel                 matlab.ui.control.Label
        MonteCarlostepsLabel           matlab.ui.control.Label
        MonteCarlostepsDropDown        matlab.ui.control.DropDown
        DisplaysignalButtonGroup       matlab.ui.container.ButtonGroup
        Button1                        matlab.ui.control.ToggleButton
        Button2                        matlab.ui.control.ToggleButton
        Button12                       matlab.ui.control.ToggleButton
        MovingcellsLabel               matlab.ui.control.Label
        Sigma_DEditFieldLabel          matlab.ui.control.Label
        Sigma_DEditField               matlab.ui.control.NumericEditField
        GrowingcellsLabel              matlab.ui.control.Label
        c_growthEditFieldLabel         matlab.ui.control.Label
        c_growthEditField              matlab.ui.control.NumericEditField
        k_growthEditFieldLabel         matlab.ui.control.Label
        k_growthEditField              matlab.ui.control.NumericEditField
        sigma_rcellEditFieldLabel      matlab.ui.control.Label
        sigma_rcellEditField           matlab.ui.control.NumericEditField
        InitialconfigurationLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        % app control variables
        SelectedTab = 1; % currently selected tab (set to app.TabGroup.SelectedTab later)
        
        % simulation control flow
        ContinueSimulation = 1; %When set to 0, simulation stops
        NewSimulation = 1; %When set to 0, simulation continues where it left off.
        ReplayTrajectory = 0; % When set to 1, simulator will show a loaded trajectory
        Changed = 1; %When set to 0, equilibrium state reached
        showI = 0; % Whether to show I on top of simulation (slower)
        InitiateI = 0; % Whether to initiate the system with given I
        ManualInputInitialState = 0; % Whether to manually input an initial state
        
        % simulation variables
        Time = 0;
        CellsOut = [];
        CellsHist = {}; % store trajectories
        %TimeFinal = 0; % final time of loaded simulation
        
        % Figures
        % num_fig = 10; %number of figures in app
        h_cells = []; % handle for cell lattice plot (scatter)
        %h_borders = []; % handle for cell lattice borders (scatter)
        FigureHandles = {}; % stores all handles to plot figures
        a0_fig = NaN; % a0 in terms of figure sizes (pixels), depends on lattice
        pix2pts = NaN; % conversion factor from pixels to points, depends on operating system
        
        % I/O
        DefaultSaveFolder = ''; 
        InputStateSaveFolder = ''; 
        
        % simulation constants
        rCell = NaN;
        tmax = 10^6;
        precision = 8; %precision for continuous system
        dI = 0.01; % Maximum allowed range for generated I (e.g. generate I within [I_min, I_min+dI]
        
        FigurePosition = [0.1 0.1 7 5]; % Default position and size of plotted figures
        SimID = {'one_signal', 'two_signal_mult', 'two_signal_add'}; % labels for simulation properties / tabs
        
        % save positions and distances
        dist = []; % distance matrix
        pos = [];  % position vector
        pos_hist = {}; % cell array storing all positions of all cells over time (for moving cells)
        rcell_all = []; % vector of all radii of cells
        rcellHist = {}; % cell array storing all cell sizes of all cells over time (for growing cells)        
        
    end

    methods (Access = private)
        function reset_cell_figure(app)
            ax = app.UIAxes;
            cla(ax);
            title(ax, 'Simulate dynamics', 'FontSize', 20);
            
            % set image properties
            set(ax, 'YTick', [], 'XTick', []);
            
            %}
        end
        
        function generate_lattice(app, gz, mcsteps, new_lattice, hex_lattice)
            %hex_lattice: whether to plot lattice in the old way
            ax = app.UIAxes;
            N = gz^2;
            hold(ax, 'on')
            if nargin<5
                hex_lattice = 0;
            end            
            
            if new_lattice && mcsteps>0
                app.MessagesTextArea.Value{1} = 'Generating lattice...';
                pause(0.01);
                [app.pos, app.dist, ~, rejections] = initial_cells_random_markov_periodic(gz, mcsteps, app.rCell);
                app.MessagesTextArea.Value{2} = sprintf('MC steps: %d, rejections = %d', mcsteps, rejections);
            elseif new_lattice && mcsteps==0
                %[app.pos, app.dist, ~, ~] = initial_cells_random_markov_periodic(gz, mcsteps, app.rCell);
                [app.dist, app.pos] = init_dist_hex_new(gz, mcsteps, app.rCell);
            end
            
            % Plot lattice: (1) hexagonal old style, (2) new style box
            if hex_lattice
                warning('Hexagonal lattice');
                
                % Show hexagonal lattice
                [app.dist, app.pos] = init_dist_hex(gz, gz);
                
                % set image properties
                set(ax, 'YTick', [], 'XTick', []);
                xlim(ax, [-1 app.pos(end,1)+1]);
                ylim(ax, [-1 app.pos(end,2)+1]);
                
                %% calculate figure dimensions
                N = size(app.pos,1);
                %gz = sqrt(N);
                Lx = app.UIAxes.InnerPosition(3); %512;
                %Ly = (sqrt(3)/2*(gz-1)+2)/(3/2*(gz+1))*Lx;
                app.a0_fig = Lx/(3/2*(gz+1));
                Rcell = app.rCell*app.a0_fig;
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'Lx = ', num2str(Lx));
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'a0 = ', num2str(a0));
                
                % --plot cells--
                % colours
                c_all = ones(N, 3);
                clr_k = zeros(N, 3); % black boundaries
                %markers = {'o', 's'};
                
                app.h_cells = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, c_all, 'filled', 'o', 'MarkerEdgeColor', [0 0 0]);
                %app.h_borders = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, clr_k, 'o'); % plot cell boundaries
            else 
                % Generate new lattice
                d = 1.5*app.rCell*1/(gz); % offset in lattice
                
                % set box aspect ratio
                %pbaspect(ax, [1 sqrt(3)/2 1])
                %
                set(ax, 'PlotBoxAspectRatioMode', 'manual');
                set(ax, 'PlotBoxAspectRatio', [1+2*d sqrt(3)/2+2*d 1]);
                %pbaspect(ax, [1+2*d sqrt(3)/2+2*d 1])
                %}
                %{
                fig_pos = app.UIAxes.Position;
                fig_width = 500;
                set(ax, 'Position', [fig_pos(1) fig_pos(2) fig_width sqrt(3)/2*fig_width]);
                %}
                
                % Set limits
                xlim(ax, [-d 1+d]);
                ylim(ax, [-d sqrt(3)/2+d]);
                
                %% calculate figure dimensions
                Sx = app.UIAxes.InnerPosition(3); %512;
                %Sy = (sqrt(3)/2*(gz-1)+2)/(3/2*(gz+1))*Lx;
                app.a0_fig = Sx/(1+2*d)/(gz);
                %}
                Rcell = app.rCell*app.a0_fig; % units: pixels
                
                % conversion from pixels to points: depends on operating system
                % calculated in Startup function  
                Rcell = Rcell*app.pix2pts; 
    
                % --plot cells--
                % colours
                c_all = ones(N, 3); % default: white cells
                app.h_cells = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, c_all, 'filled', 'o', 'MarkerEdgeColor', 'none'); %[0 0 0]);
                % app.h_borders = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, zeros(N,3), 'o'); % plot cell boundaries, not needed
                
                % Plot box outline
                Lx = 1; Ly = sqrt(3)/2;
                plot(ax,[0 Lx], [0 0], 'k--');
                plot(ax,[0 Lx], [Ly Ly], 'k--');
                plot(ax,[0 0], [0 Ly], 'k--');
                plot(ax,[Lx Lx], [0 Ly], 'k--');
                
                hold(ax, 'off');
            end
            
            % get plot aspect ratio
            % app.MessagesTextArea.Value{end+1} = num2str( app.UIAxes.InnerPosition(4)/Sx );
        end
        
        function update_cell_figure_continuum(app, pos, dist, a0, cells, t, disp_mol)
            ax = app.UIAxes;
            if disp_mol==12 % plot both colors
                % no signal -> white
                % signal 1 -> yellow
                % signal 2 -> blue 
                % signals 1&2 -> black
                
                % plot title
                if app.showI %whether or not to display I (slow down simulation)
                    %title(ax, sprintf('t=%d, p = %.2f', t, mean(cells)), ...
                    %    'FontSize', 20);
                    this_p = mean(cells, 1);
                    this_I = [moranI(cells(:, 1), a0*dist) moranI(cells(:, 2), a0*dist)];
                    title(ax, sprintf('t=%d, p1 = %.2f, p2 = %.2f, I1 = %.2f, I2 = %.2f', t,...
                        this_p(1), this_p(2), this_I(1), this_I(2)), ...
                        'FontSize', 20);
                else
                    this_p = mean(cells, 1);
                    title(ax, sprintf('t=%d, p1 = %.2f, p2 = %.2f', t, this_p(1), this_p(2)), ...
                        'FontSize', 20);
                end
                
                % --update cells--
                clrs = 1-cells;
                c_all = zeros(size(cells, 1), 3); 
                c_all(:, 3) = clrs(:, 1); % signal 1 present -> Turn on blue channel
                c_all(:, 1) = clrs(:, 2); % signal 2 present -> Turn on red channel
                c_all(:, 2) = clrs(:, 1) & clrs(:, 2); % signals 1 & 2 present -> Turn on green channel
            else
                % show molecule number (not optimized)
                i_str = ''; 
                if ~isempty(disp_mol)
                    cells = cells(:, disp_mol);
                    i_str = num2str(disp_mol); 
                end
                
                % plot title
                %
                if app.showI %whether or not to display I (slow down simulation)
                    %title(ax, sprintf('t=%d, p = %.2f', t, mean(cells)), ...
                    %    'FontSize', 20);
                    this_p = mean(cells);
                    this_I = moranI(cells, a0*dist);
                    title(ax, sprintf('t=%d, p%s = %.2f, I%s = %.2f', t, i_str, this_p, i_str, this_I), ...
                        'FontSize', 20);
                else
                    this_p = mean(cells);
                    title(ax, sprintf('t=%d, p%s = %.2f', t, i_str, this_p), ...
                        'FontSize', 20);
                end
                %}
                c_all = repmat(1-cells, 1, 3);
            end
            
            % Efficient code: update cell colors only
            %set(app.h_cells, 'CData', c_all);
            %
            %hold(ax, 'on');
            app.h_cells.CData = c_all;
            %app.h_cells.Marker = 'd';
            %hold(ax, 'off');
            %app.MessagesTextArea.Value = num2str(app.h_cells.CData(1,:));
            %}
            
            % Update cell positions
            set(app.h_cells, 'xdata', pos(:, 1), 'ydata', pos(:, 2) );
            %set(h_borders, 'xdata', app.pos(:, 1), 'ydata', app.pos(:, 2) );
            
            % Update cell radii
            if ~isempty(app.rcell_all)
                Rcell_all = app.a0_fig*app.pix2pts*app.rcell_all;
                set(app.h_cells, 'sizedata', Rcell_all.^2 );
                %set(app.h_borders, 'sizedata', app.rcell_all );  
            end
            
            % pause before plotting next frame
            pauseTime = 1/app.SpeedSlider.Value;
            pause(pauseTime);
        end
        %}
        
        function simulate_cells_continuous(app, N, M_int, a0, Rcell, Con, K, noise, hill, prec, p0, I0, sigma_D,...
                mcsteps, c_growth, k_growth, sigma_rcell, new_sim)
            gz = sqrt(N);
            %sigma_rcell = app.sigma_rcellEditField.Value;
            
            if new_sim % if starting new simulation
                % check that c_growth and k_growth are compatible
                
                
                app.MessagesTextArea.Value = 'New simulation;';
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; continuous states');
                
                % reset variables 
                reset_app_vars(app);
                
                % generate new lattice
                app.MessagesTextArea.Value{end+1} = 'Generating new lattice...';
                %mcsteps = str2double(app.MonteCarlostepsDropDown.Value);
                %mcsteps = floor(mcsteps);
                %app.MessagesTextArea.Value{end+1} = sprintf('MC steps: %d', mcsteps);
                new_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice);
                
                % initiate cell states
                if app.ManualInputInitialState
                    % manual input state
                    signal_count = 1;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                elseif hill<Inf
                    % continuous cells
                    cells = init_random_cells_montecarlo(N, p0);
                else
                    % binary cells
                    iniON = round(p0*N);
                    cells = zeros(N, 1);
                    cells(randperm(N, iniON)) = 1; 
                    if app.InitiateI
                        app.MessagesTextArea.Value{end+1} = 'Generating lattice with I(t=0)...';
                        pause(0.01);
                        [cells, test, I_ini, t_out] = generate_I_new(cells, I0, I0+app.dI, app.dist, a0);
                        app.MessagesTextArea.Value{end+1} = sprintf('Generated I: %.2f; in range (Y=1/N=0)? %d, t_out = %d;', I_ini, test, t_out);
                        %app.MessagesTextArea.Value{end+1} = sprintf('Generated initial I: %.2f; in range (Y=1/N=0)? %d;', I_ini, test);
                    end
                end
                
                % Generate different cell radii, store radii
                if sigma_rcell>0
                    app.rcell_all = app.rCell + randn(N, 1)*sigma_rcell*app.rCell;
                    % if system generates negative radii, retry until all radii are positive
                    retry = ~all( app.rcell_all>0 );
                    trials = 0;
                    while retry && trials < 10^4
                        idx = app.rcell_all<0;
                        app.rcell_all( idx ) = app.rCell + randn(sum(idx), 1)*sigma_rcell*app.rCell;
                        retry = ~all( app.rcell_all>0 );
                        trials = trials + 1;
                    end
                    % if after 10^4 trials radii are still negative, abort
                    if retry
                        error('Initial cell radii negative!');
                        app.MessagesTextArea.Value{end+1} = sprintf('Initial cell radii negative after %d trials', trials);
                        return
                    end
                    % app.MessagesTextArea.Value{end+1} = sprintf('Trials = %d', trials);
                end
                
                % store variables
                app.CellsHist{end+1} = cells;
                if sigma_D>0
                    app.pos_hist{end+1} = app.pos;
                end
                if sigma_rcell>0
                    app.rcellHist{end+1} = app.rcell_all;
                end
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, [])
                [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                    cells, app.dist, M_int, Con, K, a0, Rcell, noise, hill, prec); 
                
                % update positions and radii
                [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
                app.rcell_all = update_cell_sizes(app, app.rcell_all, c_growth, k_growth);
            elseif (app.Changed && app.Time < app.tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
            end
            
            while app.Time < app.tmax && app.ContinueSimulation
                % update variables
                app.Time = app.Time+1;
                app.CellsHist{end+1} = app.CellsOut;
                if sigma_D>0
                    app.pos_hist{end+1} = app.pos;
                end
                if ~isempty(app.rcellHist)
                    app.rcellHist{end+1} = app.rcell_all;
                end
                cells = app.CellsOut;
                
                % update figure
                update_cell_figure_continuum(app, app.pos, app.dist, a0, app.CellsOut, app.Time, [])
                
                % update cells
                try 
                    [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                        cells, app.dist, M_int, Con, K, a0, Rcell, noise, hill, prec);  
                catch
                    error('Error in update_cells_noise_hill. Check source code.');
                end
                
                % update positions and radii
                [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
                [app.rcell_all, rcellChanged] = update_cell_sizes(app, app.rcell_all, c_growth, k_growth);
                
                % stop simulation when cells don't change anymore, only if cells are not moving
                if sigma_D==0 && ~app.Changed && ~rcellChanged
                    break
                end
                    
            end
            
            if app.Time == app.tmax
                app.MessagesTextArea.Value{end+1} = 'max. simulation time reached; ';
            end
        end
    
        function simulate_cells_two_signals_multiply(app, N, M_int, lambda12, a0, Rcell,...
                Con, Coff, K, hill, noise, p0, I0, sigma_D, mcsteps, c_growth, k_growth, sigma_rcell, new_sim, cells_in, disp_mol)
            gz = sqrt(N);
            lambda = [1 lambda12];
            %sigma_rcell = app.sigma_rcellEditField.Value;
            %app.MessagesTextArea.Value{end+1} = sprintf('%d', cells_in);
            %pause(1);
                
            if new_sim % if starting new simulation
                app.MessagesTextArea.Value = 'New simulation;';
                
                % reset app variables
                reset_app_vars(app);
                
                % generate new lattice
                app.MessagesTextArea.Value{end+1} = 'Generating new lattice...';
                %mcsteps = str2double(app.MonteCarlostepsDropDown.Value);
                %mcsteps = floor(mcsteps);
                %app.MessagesTextArea.Value{end+1} = sprintf('MC steps: %d', mcsteps);
                new_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice);
                
                % generate initial lattice
                if app.ManualInputInitialState
                    % Manually input initial state
                    signal_count = 2;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                else
                    % Generate random lattice
                    iniON = round(p0*N);
                    cells = zeros(N, 2);
                    for i=1:2
                        cells(randperm(N,iniON(i)), i) = 1;
                        if app.InitiateI && hill==Inf
                            app.MessagesTextArea.Value{end+1} = sprintf('Generating lattice with I%d(t=0)...', i);
                            pause(0.01);
                            [cells_temp, test, I_ini] = generate_I_new(cells(:, i), I0(i), I0(i)+app.dI, app.dist, a0);
                            
                            cells(:,i) = cells_temp;
                            app.MessagesTextArea.Value{end+1} = sprintf('Generated initial I%d: %.2f; in range (Y=1/N=0)? %d;', i, I_ini, test);
                        end
                    end
                end
                
                % Generate different cell radii
                if sigma_rcell>0
                    app.rcell_all = app.rCell + randn(N, 1)*sigma_rcell*app.rCell;
                end
                                
                % store initial config
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                if sigma_D>0
                    app.pos_hist{end+1} = app.pos;
                end
                if sigma_rcell>0
                    app.rcellHist{end+1} = app.rcell_all;
                end
                
                % update cells
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells, app.dist, M_int, a0,...
                    Rcell, Con, Coff, K, lambda, hill, noise);
                % update positions and radii
                [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
                
                app.rcell_all = update_cell_sizes(app, app.rcell_all, c_growth, k_growth);   
            elseif (app.Changed && app.Time < app.tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
                app.CellsOut = cells_in;
            end
            
            %app.MessagesTextArea.Value{end+1} = sprintf('%d', app.CellsOut);
            %pause(1);
            
            while app.Time < app.tmax && app.ContinueSimulation
                % update variables
                app.Time = app.Time+1;
                cells = app.CellsOut;
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                if sigma_D>0
                    app.pos_hist{end+1} = app.pos;
                end
                if ~isempty(app.rcellHist)
                    app.rcellHist{end+1} = app.rcell_all;
                end
                
                % update figure
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                
                % update cells
                try
                    [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells, app.dist, M_int, a0,...
                        Rcell, Con, Coff, K, lambda, hill, noise);
                catch
                    app.MessagesTextArea.Value = 'Error in update_cells_two_signals_multiply_finite_Hill. Check source code.';
                end
                % update positions and radii
                [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
       
                app.rcell_all = update_cell_sizes(app, app.rcell_all, c_growth, k_growth);            
                % stop simulation when cells don't change anymore, only if cells are not moving
                if sigma_D==0 && ~app.Changed
                    break
                end
            end
            
            if app.Time == app.tmax
                app.MessagesTextArea.Value{end+1} = 'max. simulation time reached; ';
            end
            
        end

        function M_int = interactionDropDownConvert(app, input)
            % converts values from dropdown menu into booleans
            if strcmp(input, 'Activation')
                M_int = 1; % store output as temporary value
            elseif strcmp(input, 'Repression')
                %app.tempValue = -1;
                M_int = -1;
            elseif strcmp(input, 'None')
                M_int = 0;
            else
                warning('Error message 2: interactionDropDownConvert input of the wrong type');
            end
        end
        
        function reset_app_vars(app)
            % resets the app variables that store data
            % ! do not change variables that control simulation flow !
            % HAS TO BE UPDATED BY HAND after introducing new variables
            app.Changed = 1; %When set to 0, equilibrium state reached
            app.Time = 0;
            app.CellsOut = [];
            app.CellsHist = {}; % store trajectories
            app.FigureHandles = {}; % remove figure handles
            
            %app.pos = [];
            %app.dist = [];
            app.rcell_all = [];
            
            app.pos_hist = {};
            app.rcellHist = {};
        end
        
        function [gz, a0, disp_mol, K, Con, Coff, noise, hill, p0, I0, M_int,...
                lambda12, mcsteps, sigma_rcell, sigma_D, c_growth, k_growth] = get_input_vars(app)
            tab = tab_to_num(app);
            
            % Common input variables
            mcsteps = str2double(app.MonteCarlostepsDropDown.Value);
            sigma_D = app.Sigma_DEditField.Value;
            sigma_rcell = app.sigma_rcellEditField.Value;
            c_growth = app.c_growthEditField.Value;
            k_growth = app.k_growthEditField.Value;
            
            % Tab-specific variables
            switch tab
                case 1
                    gz = app.GridsizeEditField.Value;
                    a0 = app.a0EditField.Value;
                    K = app.KEditField.Value;
                    Con = app.ConEditField.Value;
                    noise = app.NoiseEditField.Value;
                    hill = app.HillcoefficientEditField.Value;
                    p0 = app.pt0EditField.Value;
                    I0 = app.It0EditField.Value;
                    
                    M_int = interactionDropDownConvert(app, app.InteractionDropDown.Value);
                    % define irrelevant variables
                    Coff = 1;
                    disp_mol = 1;
                    lambda12 = [];
                    
                case 2
                    gz = app.GridsizeEditField_2.Value;
                    a0 = app.a0EditField_2.Value;
                    Con = [app.Con1EditField.Value app.Con2EditField.Value];
                    %Coff = [app.Coff1EditField.Value app.Coff2EditField.Value];
                    Coff = [1 1];
                    lambda12 = app.Lambda12EditField.Value;
                    noise = app.NoiseEditField_2.Value;
                    hill = app.HillcoefficientEditField_2.Value; 
                    p0 = [app.p1t0EditField.Value app.p2t0EditField.Value];
                    I0 = [app.I1t0EditField.Value app.I2t0EditField.Value];
                    
                    M_int = zeros(2,2); % do not change order of commands in next 4 lines
                    M_int(1,1) = interactionDropDownConvert(app, app.DropDown11.Value);
                    M_int(1,2) = interactionDropDownConvert(app, app.DropDown12.Value);
                    M_int(2,1) = interactionDropDownConvert(app, app.DropDown21.Value);
                    M_int(2,2) = interactionDropDownConvert(app, app.DropDown22.Value);
                    %app.tempValue = NaN; % reset temporary value
                    
                    K = zeros(2,2);
                    K(1,1) = app.K11EditField.Value;             
                    K(1,2) = app.K12EditField.Value;
                    K(2,1) = app.K21EditField.Value;
                    K(2,2) = app.K22EditField.Value;
                    
                    if app.Button1.Value
                        disp_mol = 1;
                    elseif app.Button2.Value
                        disp_mol = 2;
                    elseif app.Button12.Value
                        disp_mol = 12;
                    end
                case 3
                    gz = app.GridsizeEditField_3.Value;
                    a0 = app.a0EditField_3.Value;
                    lambda12 = app.DiffusionconstratioEditField_2.Value;
                    noise = app.NoiseEditField_3.Value;
                    hill = app.HillCoeffEditField_2.Value;
                    p0 = [app.p1t0EditField_2.Value app.p2t0EditField_2.Value];
                    I0 = [app.I1t0EditField_2.Value app.I2t0EditField_2.Value];
                    
                    M_int = zeros(2,2); % do not change order of commands in next 4 lines
                    M_int(1,1) = interactionDropDownConvert(app, app.DropDown11.Value);
                    M_int(1,2) = interactionDropDownConvert(app, app.DropDown12.Value);
                    M_int(2,1) = interactionDropDownConvert(app, app.DropDown21.Value);
                    M_int(2,2) = interactionDropDownConvert(app, app.DropDown22.Value);
                    
                    K = zeros(2,2);
                    K(1,1) = app.K11EditField.Value;
                    K(1,2) = app.K12EditField.Value;
                    K(2,1) = app.K21EditField.Value;
                    K(2,2) = app.K22EditField.Value;
                    
                    Con = zeros(2,2);
                    Con(1,1) = app.Con11EditField.Value;
                    Con(1,2) = app.Con12EditField.Value;
                    Con(2,1) = app.Con21EditField.Value;
                    Con(2,2) = app.Con22EditField.Value;
                    
                    Coff = ones(2,2);
                    %Coff = zeros(2,2);
                    %Coff(1,1) = app.Coff11EditField.Value;
                    %Coff(1,2) = app.Coff12EditField.Value;
                    %Coff(2,1) = app.Coff21EditField.Value;
                    %Coff(2,2) = app.Coff22EditField.Value;
                    
                    if app.Button1_2.Value
                        disp_mol = 1;
                    elseif app.Button2_2.Value
                        disp_mol = 2;
                    elseif app.Button12_2.Value
                        disp_mol = 12;
                    end
            end
        end
        
        function set_input_vars(app, tab, s, p_ini) %gz, a0, K, Con, Coff, noise, hill, p_ini, lambda12, M_int, hill_ID, ini_ID)
            % upon loading a file, sets the entries in the box fields to the values from the loaded file
            % s = save_consts_struct must be a structure
            
            % display field names
            %{
            snames = fieldnames(s);
            for ii=1:numel(snames)
                app.MessagesTextArea.Value{end+1} = snames{ii};
            end
            %}
            
            % set common variables
            gz = sqrt(s.N);
            
            try
                app.Sigma_DEditField.Value = s.sigma_D;
            catch
                warning('Variable sigma_D does not exist! Values set to zero.');
                app.MessagesTextArea.Value{end+1} = 'Variable sigma_D does not exist! Value set to zero. ';
                app.Sigma_DEditField.Value = 0;
            end
            
            try
                app.MonteCarlostepsDropDown.Value = num2str(s.mcsteps);
            catch
                warning('Variable mcsteps does not exist! Value set to zero.');
                app.MessagesTextArea.Value{end+1} = 'Variable mcsteps does not exist! Value set to zero. ';
                app.MonteCarlostepsDropDown.Value = '0';
            end
            
            try
                app.sigma_rcellEditField.Value = s.sigma_rcell;
            catch
                warning('Variable sigma_rcell does not exist! Values set to zero.');
                app.MessagesTextArea.Value{end+1} = 'Variable sigma_rcell does not exist! Value set to zero. ';
                app.sigma_rcellEditField.Value = 0;
            end
            
            try
                app.c_growthEditField.Value = s.c_growth;
                app.k_growthEditField.Value = s.k_growth;
            catch
                try
                    % if k_growth doesn't exist, try calculating from K_growth
                    app.c_growthEditField.Value = s.c_growth;
                    ini_density = 2*pi/sqrt(3)*app.rCell^2;
                    app.k_growthEditField.Value = s.K_growth/ini_density;
                catch
                    warning('Variables growth_rate, R_division do not exist! Values set to zero.');
                    app.MessagesTextArea.Value{end+1} = 'Variables growth_rate, R_division do not exist! Values set to zero.';
                    app.c_growthEditField.Value = 0;
                    app.k_growthEditField.Value = 0;
                end
            end
            
            %app.MessagesTextArea.Value = strcat(sprintf('gz = %s', num2str(gz)), ';');
            switch tab % distinguish between tabs
                case 1
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'case 1;');
                    app.GridsizeEditField.Value = gz;
                    app.a0EditField.Value = s.a0;
                    app.KEditField.Value = s.K;
                    app.ConEditField.Value = s.Con;
                    app.NoiseEditField.Value = s.noise;
                    app.HillcoefficientEditField.Value = s.hill;
                    app.pt0EditField.Value = s.p_ini;
                    % default values for I
                    app.InitiatewithIt0Menu.Checked = 'off';
                    app.InitiateI = 0;
                    app.It0EditField.Value = 0;
                    % load initial I; first check whether I_ini exists
                    s_fields = fieldnames(s);
                    app.InitiatewithIt0Menu.Checked = 'off'; %default = off
                    app.InitiateI = 0;
                    for i=1:numel(s_fields)
                        if strcmp(s_fields{i},'I_ini_str')
                            if ~isempty(s.I_ini_str)
                                app.It0EditField.Value = s.I_ini;
                                app.InitiatewithIt0Menu.Checked = 'on';
                                app.InitiateI = 1;
                            end
                        end
                    end
                case 2
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'case 2;');
                    app.GridsizeEditField_2.Value = gz;
                    app.a0EditField_2.Value = s.a0;
                    app.Lambda12EditField.Value = s.lambda12;
                    app.NoiseEditField_2.Value = s.noise;
                    app.HillcoefficientEditField_2.Value = s.hill;
                    app.p1t0EditField.Value = p_ini(1);
                    app.p2t0EditField.Value = p_ini(2);
                    % default values for I
                    app.InitiatewithIt0Menu.Checked = 'off';
                    app.InitiateI = 0;
                    app.I1t0EditField.Value = 0;
                    app.I2t0EditField.Value = 0; 
                    % load initial I; first check whether I_ini exists
                    s_fields = fieldnames(s); 
                    for i=1:numel(s_fields)
                        if strcmp(s_fields{i},'I_ini_str')
                            if ~isempty(s.I_ini_str)
                                app.I1t0EditField.Value = s.I_ini(1);
                                app.I2t0EditField.Value = s.I_ini(2);
                                app.InitiatewithIt0Menu.Checked = 'on';
                                app.InitiateI = 1;
                            end
                        end
                    end
                    
                    app.K11EditField.Value = s.K(1,1);             
                    app.K12EditField.Value = s.K(1,2);
                    app.K21EditField.Value = s.K(2,1);
                    app.K22EditField.Value = s.K(2,2);  
                    app.Con1EditField.Value = s.Con(1);
                    app.Con2EditField.Value = s.Con(2);
                    %app.Coff1EditField.Value = Coff(1);
                    %app.Coff2EditField.Value = Coff(2);
                    
                    % set dropdown boxes
                    M_int_idx = s.M_int + 2; %convert index values
                    dditems = {app.DropDown11.Items{2}, app.DropDown11.Items{3}, app.DropDown11.Items{1}};
                    
                    app.DropDown11.Value = dditems{M_int_idx(1,1)};
                    app.DropDown12.Value = dditems{M_int_idx(1,2)};
                    app.DropDown21.Value = dditems{M_int_idx(2,1)};
                    app.DropDown22.Value = dditems{M_int_idx(2,2)};
            end
        end
        
        function tab = tab_to_num(app)
            % Convert SelectedTab into number 
            if app.TabGroup.SelectedTab == app.signalTab
                tab = 1;
            elseif app.TabGroup.SelectedTab == app.signalsTab
                tab = 2;
            end
        end
        
        function [fname, save_vars_struct] = get_save_file(app, folder, label, ext)
            % outputs 'fname', a string giving the default name for saving files (with folder, without extension)
            % outputs a structure array 'save_vars_struct' containing other variables (constants) to save
            % input 'folder' as the default folder to save in.
            % input 'ext' as extension for file (e.g. '.mat' or '.pdf').
            
            % v16: omit version control if ext is not certain (when saving figures).
            % Standard output: '-v1'.
            
            % folder to save in
            %folder = fullfile(app.DefaultSaveFolder, 'time_evolution'); %default folder
            if exist(folder, 'dir') ~= 7
                app.MessagesTextArea.Value{end+1} = 'Folder does not exist! ';
            end
            
            % common save variables
            rcell = app.rCell;
            tMax = app.tmax;
            prec = app.precision;
            
            tab = tab_to_num(app);
            % tab-specific save variables
            if app.SelectedTab == app.signalTab
                % (A) One signalling molecule 
                %tab = 1;
                
                % define variables from app input
                [gz, a0, ~, K, Con, ~, noise, hill, p_ini, I_ini, M_int, ~, mcsteps,...
                     sigma_D, growth_rate, R_division] = get_input_vars(app);
                N = gz^2;
                
                % ---- format file name ----
                sim_ID = app.SimID{tab};
                I_ini_str = '';
                if hill==Inf
                    hill_ID = 'binary';
                    filename = strrep(sprintf('%s_%s_N%d_a0_%.2f_K%.2f_Con%.2f_noise%.2f_p_ini%.2f_rcell%.2f_tmax%d',...
                        sim_ID, hill_ID, N, a0, K, Con, noise, p_ini, rcell, tMax), '.', 'p');
                else
                    hill_ID = 'continuous';
                    filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_K%.2f_Con%.2f_noise%.2f_p_ini%.2f_rcell%.2f_tmax%d_prec%d',...
                        sim_ID, hill_ID, hill, N, a0, K, Con, noise, p_ini, rcell, tMax, prec), '.', 'p');
                end
                % ---- shortened name ---- 
                %filename = strrep(sprintf('%s_%s_N%d_', sim_ID, hill_ID, N), '.', 'p');
                
                % ---- check if filename already exists ----
                i=1;
                fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                while exist(fname, 'file') == 2
                    i=i+1;
                    fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                end
                
                % ---- prepare output (structure array) ----
                save_vars = {N, a0, K, Con, hill, noise, p_ini, I_ini, M_int, rcell, tMax, prec,...
                    sim_ID, hill_ID, I_ini_str, mcsteps, sigma_D, growth_rate, R_division};
                save_vars_lbl = {'N', 'a0', 'K', 'Con', 'hill', 'noise', 'p_ini', 'I_ini', 'M_int', 'rcell', 'tmax', 'prec',...
                    'sim_ID', 'hill_ID', 'I_ini_str', 'mcsteps', 'sigma_D', 'growth_rate', 'R_division'};
                save_vars_struct = cell2struct(save_vars, save_vars_lbl, 2);
                
            elseif app.SelectedTab == app.signalsTab
                % (B) 2 signals, multiplicative 
                %tab = 2;
                
                % define variables from app input
                [gz, a0, ~, K, Con, Coff, noise, hill, p_ini, I_ini, M_int, lambda12, mcsteps,...
                     sigma_D, growth_rate, R_division] = get_input_vars(app);
                N = gz^2;
                
                % ---- format file name ----
                % filename long
                sim_ID = app.SimID{tab};
                M_int_str = sprintf('M_int%d_%d_%d_%d', M_int(1,1), M_int(1,2), M_int(2,1), M_int(2,2));
                I_ini_str = '';
                if app.InitiateI
                    I_ini_str = sprintf('_I1_I2_%.2f_%.2f', I_ini(1), I_ini(2));
                end
                %
                K_str = sprintf('K%.1f_%.1f_%.1f_%.1f', K(1,1), K(1,2), K(2,1), K(2,2));
                Con_str = sprintf('Con%.1f_%.1f', Con(1), Con(2));
                Coff_str = sprintf('Coff%.1f_%.1f', Coff(1), Coff(2));
                p_ini_str = sprintf('p_ini%.2f_%.2f', p_ini(1), p_ini(2));
                
                %filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s_noise%.1f_%s%s_l12_%.1f_rcell%.1f_tmax%d',...
                %    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str, noise, p_ini_str, I_ini_str, lambda12, rcell, tmax), '.', 'p');
                
                % semi-long
                filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s',...
                    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str), '.', 'p');
                %}
                
                % shortened name
                %filename = strrep(sprintf('%s_%s_hill%.2f_N%d_', sim_ID, M_int_str, hill, N), '.', 'p');    
                    
                % ---- check if filename already exists ----
                i=1;
                fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                while exist(fname, 'file') == 2
                    i=i+1;
                    fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                end
                
                % ---- prepare output variables ----
                save_vars = {N, a0, K, Con, Coff, M_int, hill, noise, p_ini, I_ini, rcell,...
                    tMax, lambda12, sim_ID, I_ini_str, mcsteps, sigma_D, growth_rate, R_division};
                save_vars_lbl = {'N', 'a0', 'K', 'Con', 'Coff', 'M_int', 'hill', 'noise', 'p_ini', 'I_ini', 'rcell',...
                    'tmax', 'lambda12', 'sim_ID', 'I_ini_str', 'mcsteps', 'sigma_D', 'growth_rate', 'R_division'};
                save_vars_struct = cell2struct(save_vars, save_vars_lbl, 2);
                
            end
        end

        function save_figures(app, folder, ext)
            % Sves all opened figures made by the app
            for i=1:numel(app.FigureHandles)
                this_handle = app.FigureHandles{i};
                if ishandle(this_handle)%~isempty(h.Children)
                    this_label = this_handle.Name; %fig_label{i};
                    fname = get_save_file(app, folder, strcat('_', this_label), ext);
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, fname);
                    [file, path] = uiputfile(strcat(fname, ext));
                    
                    if ~file % if user pressed cancel, continue to next fig
                        continue;
                    end
                    [~, name, ext] = fileparts(file); %separate file name from extension
                    
                    fname_out = fullfile(path, name);
                    save_figure(this_handle, fname_out, ext); % use current figure size
                    
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Saved %s plot as %s file;', this_label, ext);
                end
            end
        end
        
        function [status, cells] = manual_input_state(app, signal_count, N)
            % used in simulate_cells_... functions when manual input state is called
            app.MessagesTextArea.Value{end+1} = 'Manually input initial state...';
                    
            folder = app.InputStateSaveFolder; %fullfile('..', '..', 'data', 'input_states');
            [file, path] = uigetfile({'*.xls'; '*.xlsx'; '*.*'}, 'Load initial lattice configuration', folder );
            try
                cells_in = cell(2, 1);
                cells_in{1} = xlsread(fullfile(path, file), 1);
                cells_in{2} = xlsread(fullfile(path, file), 2);
            catch
                try 
                    cells_in = cell(2, 1);
                    cells_in{1} = xlsread(fullfile(path, file), 1);
                catch 
                    app.MessagesTextArea.Value = 'Wrong input initial state, operation aborted';
                    status = 0;
                    cells = [];
                    return
                end
            end
            
            [status, cells] = InputCellStateParser(app, cells_in, signal_count, N);
            if ~status
                app.MessagesTextArea.Value{end+1} = 'Wrong input data format';
                return
            end
        end

        function [status, cells_parsed] = InputCellStateParser(app, cells, signal_count, N)
            % Checks whether input cells state (loaded from xlsx file) is in the right format 
            % If so, parse cell states
            %[gz, ~] = get_input_vars(app);
            %N = gz^2;
            gz = sqrt(N);
            status = 1; % Default
            if signal_count==1
                % one signal
                cells = cells{1};
                format1 = (size(cells, 1)==N && size(cells,2)==1);
                format2 = (size(cells, 1)==gz && size(cells,2)==gz);
                if format1 % N x 1 arrangement
                    cells_parsed = cells;
                elseif format2 % gz x gz arrangement
                    cells_parsed = cells(:);
                else
                    cells_parsed = [];
                    status = 0; % cells not good
                end
            elseif signal_count==2
                % two signals
                format1 = (size(cells{1}, 1)==N && size(cells{1},2)==2);
                format2 = ( all(size(cells{1})==[gz gz]) && all(size(cells{2})==[gz gz]) );
                if format1 % N x 2 arrangement
                    cells_parsed = cells{1};
                elseif format2 % two sheets, gz x gz arrangement
                    cells_parsed = zeros(N, 2);
                    cells_parsed(:, 1) = reshape(cells{1}, N, 1);
                    cells_parsed(:, 2) = reshape(cells{2}, N, 1);
                else
                    cells_parsed = [];
                    status = 0; % cells not good
                end
            end
        end

        function [rcell_all_out, rcellChanged] = update_cell_sizes(app, rcell_all, c_growth, k_growth)
            rcellChanged = 0;
            if isempty(rcell_all)
                rcell_all_out = rcell_all;
                return
            end
            
            N = size(rcell_all, 1);
            % calculate growth rate
            A_system = sqrt(3)/2; % area of system
            A_cells = pi*mean(rcell_all.^2); % area covered by cells
            rho = A_cells/A_system; % area density of cells
            ini_density = 2*pi/sqrt(3)*app.rCell^2; % initial density of system
            K_growth = k_growth*ini_density; % K_growth is expressed as max. fold-change of initial density
            mu_cells = c_growth*rho*(1-rho/K_growth); 
    
            rcell_all_new = rcell_all*sqrt((1+mu_cells)); % sqrt: growth rate is for biomass
            
            % check whether new sizes are acceptable
            rcell_mat = repmat(rcell_all_new, 1, N) + repmat(rcell_all_new', N, 1); % sum of radii of pairs of cells
            cond_mat = (app.dist > rcell_mat);
            if all(cond_mat(app.dist>0))
                % if passed, accept new radii
                % update cell sizes 
                rcellChanged = 1;
                rcell_all_out = rcell_all_new;
            else
                % growth hits a maximum, don't update cell radii
                % warning('Updating cell radii would lead to overlapping cells!');
                rcell_all_out = rcell_all;
            end
        end
    
        function dist = pos2dist(app, pos)
            % calculates distances from input pos
            Lx = 1;
            Ly = sqrt(3)/2*Lx;
            dist = calc_dist_periodic(pos(:,1), pos(:,2), Lx, Ly);
        end
        
        %-------------------------END OF ALL FUNCTIONS----------------------
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % set directories
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            [filepath, ~, ~] = fileparts(mfilename('fullpath'));
            cd(filepath);
            app.MessagesTextArea.Value = {'Current directory: ', pwd};
            % add subdirectories
            % addpath('app_icons'); % subdirectory for app icons
            
            % set default save folder
            app.DefaultSaveFolder = fullfile('..', '..', 'data', 'time_evolution'); %fullfile(pwd, 'data', 'time_evolution'); %fullfile('..', '..', 'data', 'time_evolution'); % default (parent) folder for saving (simulation) data
            %app.DefaultSaveFolder = 'M:\tnw\bn\hy\Shared\Yiteng\Multicellularity\patterns';
            %app.DefaultSaveFolder = 'H:\My Documents\Multicellular automaton\paper_2_draft\trajectories';
            
            % set default input states folder (for saving and loading input states)
            app.InputStateSaveFolder = fullfile('..', '..', 'data', 'system_states');
            
            % clear axes
            ax = app.UIAxes; %figure handle
            cla(ax);
            
            set(0, 'DefaultTextInterpreter', 'none')
            %----------------set default properties---------------------
            % set default control variables
            app.ContinueSimulation = 1;
            app.NewSimulation = 1;
            app.ReplayTrajectory = 0;
            app.Changed = 1;
            app.SelectedTab = app.TabGroup.SelectedTab;
            app.showI = 0; % Whether to show I on top of simulation (slower)
            app.InitiateI = 0; % Whether to initiate the system with given I
            app.ManualInputInitialState = 0; % Whether to manually input an initial state
        
            % clear default variables
            app.Time = 0;
            app.CellsOut = [];
            app.CellsHist = {}; % store trajectories
            app.pos = [];
            app.dist = [];
            app.pos_hist = {};
            app.rCell = app.CellsizeEditField.Value;
            
            % Figures
            app.h_cells = []; % handle for cell lattice plot (scatter)
            %h_borders = []; % handle for cell lattice borders (scatter)
            app.FigureHandles = {}; % stores all handles to plot figures
            
            %------------------set folders-------------------------------
            % check if default save folders exist. If not, make new folder
            if exist(app.DefaultSaveFolder, 'dir') ~= 7
                app.MessagesTextArea.Value = ('Make new folder; ');
                %out = regexp(app.DefaultSaveFolder,'\','split');
                %make_folder = fullfile(pwd, out{end-1}, out{end});
                [status, msg] = mkdir(app.DefaultSaveFolder);
                make_folder = fullfile(app.DefaultSaveFolder);
                
                if status
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Successfully created folder %s ;',...
                            make_folder);
                else
                    % print 'Couldn't create folder';
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Could not create folder %s ;',...
                            make_folder);
                    app.MessagesTextArea.Value{end+1} = msg;
                end
            end
            
            if exist(app.InputStateSaveFolder, 'dir') ~= 7
                app.MessagesTextArea.Value = ('Make new folder; ');
                [status, msg] = mkdir(app.InputStateSaveFolder);
                
                make_folder = fullfile(app.InputStateSaveFolder);
                if status
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Successfully created folder %s ;',...
                           make_folder);
                else
                    % print 'Couldn't create folder';
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Could not create folder %s ;',...
                            make_folder);
                    app.MessagesTextArea.Value{end+1} = msg;
                end
            end
            %}
            %-----------------------------------------------------------------
            % Get correct scaling of cell sizes according to operating system
            if ismac
                % Code to run on Mac platform
                app.pix2pts = 1;
            elseif isunix
                % Code to run on Linux platform
                % Try to calculate conversion factor from screen size (code not tested)
                %Sets the units of your root object (screen) to pixels
                set(0,'units','pixels')  
                %Obtains this pixel information
                Pix_SS = get(0,'screensize');
                %Sets the units of your root object (screen) to inches
                set(0,'units','inches')
                %Obtains this inch information
                Inch_SS = get(0,'screensize');
                %Calculates the resolution (pixels per inch)
                Res = Pix_SS(3:4)./Inch_SS(3:4);
                app.pix2pts = mean(Res)/72; % (pixels per point=1/72 inch, take mean over horiz. and vertical directions)
            elseif ispc
                % Code to run on Windows platform
                app.pix2pts = 96/72;
            else
                disp('Platform not supported')
            end              
            
            % display binary system with all cells off
            reset_cell_figure(app)
            
            % disables zooming in and out by scrolling over plot (introduced in R2018b)
            % get current MATLAB version
            matlabVersion = version;
            [~, tk] = regexp(matlabVersion, 'R(2018)(b)', 'match', 'tokens');
            if numel(tk)>0
                v_year = str2double(tk{1}{1});
                v_release = tk{1}{2};
                
                % if version newer than R2018b, disable interactivity
                if v_year>2018
                    disableDefaultInteractivity(app.UIAxes);
                elseif v_year==2018 && strcmp(v_release, 'b')
                    disableDefaultInteractivity(app.UIAxes);
                end
            end
        end

        % Callback function: ButtonPlay, RunMenu
        function RunButtonPushed(app, event)
            app.ContinueSimulation = 1;
            app.SelectedTab = app.TabGroup.SelectedTab;
            
            % clear axes
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            %ax = app.UIAxes; %figure handle
            %cla(ax);
            
            % get current tab
            tab = tab_to_num(app);
            
            % get all parameters
            [gz, a0, disp_mol, K, Con, Coff, noise, hill, p0, I0, M_int,...
                lambda12, mcsteps, sigma_rcell, sigma_D, c_growth, k_growth] = get_input_vars(app);
            N = gz^2;
            Rcell = app.rCell*a0;
            %cell_type = zeros(N, 1);
            %[dist, ~] = init_dist_hex(gz, gz);
            lambda = [1 lambda12];
            t_final = length(app.CellsHist)-1;
            
            % Replay trajectory
            if app.ReplayTrajectory && app.Time < t_final
                app.MessagesTextArea.Value = 'Simulation replay; ';
                %app.MessagesTextArea.Value{end+1} = 'Way 1; ';
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('Length(cells_hist): %s', num2str(size(app.CellsHist)) ));
                
                % to be expanded:
                %cell_type = zeros(N, 1); 
                
                %app.MessagesTextArea.Value{end+1} = 'CP1;';
                %app.MessagesTextArea.Value{end+1} = sprintf('app.Time = %d', app.Time);
                
                while app.Time < t_final && app.ContinueSimulation
                    app.Time = app.Time + 1;
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, ' time = ', num2str(app.Time), ';');
                    cells = app.CellsHist{app.Time+1};
                    if ~isempty(app.pos_hist)
                        app.pos = app.pos_hist{app.Time+1};
                        app.dist = pos2dist(app, app.pos);
                    end
                    if ~isempty(app.rcellHist)
                        app.rcell_all = app.rcellHist{app.Time+1};
                    end
                    update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                end
                %app.MessagesTextArea.Value = strcat('Replay finished; ');
                
                % skip running another simulation in next section
                app.NewSimulation = 0;
                
                % Check whether the final state after replay is in equilibrium by doing one more updating step
                cells = app.CellsHist{end};
                switch tab
                    case 1
                        [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                            cells, app.dist, M_int, Con, K, a0, Rcell, noise, hill, app.precision);  
                    case 2
                        [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells,...
                            app.dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                end
                % also update cell positions and radii
                [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D); 
                app.rcell_all = update_cell_sizes(app, app.rcell_all, c_growth, k_growth); 
                
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'app.Changed = ', num2str(app.Changed), ';');
                
                %app.MessagesTextArea.Value{end+1} = sprintf('%d', cells);
                %pause(1);
            end
            
            % ---- run a new simulation / continue current simulation ----
            % conditions under which the following code should be run:
            % (1) new simulation
            cond1 = app.NewSimulation;
            % (2) resume after pause
            cond2 = (~app.NewSimulation)&&(~app.ReplayTrajectory);
            % (3) after replay & run, the system has not reached equilibrium
            cond3 = (app.ReplayTrajectory)&&(app.Changed);
            % (4) cells are moving, always continue (as long as t<tmax)
            cond4 = (sigma_D>0)&&(app.Time<app.tmax);
            %{
            if cond1
                % Generate new lattice
                app.MessagesTextArea.Value{end+1} = 'Generating new lattice...';
                mcsteps = str2double(app.MonteCarlostepsDropDown.Value);
                mcsteps = floor(mcsteps);
                %app.MessagesTextArea.Value{end+1} = sprintf('MC steps: %d', mcsteps);
                new_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice);
                
                % Generate different cell radii
                sigma_rcell = app.sigma_rcellEditField.Value;
                if sigma_rcell>0
                    app.rcell_all = app.rCell + randn(N, 1)*sigma_rcell*app.rCell;
                end
            end
            %}
            if cond1 || cond2 || cond3 || cond4
                %{
                app.MessagesTextArea.Value{end+1} = strcat('cond1: ', num2str(cond1) );
                app.MessagesTextArea.Value{end+1} = strcat('cond2: ', num2str(cond2) );
                app.MessagesTextArea.Value{end+1} = strcat('cond3: ', num2str(cond3) );
                app.MessagesTextArea.Value{end+1} = strcat('cond4: ', num2str(cond4) );
                app.MessagesTextArea.Value{end+1} = sprintf('%d', app.NewSimulation);
                pause(1);
                
                app.MessagesTextArea.Value{end+1} = sprintf('%d', cells);
                pause(1);
                %}
                switch tab
                    case 1
                        simulate_cells_continuous(app, N, M_int, a0, Rcell, Con, K, noise, hill,...
                            app.precision, p0, I0, sigma_D, mcsteps, c_growth, k_growth, sigma_rcell, app.NewSimulation);
                    case 2
                        if ~exist('cells', 'var')
                            % optional input: initial cells (for continued simulations only)
                            cells = [];
                        end
                        simulate_cells_two_signals_multiply(app, N, M_int, lambda12, a0, Rcell,...
                            Con, Coff, K, hill, noise, p0, I0, sigma_D, mcsteps, c_growth,...
                            k_growth, sigma_rcell, app.NewSimulation, cells, disp_mol);
                end
            end
            
            % if the trajectory reached equilibrium at the end, pressing RUN again should do nothing / replay the trajectory
            if app.Changed == 0 && sigma_D==0
                app.MessagesTextArea.Value{end+1} = 'Equilbrium state reached; ';
                %app.ReplayTrajectory = 1;
                %app.Time = 0; % reset time
                app.NewSimulation = 0;
            end
            
        end

        % Callback function: ButtonStop, Con1EditField, 
        % Con2EditField, ConEditField, DropDown11, DropDown12, 
        % DropDown21, DropDown22, GridsizeEditField, 
        % GridsizeEditField_2, HillcoefficientEditField, 
        % HillcoefficientEditField_2, I1t0EditField, I2t0EditField, 
        % InteractionDropDown, It0EditField, K11EditField, K12EditField, 
        % K21EditField, K22EditField, KEditField, Lambda12EditField, 
        % MonteCarlostepsDropDown, NoiseEditField, NoiseEditField_2, 
        % ResetMenu, Sigma_DEditField, a0EditField, a0EditField_2, 
        % c_growthEditField, k_growthEditField, p1t0EditField, 
        % p2t0EditField, pt0EditField, sigma_rcellEditField
        function ResetButtonPushed(app, event)
            app.ContinueSimulation = 0;
            app.NewSimulation = 1;
            app.ReplayTrajectory = 0;
            
            % close all generated figures
            %app.CloseallfiguresMenuSelected(app);
            CloseallfiguresMenuSelected(app);
            
            % reset selected tab
            % N.B. changing the tab runs ResetButtonPushed, so this code has to be placed here
            app.SelectedTab = app.TabGroup.SelectedTab;
            
            % clear saved variables (not done in previous version)
            reset_app_vars(app);
            
            % clear figure to standard configuration
            %[~, pos] = init_dist_hex(gz, gz);
            reset_cell_figure(app)
            
            % clear output textbox 
            app.MessagesTextArea.Value = ''; 
            
        end

        % Callback function: ButtonPause, PauseMenu
        function PauseButtonPushed(app, event)
            if app.ContinueSimulation % only do something if the simulation is still running
                app.ContinueSimulation = 0;
                app.NewSimulation = 0;
                if app.Changed
                    app.MessagesTextArea.Value = 'Simulation paused; ';
                end
            end
        end

        % Menu selected function: SavetrajectoryMenu
        function SavetrajectoryMenuSelected(app, event)
            % Save trajectory
            % Check trajectory exists
            if isempty(app.CellsHist)
                app.MessagesTextArea.Value = 'No simulation data to save!';
                return
            end
            
            % Get default folder and file name
            folder = app.DefaultSaveFolder; 
            ext = '.mat';
            label = '';
            [fname, save_consts_struct] = get_save_file(app, folder, label, ext);
            %[fname, ~] = get_save_file(app, folder, label, ext);
            
            % simulation data to save
            cells_hist = app.CellsHist;
            t_out = length(cells_hist); %app.TimeFinal;
            changed = app.Changed;
            
            % Ask user to provide definitive save path
            %vars_out = {'save_consts_struct', 'cells_hist', 't_out', 'changed'};
            %uisave(vars_out, fname);
            [file, path] = uiputfile(fname);
            if isnumeric(file) && isnumeric(path) % if user clicked "cancel"
                %app.MessagesTextArea.Value = sprintf('Simulation not loaded.');
                return
            end
            
            % Variables to save
            positions = app.pos;
            distances = app.dist;
            if ~isempty(app.pos_hist)
                positions_all = app.pos_hist;
                positions = app.pos_hist{1};
                distances = pos2dist(app, positions); 
                % save simulation
                save(fullfile(path, file), 'save_consts_struct',...
                'cells_hist', 't_out', 'changed', 'positions', 'distances', 'positions_all');
            else
                % save simulation
                save(fullfile(path, file), 'save_consts_struct',...
                'cells_hist', 't_out', 'changed', 'positions', 'distances');
            end
            
            app.MessagesTextArea.Value{end+1} = ...
                sprintf('Saved simulation: %s ;', fullfile(path, file));
            
            % to do: change view to app after saving / loading file
            %app.MulticellularModelSimulatorUIFigure;
        end

        % Menu selected function: LoadtrajectoryMenu
        function LoadtrajectoryMenuSelected(app, event)
            app.ReplayTrajectory = 1;
            app.NewSimulation = 0;
            
            % reset vars
            app.CellsOut = [];
            app.Changed = 1;
            app.Time = 0;
            
            % load saved trajectory from folder
            %folder = app.DefaultSaveFolder; %fullfile(pwd, 'data', 'time_evolution');
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\batch_sim_all_topologies_run2\selected';
            %folder = 'H:\My Documents\Multicellular automaton\app\data\time_evolution';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\batch_sim_all_topologies_run2\filtered_TW';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\trav_wave_vs_N';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\sweep K12 new lattice\sensitivity_init_cond';
            %folder = 'H:\My Documents\Multicellular automaton\paper_2_draft\trajectories';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\moving_growing_cells';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\moving_cells_TW';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\randomized lattice\TW_propagation_network_15';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\moving_cells_TW\TW_formation_network_15_fixed_params_reanalyzed';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\batch_sim_all_topologies_run2';
            folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\trav_wave_vs_N';
            
            %[file, path] = uigetfile(folder, 'Load saved simulation');
            [file, path] = uigetfile(fullfile(folder, '\*.mat'), 'Load saved simulation');

            %app.MessagesTextArea.Value = sprintf('file = %s, path = %s', file, path);
            if isnumeric(file) && isnumeric(path) % if user clicked "cancel"
                %app.MessagesTextArea.Value = sprintf('Simulation not loaded.');
                return
            end
            
            % Load simulation
            load(fullfile(path, file));
            app.MessagesTextArea.Value = sprintf('Loaded simulation: %s; ', fullfile(path,file));

            % store loaded variables in app environment 
            app.CellsHist = cells_hist;
            app.CellsOut = cells_hist{end};
            
            % Generate lattice
            new_lattice = 0;
            mcsteps = 0; %variable not used
            reset_cell_figure(app); 
            gz = sqrt(save_consts_struct.N);
            %app.rCell = save_consts_struct.rcell;
            
            %---Try loading optional variables---
            if exist('positions', 'var')  
                app.pos = positions;
                if exist('distances', 'var')
                    app.dist = distances;
                else
                    app.dist = pos2dist(app, positions);
                end
                app.pos_hist = {};
            end
            
            % try loading positions array
            if exist('positions_all', 'var')
                % try loading cell positions (for moving cells)
                app.pos_hist = positions_all;
                app.pos = positions_all{1};
                app.dist = pos2dist(app, positions_all{1}); 
            end
                
            % generate lattice
            if ~isempty(app.dist) && ~isempty(app.pos)
                % display lattice with correct size
                %gz = get_input_vars(app);
                %[~, pos] = init_dist_hex(gz, gz);
                hex_lattice = 0;
                generate_lattice(app, gz, mcsteps, new_lattice, hex_lattice);
            else % display default hexagonal lattice
                hex_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice, hex_lattice);
            end
            
            % = length(cells_hist)-1; %t_out;
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('Length(cells_hist): %s', num2str(size(app.CellsHist)) ));
            if exist('rcell_hist', 'var')
                % try loading cell positions (for moving cells)
                app.rcellHist = rcell_hist;
                app.rCell = save_consts_struct.rcell; % mean initial radius
            else
                app.rcellHist = {};
                app.rCell = save_consts_struct.rcell;
            end
            
            % --- switch to right tab ---
            %{
            if strcmp(save_consts_struct.sim_ID, app.SimID{1})
                app.TabGroup.SelectedTab = app.signalTab;
                app.CellsizeEditField.Value = save_consts_struct.rcell;
                %tab = 1;
            elseif strcmp(save_consts_struct.sim_ID, app.SimID{2})
                app.TabGroup.SelectedTab = app.signalsTab;
                app.CellsizeEditField_2.Value = save_consts_struct.rcell;
                %tab = 2;
            elseif strcmp(save_consts_struct.sim_ID, app.SimID{3})
                app.TabGroup.SelectedTab = app.signalsaddTab;
                %tab = 3;
            end
            %}
            if size(cells_hist{1}, 2)==1
                app.TabGroup.SelectedTab = app.signalTab;
                app.CellsizeEditField.Value = save_consts_struct.rcell;
                %tab = 1;
            elseif size(cells_hist{1}, 2)==2
                app.TabGroup.SelectedTab = app.signalsTab;
                app.CellsizeEditField_2.Value = save_consts_struct.rcell;
                %tab = 2;
            end
            
            %pause(2);
            tab = tab_to_num(app);
            %app.MessagesTextArea.Value = sprintf('Tab = %s', num2str(tab));
            %app.MessagesTextArea.Value = sprintf('Size(s) = %s', num2str(size(save_consts_struct)));
            
            % --- change data fields ---
            p_ini = mean(cells_hist{1}, 1);
            set_input_vars(app, tab, save_consts_struct, p_ini); 
            
            % app.MulticellularModelSimulatorUIFigure;
            % to do: change view to app after saving / loading file
        end

        % Menu selected function: MeangeneexpressionptMenu
        function MeangeneexpressionptMenuSelected(app, event)
            % make a graph of p(t)
            [msg, h] = plot_p_vs_t(app.CellsHist, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: SpatialautocorrelationItMenu
        function SpatialautocorrelationItMenuSelected(app, event)
            % make a graph of I(t)
            [~, a0] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            option = 1; % plot I
            [msg, h] = plot_I_vs_t(app.CellsHist, a0, app.dist, app.pos_hist, option, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: SpatialautocorrelationThetatMenu
        function SpatialautocorrelationThetatMenuSelected(app, event)
            % make a graph of Theta(t)
            [~, a0] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            option = 2; % plot Theta
            [msg,h] = plot_I_vs_t(app.CellsHist, a0, app.dist, app.pos_hist, option, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DtrajectoryptItMenu
        function TrajectoryptItMenuSelected(app, event)
            % make a graph of (p(t), I(t))
            % get input vars
            [~, a0, ~, K, Con] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            % calculate fN
            %dist = round(dist, 5);
            Rcell = 0.2*a0;
            dist_vec = a0*app.dist(1,:);
            r = dist_vec(dist_vec>0); % exclude self influence
            fN = sum(sinh(Rcell)*sum(exp(Rcell-r)./r)); % calculate signaling strength
            
            [msg, h] = plot_p_I_vs_t(app.CellsHist, app.dist, a0, Con, K, fN, 1, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DtrajectoryptThetatMenu
        function TrajectoryptThetatMenuSelected(app, event)
            % make a graph of (p(t), Theta(t))
            % get input vars
            [~, a0, ~, K, Con] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            % calculate fN
            %dist = round(dist, 5);
            Rcell = 0.2*a0;
            dist_vec = a0*app.dist(1,:);
            r = dist_vec(dist_vec>0); % exclude self influence
            fN = sum(sinh(Rcell)*sum(exp(Rcell-r)./r)); % calculate signaling strength
            
            [msg, h] = plot_p_I_vs_t(app.CellsHist, app.dist, a0, Con, K, fN, 2, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web('https://github.com/YitengDang/Multicellularity-app', '-browser');
        end

        % Menu selected function: PseudoenergyhtMenu
        function PseudoenergyhtMenuSelected(app, event)
            % make a graph of h(t)
            % get input vars
            [~, a0, ~, K, Con, ~, noise] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            [msg, h] = plot_h_vs_t(app.CellsHist, app.dist, a0, Con, K, noise, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: CloseallfiguresMenu
        function CloseallfiguresMenuSelected(app, event)
            % closes all opened figures from simulation
            %close all;
            
            %for i=1:app.num_fig
            %    h = figure(i); close(h);
            %end
            
            for i=1:numel(app.FigureHandles)
                this_handle = app.FigureHandles{i};
                %app.MessagesTextArea.Value{end+1} = sprintf('Handle %d', this_handle.Number);
                if ishandle(this_handle) % checks whether handle points to existing figure
                    y = figure(this_handle.Number);
                    close(y);
                end
            end
        end

        % Callback function: BackstepMenu, ButtonBack
        function ButtonBackPushed(app, event)
            % go back 1 time step
            if ~isempty(app.CellsHist) && app.Time>0
                app.ReplayTrajectory = 1;
                app.Time = app.Time-1;
                
                % display lattice
                cells = app.CellsHist{app.Time+1};
                if ~isempty(app.pos_hist)
                    app.pos = app.pos_hist{app.Time+1};
                end
                if ~isempty(app.rcellHist)
                    app.rcell_all = app.rcellHist{app.Time+1};
                end
                    
                [~, a0, disp_mol] = get_input_vars(app);
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                
                % display msg
                app.MessagesTextArea.Value = 'Replay mode;';
            end
        end

        % Callback function: ButtonFwd, ForwardstepMenu
        function ButtonFwdPushed(app, event)
            % go fwd 1 time step (in playback mode)
            % possible adjustment: also forward evolve 1 time step when at end of playback           
            
            if ~isempty(app.CellsHist)
                t_final = length(app.CellsHist)-1; 
                if app.Time < t_final
                    app.Time = app.Time+1;
                    
                    % display lattice
                    cells = app.CellsHist{app.Time+1};
                    if ~isempty(app.pos_hist)
                        app.pos = app.pos_hist{app.Time+1};
                    end
                    if ~isempty(app.rcellHist)
                        app.rcell_all = app.rcellHist{app.Time+1};
                    end
                    
                    [~, a0, disp_mol] = get_input_vars(app);
                    
                    update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                    
                    % display msg
                    app.MessagesTextArea.Value = 'Replay mode;';
                end
                
                if app.Time >= t_final
                    % update cell positions
                    gz = get_input_vars(app);
                    sigma_D = app.Sigma_DEditField.Value;
                    [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
                end
            end
        end

        % Callback function: ButtonToStart, MovetostartMenu
        function ButtonToStartPushed(app, event)
            if ~isempty(app.CellsHist)
                % set simulation to t=0 frame
                app.ReplayTrajectory = 1;
                
                % play from start until equilibrium / tmax reached
                app.Time = 0;
                
                % display initial configuration
                cells = app.CellsHist{1};
                if ~isempty(app.pos_hist)
                    app.pos = app.pos_hist{app.Time+1};
                end
                if ~isempty(app.rcellHist)
                    app.rcell_all = app.rcellHist{app.Time+1};
                end
                    
                [~, a0, disp_mol]  = get_input_vars(app);
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                
                % display msg
                app.MessagesTextArea.Value = 'Replay mode;';
            end
        end

        % Callback function: ButtonToEnd, MovetoendMenu
        function ButtonToEndPushed(app, event)
            % move to end of simulation
            if ~isempty(app.CellsHist)
                t_final = length(app.CellsHist)-1;
                if app.Time < t_final
                    app.Time = t_final;
                    
                    %app.ReplayTrajectory = 1;
                    
                    % display configuration
                    cells = app.CellsHist{app.Time+1};
                    if ~isempty(app.pos_hist)
                        app.pos = app.pos_hist{app.Time+1};
                    end
                    if ~isempty(app.rcellHist)
                        app.rcell_all = app.rcellHist{app.Time+1};
                    end
                    
                    [~, a0, disp_mol]  = get_input_vars(app);
                    update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                    
                    % display msg
                    % app.MessagesTextArea.Value = 'Replay mode;';
                    
                    % update cell positions (for next time step)
                    gz = get_input_vars(app);
                    sigma_D = app.Sigma_DEditField.Value;
                    [app.pos, app.dist, ~] = update_cell_positions(gz, app.rCell, app.pos, app.dist, sigma_D);
                end
            end
        end

        % Menu selected function: ShowIMenu
        function ShowIMenuSelected(app, event)
            % toggle setting (whether to show I on top of figures)
            app.showI = ~app.showI;
            if app.showI
                app.ShowIMenu.Checked = 'on';
            else
                app.ShowIMenu.Checked = 'off';
            end
        end

        % Menu selected function: InitiatewithIt0Menu
        function InitiatewithIt0MenuSelected(app, event)
            % whether or not to initiate the lattice with a specific (target) value of I
            app.InitiateI = ~app.InitiateI;
            if app.InitiateI
                app.InitiatewithIt0Menu.Checked = 'on';
            else
                app.InitiatewithIt0Menu.Checked = 'off';
            end
        end

        % Selection changed function: DisplaysignalButtonGroup
        function DisplaysignalButtonGroupSelectionChanged(app, event)
            % Toggle molecule field displayed
            
            tab = tab_to_num(app);
            if tab==1
                % 1 molecule, nothing to change
                return
            end
            
            % selectedButton = app.DisplaysignalButtonGroup.SelectedObject;
            if ~isempty(app.CellsHist)
                % display configuration
                if app.Time < numel(app.CellsHist)
                    cells = app.CellsHist{app.Time+1};
                elseif app.Time == numel(app.CellsHist) %special case for equilibrium state
                    cells = app.CellsHist{app.Time};
                end
                [~, a0, disp_mol] = get_input_vars(app);
                app.MessagesTextArea.Value{end+1} = sprintf('Switched to molecule %d', disp_mol);
                %N = gz^2;
                %[dist, ~] = init_dist_hex(gz, gz);
                %cell_type = zeros(N,1);
                
                if ~isempty(app.pos_hist)
                    app.pos = app.pos_hist{app.Time};
                    app.dist = 1;
                end
                %update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol)
                
                % display message
                app.MessagesTextArea.Value{end+1} = sprintf('Switched to molecule %d', disp_mol);
            end
        end

        % Menu selected function: CloseappMenu
        function CloseappMenuSelected(app, event)
            % closes all figures and closes the app
            CloseallfiguresMenuSelected(app, event);
            delete(app);
        end

        % Menu selected function: SavefiguresepsMenu
        function SavefiguresepsMenuSelected(app, event)
            % Saves opened figures as eps
            folder = fullfile(app.DefaultSaveFolder);
            ext = '.eps';
            save_figures(app, folder, ext);
        end

        % Menu selected function: SavefigurespdfMenu
        function SavefigurespdfMenuSelected(app, event)
            % Saves opened figures as pdf
            folder = fullfile(app.DefaultSaveFolder);
            ext = '.pdf';
            save_figures(app, folder, ext);
        end

        % Menu selected function: SkiptotimeMenu
        function SkiptotimeMenuSelected(app, event)
            % skips to time user inputs
            prompt = 'Skip to time:';
            answer = inputdlg(prompt);
            if ~isempty(answer)
                input_time = str2double(answer{1});
                if (input_time>-1)&&(input_time<numel(app.CellsHist))
                    app.Time = input_time;
                    
                    % get variables at that time
                    cells = app.CellsHist{app.Time+1};
                    [~, a0, disp_mol]  = get_input_vars(app);
                    if ~isempty(app.pos_hist)
                        app.pos = app.pos_hist{app.Time+1};
                    end
                    if ~isempty(app.rcellHist)
                        app.rcell_all = app.rcellHist{app.Time+1};
                    end
                    
                    % display configuration
                    update_cell_figure_continuum(app, app.pos, app.dist, a0, cells, app.Time, disp_mol);
                
                    % display msg
                    app.MessagesTextArea.Value = 'Replay mode;';
                end
            else 
                app.MessagesTextArea.Value{end+1} = 'Invalid time;';
            end
        end

        % Menu selected function: CircuittopologyMenu
        function CircuittopologyMenuSelected(app, event)
            % Show circuit topology
            [gz, a0, ~, K, Con, Coff, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            h = plot_circuit(gz, a0, app.rCell, M_int, Con, Coff, K, lambda12);
            app.FigureHandles{end+1} = h;
            
            app.MessagesTextArea.Value{end+1} = 'Circuit topology plotted';
            app.MessagesTextArea.Value{end+1} = 'Dotted line: interaction always below threshold';
            app.MessagesTextArea.Value{end+1} = 'Dashed line: interaction always above threshold';
            app.MessagesTextArea.Value{end+1} = 'Normal line: no restrictions';
        end

        % Menu selected function: StatediagramsinglecellMenu
        function StatediagramMenuSelected(app, event)
            [~, ~, ~, K, Con, Coff, ~, ~, ~, ~, M_int, ~] = get_input_vars(app);
            [~, ~, h] = plot_state_diagram_onecell(M_int, Con, Coff, K);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted state diagram (single cell)';
        end

        % Menu selected function: PhasediagramMenu
        function PhasediagramMenuSelected(app, event)
            % Plots the phase diagram of K, Con for each interaction
            [gz, a0, ~, K, Con, ~, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            h = plot_phase_diagram(gz, a0, app.rCell, M_int, K, Con, lambda12);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted phase diagram';
        end

        % Menu selected function: StatediagramfullsystemMenu
        function StatediagramfullsystemMenuSelected(app, event)
            [gz, a0, ~, K, Con, Coff, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            if numel(M_int)==1
                %app.MessagesTextArea.Value{end+1} = 'Unable to plot for one signal.';
                h = plot_state_diagram_multicell_one_signal(gz, a0, app.rCell, M_int, Con, Coff, K);
            elseif numel(M_int)==4
                [phase, A, h] = plot_state_diagram_multicell(gz, a0, app.rCell, M_int, Con, Coff, K, lambda12);
                
                cycles=all_topologies_cycle_finder(A);
                app.MessagesTextArea.Value{end+1} = 'Found the following cycles:';
                for i=1:numel(cycles)
                    cycle = cycles{i};
                    app.MessagesTextArea.Value{end+1} = num2str(cycle);
                end
                % Check correct phase
                app.MessagesTextArea.Value{end+1} = sprintf('phase = [%d %d; %d %d]', ...
                    phase(1,1), phase(1,2), phase(2,1), phase(2,2));
            end
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted state diagram (multi-cell)';
        end

        % Menu selected function: SavemovieaviMenu
        function SavemovieaviMenuSelected(app, event)
            % Replay trajectory in external fig
            %frames = struct('cdata',[],'colormap',[]);
            
            % get all parameters
            [~, ~, disp_mol] = get_input_vars(app);
            %N = gz^2;
            %cell_type = zeros(N, 1);
            %[~, pos] = init_dist_hex(gz, gz);
            
            % get frame rate
            %{
            % (1) From user input
            prompt = {'Enter frame rate:'};
            title = 'User input';
            dims = [1];
            definput = {'1'};
            answer = inputdlg(prompt,title,dims,definput);
            frame_rate = str2double(answer);
            %}
            % (2) From playback speed
            frame_rate = app.SpeedSlider.Value;
            
            % Get filename from user
            folder = app.DefaultSaveFolder;
            ext = '.avi';
            fname = get_save_file(app, folder, '_movie', ext);
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, fname);
            [file, path] = uiputfile(strcat(fname, ext));
            
            if file % if user pressed cancel, do nothing
                [~, name, ~] = fileparts(file); %separate file name from extension
                
                fname_out = fullfile(path, name);
                
                app.MessagesTextArea.Value{end+1} = ...
                    sprintf('Movies frames: %d;', length(app.CellsHist) );
                
                save_movie(app.CellsHist, app.rCell, app.pos, app.pos_hist, disp_mol, fname_out, frame_rate);
 
                app.MessagesTextArea.Value{end+1} = ...
                    sprintf('Saved movie %s;', fname_out);
            end
        end

        % Value changed function: CellsizeEditField
        function CellsizeEditFieldValueChanged(app, event)
            app.rCell = app.CellsizeEditField.Value;
            app.ResetButtonPushed;
        end

        % Menu selected function: CellcolorlegendMenu
        function CellcolorlegendMenuSelected(app, event)
            [~, ~, disp_mol] = get_input_vars(app);
            if disp_mol==12
                h = plot_color_map_2D(2);
            else
                h = plot_color_map_2D(1);
            end
            app.FigureHandles{end+1} = h;
        end

        % Menu selected function: PeriodicitytestMenu
        function PeriodicitytestMenuSelected(app, event)
            app.MessagesTextArea.Value{end+1} = 'Testing trajectory for periodicity...';
            pause(0.01);
            
            % Rounding cell states
            tab = tab_to_num(app);
            switch tab
                case 1 % 1 signal
                    hill = app.HillcoefficientEditField.Value;
                case 2 % 2 signals
                    hill = app.HillcoefficientEditField_2.Value;
            end
            
            %app.MessagesTextArea.Value{end+1} = sprintf('%d', hill);
            if hill<Inf
                decimals = 5;
            else 
                decimals = Inf;
            end
            
            % periodicity test
            [period_ub, ~] = periodicity_test_short(app.CellsHist, decimals); 
            if period_ub < Inf
                app.MessagesTextArea.Value{end+1} = sprintf('Periodicity found! Upper bound: %d. Finding period...', period_ub) ;
                pause(0.01);
                [period, t_onset] = periodicity_test_detailed(app.CellsHist,...
                    period_ub, decimals);
                app.MessagesTextArea.Value{end+1} = sprintf('Period %d, starting at t=%d', period, t_onset);
            else
                app.MessagesTextArea.Value{end+1} = sprintf('No periodicity found');
            end
            
            %}
        end

        % Menu selected function: PopulationfractionspijtbinaryMenu
        function PopulationfractionspijtbinaryMenuSelected(app, event)
            % make a graph of p_ij(t)
            
            % if cells not binary, skip
            [~, ~, ~, ~, ~, ~, ~, hill, ~, ~, M_int, ~] = get_input_vars(app);
            if hill<Inf
                app.MessagesTextArea.Value{end+1} = 'Cannot plot pij(t), infinite Hill';
                return
            elseif numel(M_int)~=4
                app.MessagesTextArea.Value{end+1} = 'Cannot plot pij(t), 1 signal';
                return
            end
            
            [msg, h] = plot_p_ij_vs_t(app.CellsHist, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
            
        end

        % Menu selected function: SpatialcrosscorrelationMenu
        function SpatialcrosscorrelationMenuSelected(app, event)
            tab = tab_to_num(app);
            % only for 2 signals
            if tab==2 || tab==3
                [~, ~, h] = plot_spatial_cross_corr(app.CellsHist, app.FigurePosition);
                app.FigureHandles{end+1} = h;
                app.MessagesTextArea.Value{end+1} = 'Successfully plotted spatial cross-correlation';
            end
        end

        % Menu selected function: ManuallyinputinitialstateMenu
        function ManuallyinputinitialstateMenuSelected(app, event)
            app.ManualInputInitialState = ~app.ManualInputInitialState;
            if app.ManualInputInitialState
                app.ManuallyinputinitialstateMenu.Checked = 'on';
            else
                app.ManuallyinputinitialstateMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavesystemstatexlsMenu
        function SavesystemstatexlsMenuSelected(app, event)
            % Save the system's state at a given time (user input) as xlsx file.
            % Data is given in such a way that it can be easily re-imported into the app.
            
            % ask for time from user
            prompt = 'Save system state at time: ';
            input_time = str2double(inputdlg(prompt));
            
            % check that the given input time is valid
            if isempty(input_time) || input_time<0 || input_time>length(app.CellsHist)-1
                app.MessagesTextArea.Value{end+1} = 'Invalid input time!';
                return
            end
            
            % save data
            cells = double(app.CellsHist{input_time+1});
            %app.MessagesTextArea.Value{end+1} = num2str(size(cells));
            save_folder = app.InputStateSaveFolder;
            fname_str = sprintf('snapshot_time%d', input_time);
            fname = fullfile(save_folder,fname_str);
            
            [file, path] = uiputfile(strcat(fname, '.xls')); % asks user for output file folder and name
            
            [status, msg] = xlswrite(fullfile(path, file), cells); % save data
            
            % messages
            if status
                app.MessagesTextArea.Value{end+1} = sprintf('Saved cell snapshot xlsx file %s', fname); 
            else
                app.MessagesTextArea.Value{end+1} = 'Save unsuccessful'; 
                app.MessagesTextArea.Value{end+1} = msg.message;
            end
        end

        % Menu selected function: KymographMenu
        function KymographMenuSelected(app, event)
            % Plots a kymograph of the dynamics (space-time plot) 
            % cells are aligned 1:N according to their variable numbering 
            % In practice this corresponds to starting in the upper left corner and counting down
            [~, ~, disp_mol] = get_input_vars(app);
            if disp_mol==1
                genes=1;
            elseif disp_mol==2
                genes=2;
            elseif disp_mol==12
                genes=12;
            end
            [~, msg, h] = plot_kymograph(app.CellsHist, genes);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
            
        end

        % Value changed function: CellsizeEditField_2
        function CellsizeEditField_2ValueChanged(app, event)
            app.rCell = app.CellsizeEditField_2.Value;
            app.ResetButtonPushed;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Tab changed
            
            % Update app.rCell
            tab = tab_to_num(app);
            if tab==1
                app.rCell = app.CellsizeEditField.Value;
            elseif tab==2
                app.rCell = app.CellsizeEditField_2.Value;
            end
            
            % Reset all variables
            app.ResetButtonPushed;
        end

        % Callback function
        function DEditFieldValueChanged(app, event)
            value = app.Sigma_DEditField.Value;
            % -> To do
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MulticellularModelSimulatorUIFigure = uifigure;
            app.MulticellularModelSimulatorUIFigure.Name = 'Multicellular Model Simulator';
            app.FileMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.FileMenu.Text = 'File';
            app.SavetrajectoryMenu = uimenu(app.FileMenu);
            app.SavetrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetrajectoryMenuSelected, true);
            app.SavetrajectoryMenu.Accelerator = 'S';
            app.SavetrajectoryMenu.Text = 'Save trajectory';
            app.LoadtrajectoryMenu = uimenu(app.FileMenu);
            app.LoadtrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtrajectoryMenuSelected, true);
            app.LoadtrajectoryMenu.Accelerator = 'O';
            app.LoadtrajectoryMenu.Text = 'Load trajectory';
            app.SavefigurespdfMenu = uimenu(app.FileMenu);
            app.SavefigurespdfMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefigurespdfMenuSelected, true);
            app.SavefigurespdfMenu.Accelerator = 'F';
            app.SavefigurespdfMenu.Text = 'Save figures (pdf)';
            app.SavefiguresepsMenu = uimenu(app.FileMenu);
            app.SavefiguresepsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefiguresepsMenuSelected, true);
            app.SavefiguresepsMenu.Text = 'Save figures (eps)';
            app.SavemovieaviMenu = uimenu(app.FileMenu);
            app.SavemovieaviMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemovieaviMenuSelected, true);
            app.SavemovieaviMenu.Accelerator = 'M';
            app.SavemovieaviMenu.Text = 'Save movie (avi)';
            app.SavesystemstatexlsMenu = uimenu(app.FileMenu);
            app.SavesystemstatexlsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesystemstatexlsMenuSelected, true);
            app.SavesystemstatexlsMenu.Accelerator = 'X';
            app.SavesystemstatexlsMenu.Text = 'Save system state (xls)';
            app.CloseallfiguresMenu = uimenu(app.FileMenu);
            app.CloseallfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallfiguresMenuSelected, true);
            app.CloseallfiguresMenu.Separator = 'on';
            app.CloseallfiguresMenu.Text = 'Close all figures';
            app.CloseappMenu = uimenu(app.FileMenu);
            app.CloseappMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseappMenuSelected, true);
            app.CloseappMenu.Text = 'Close app';
            app.SimulationMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.RunMenu = uimenu(app.SimulationMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunMenu.Accelerator = 'R';
            app.RunMenu.Text = 'Run';
            app.PauseMenu = uimenu(app.SimulationMenu);
            app.PauseMenu.MenuSelectedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseMenu.Accelerator = 'P';
            app.PauseMenu.Text = 'Pause';
            app.ResetMenu = uimenu(app.SimulationMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetMenu.Accelerator = 'C';
            app.ResetMenu.Text = 'Reset';
            app.MovetostartMenu = uimenu(app.SimulationMenu);
            app.MovetostartMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonToStartPushed, true);
            app.MovetostartMenu.Separator = 'on';
            app.MovetostartMenu.Accelerator = '1';
            app.MovetostartMenu.Text = 'Move to start';
            app.BackstepMenu = uimenu(app.SimulationMenu);
            app.BackstepMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.BackstepMenu.Accelerator = '2';
            app.BackstepMenu.Text = 'Back step';
            app.ForwardstepMenu = uimenu(app.SimulationMenu);
            app.ForwardstepMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonFwdPushed, true);
            app.ForwardstepMenu.Accelerator = '3';
            app.ForwardstepMenu.Text = 'Forward step';
            app.MovetoendMenu = uimenu(app.SimulationMenu);
            app.MovetoendMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonToEndPushed, true);
            app.MovetoendMenu.Accelerator = '4';
            app.MovetoendMenu.Text = 'Move to end';
            app.SkiptotimeMenu = uimenu(app.SimulationMenu);
            app.SkiptotimeMenu.MenuSelectedFcn = createCallbackFcn(app, @SkiptotimeMenuSelected, true);
            app.SkiptotimeMenu.Accelerator = '5';
            app.SkiptotimeMenu.Text = 'Skip to time';
            app.PlotMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.PlotMenu.Text = 'Plot';
            app.KymographMenu = uimenu(app.PlotMenu);
            app.KymographMenu.MenuSelectedFcn = createCallbackFcn(app, @KymographMenuSelected, true);
            app.KymographMenu.Text = 'Kymograph';
            app.MeangeneexpressionptMenu = uimenu(app.PlotMenu);
            app.MeangeneexpressionptMenu.MenuSelectedFcn = createCallbackFcn(app, @MeangeneexpressionptMenuSelected, true);
            app.MeangeneexpressionptMenu.Text = 'Mean gene expression p(t)';
            app.PopulationfractionspijtbinaryMenu = uimenu(app.PlotMenu);
            app.PopulationfractionspijtbinaryMenu.MenuSelectedFcn = createCallbackFcn(app, @PopulationfractionspijtbinaryMenuSelected, true);
            app.PopulationfractionspijtbinaryMenu.Text = 'Population fractions pij(t) (binary)';
            app.SpatialautocorrelationItMenu = uimenu(app.PlotMenu);
            app.SpatialautocorrelationItMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialautocorrelationItMenuSelected, true);
            app.SpatialautocorrelationItMenu.Text = 'Spatial auto-correlation I(t)';
            app.SpatialautocorrelationThetatMenu = uimenu(app.PlotMenu);
            app.SpatialautocorrelationThetatMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialautocorrelationThetatMenuSelected, true);
            app.SpatialautocorrelationThetatMenu.Text = 'Spatial auto-correlation Theta(t)';
            app.SpatialcrosscorrelationMenu = uimenu(app.PlotMenu);
            app.SpatialcrosscorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialcrosscorrelationMenuSelected, true);
            app.SpatialcrosscorrelationMenu.Text = 'Spatial cross-correlation';
            app.DtrajectoryptItMenu = uimenu(app.PlotMenu);
            app.DtrajectoryptItMenu.MenuSelectedFcn = createCallbackFcn(app, @TrajectoryptItMenuSelected, true);
            app.DtrajectoryptItMenu.Text = '2D trajectory (p(t), I(t)) ';
            app.DtrajectoryptThetatMenu = uimenu(app.PlotMenu);
            app.DtrajectoryptThetatMenu.MenuSelectedFcn = createCallbackFcn(app, @TrajectoryptThetatMenuSelected, true);
            app.DtrajectoryptThetatMenu.Text = '2D trajectory (p(t), Theta(t))';
            app.PseudoenergyhtMenu = uimenu(app.PlotMenu);
            app.PseudoenergyhtMenu.MenuSelectedFcn = createCallbackFcn(app, @PseudoenergyhtMenuSelected, true);
            app.PseudoenergyhtMenu.Text = 'Pseudo-energy h(t)';
            app.OptionsMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ShowIMenu = uimenu(app.OptionsMenu);
            app.ShowIMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowIMenuSelected, true);
            app.ShowIMenu.Text = 'Show I';
            app.InitiatewithIt0Menu = uimenu(app.OptionsMenu);
            app.InitiatewithIt0Menu.MenuSelectedFcn = createCallbackFcn(app, @InitiatewithIt0MenuSelected, true);
            app.InitiatewithIt0Menu.Text = 'Initiate with I(t=0)';
            app.ManuallyinputinitialstateMenu = uimenu(app.OptionsMenu);
            app.ManuallyinputinitialstateMenu.MenuSelectedFcn = createCallbackFcn(app, @ManuallyinputinitialstateMenuSelected, true);
            app.ManuallyinputinitialstateMenu.Text = 'Manually input initial state';
            app.PropertiesMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.PropertiesMenu.Text = 'Properties';
            app.CircuittopologyMenu = uimenu(app.PropertiesMenu);
            app.CircuittopologyMenu.MenuSelectedFcn = createCallbackFcn(app, @CircuittopologyMenuSelected, true);
            app.CircuittopologyMenu.Text = 'Circuit topology';
            app.PhasediagramMenu = uimenu(app.PropertiesMenu);
            app.PhasediagramMenu.MenuSelectedFcn = createCallbackFcn(app, @PhasediagramMenuSelected, true);
            app.PhasediagramMenu.Text = 'Phase diagram';
            app.StatediagramsinglecellMenu = uimenu(app.PropertiesMenu);
            app.StatediagramsinglecellMenu.MenuSelectedFcn = createCallbackFcn(app, @StatediagramMenuSelected, true);
            app.StatediagramsinglecellMenu.Text = 'State diagram - single cell';
            app.StatediagramfullsystemMenu = uimenu(app.PropertiesMenu);
            app.StatediagramfullsystemMenu.MenuSelectedFcn = createCallbackFcn(app, @StatediagramfullsystemMenuSelected, true);
            app.StatediagramfullsystemMenu.Text = 'State diagram - full system';
            app.CellcolorlegendMenu = uimenu(app.PropertiesMenu);
            app.CellcolorlegendMenu.MenuSelectedFcn = createCallbackFcn(app, @CellcolorlegendMenuSelected, true);
            app.CellcolorlegendMenu.Text = 'Cell color legend';
            app.PeriodicitytestMenu = uimenu(app.PropertiesMenu);
            app.PeriodicitytestMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriodicitytestMenuSelected, true);
            app.PeriodicitytestMenu.Text = 'Periodicity test';
            app.HelpMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.Version25Menu = uimenu(app.HelpMenu);
            app.Version25Menu.Enable = 'off';
            app.Version25Menu.Separator = 'on';
            app.Version25Menu.Text = 'Version 2.5';
            app.ButtonPlay = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ButtonPlay.Icon = 'app_icons_media-playback-start.png';
            app.ButtonPlay.Text = '';
            app.ButtonStop = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ButtonStop.Icon = 'app_icons_media-playback-stop.png';
            app.ButtonStop.Text = '';
            app.ButtonPause = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.ButtonPause.Icon = 'app_icons_media-playback-pause.png';
            app.ButtonPause.Text = '';
            app.TabGroup = uitabgroup(app.MulticellularModelSimulatorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.signalTab = uitab(app.TabGroup);
            app.signalTab.Title = '1 signal';
            app.GridsizeLabel = uilabel(app.signalTab);
            app.GridsizeLabel.Text = 'Grid size';
            app.GridsizeEditField = uieditfield(app.signalTab, 'numeric');
            app.GridsizeEditField.Limits = [0 Inf];
            app.GridsizeEditField.RoundFractionalValues = 'on';
            app.GridsizeEditField.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GridsizeEditField.Value = 11;
            app.a0EditFieldLabel = uilabel(app.signalTab);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.signalTab, 'numeric');
            app.a0EditField.Limits = [0 Inf];
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.a0EditField.Value = 1.5;
            app.KEditFieldLabel = uilabel(app.signalTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.signalTab, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.KEditField.Value = 7;
            app.ConEditFieldLabel = uilabel(app.signalTab);
            app.ConEditFieldLabel.Text = 'Con';
            app.ConEditField = uieditfield(app.signalTab, 'numeric');
            app.ConEditField.Limits = [0 Inf];
            app.ConEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ConEditField.Value = 16;
            app.pt0EditFieldLabel = uilabel(app.signalTab);
            app.pt0EditFieldLabel.Text = 'p(t=0)';
            app.pt0EditField = uieditfield(app.signalTab, 'numeric');
            app.pt0EditField.Limits = [0 1];
            app.pt0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.pt0EditField.Value = 0.5;
            app.NoiseEditFieldLabel = uilabel(app.signalTab);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.signalTab, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillcoefficientEditFieldLabel = uilabel(app.signalTab);
            app.HillcoefficientEditFieldLabel.Text = 'Hill coefficient';
            app.HillcoefficientEditField = uieditfield(app.signalTab, 'numeric');
            app.HillcoefficientEditField.Limits = [0 Inf];
            app.HillcoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillcoefficientEditField.Value = Inf;
            app.InitialstateLabel = uilabel(app.signalTab);
            app.InitialstateLabel.Text = 'Initial state';
            app.It0EditFieldLabel = uilabel(app.signalTab);
            app.It0EditFieldLabel.Text = 'I(t=0)';
            app.It0EditField = uieditfield(app.signalTab, 'numeric');
            app.It0EditField.Limits = [-1 1];
            app.It0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.InteractionDropDownLabel = uilabel(app.signalTab);
            app.InteractionDropDownLabel.Text = 'Interaction';
            app.InteractionDropDown = uidropdown(app.signalTab);
            app.InteractionDropDown.Items = {'Activation', 'Repression'};
            app.InteractionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.InteractionDropDown.Value = 'Activation';
            app.ParametersLabel = uilabel(app.signalTab);
            app.ParametersLabel.Text = 'Parameters';
            app.CellsizeEditFieldLabel = uilabel(app.signalTab);
            app.CellsizeEditFieldLabel.Text = 'Cell size';
            app.CellsizeEditField = uieditfield(app.signalTab, 'numeric');
            app.CellsizeEditField.Limits = [0.01 0.5];
            app.CellsizeEditField.ValueChangedFcn = createCallbackFcn(app, @CellsizeEditFieldValueChanged, true);
            app.CellsizeEditField.Value = 0.2;
            app.signalsTab = uitab(app.TabGroup);
            app.signalsTab.Title = '2 signals';
            app.GridsizeLabel_2 = uilabel(app.signalsTab);
            app.GridsizeLabel_2.Text = 'Grid size';
            app.GridsizeEditField_2 = uieditfield(app.signalsTab, 'numeric');
            app.GridsizeEditField_2.Limits = [0 Inf];
            app.GridsizeEditField_2.RoundFractionalValues = 'on';
            app.GridsizeEditField_2.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GridsizeEditField_2.Value = 15;
            app.a0EditField_2Label = uilabel(app.signalsTab);
            app.a0EditField_2Label.Text = 'a0';
            app.a0EditField_2 = uieditfield(app.signalsTab, 'numeric');
            app.a0EditField_2.Limits = [0 Inf];
            app.a0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.a0EditField_2.Value = 1.5;
            app.Lambda12EditFieldLabel = uilabel(app.signalsTab);
            app.Lambda12EditFieldLabel.Text = 'Lambda12';
            app.Lambda12EditField = uieditfield(app.signalsTab, 'numeric');
            app.Lambda12EditField.Limits = [0 Inf];
            app.Lambda12EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Lambda12EditField.Value = 1.2;
            app.InteractionsPanel = uipanel(app.signalsTab);
            app.InteractionsPanel.Title = 'Interactions';
            app.K21EditFieldLabel = uilabel(app.InteractionsPanel);
            app.K21EditFieldLabel.Text = 'K21';
            app.K21EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K21EditField.Limits = [0 Inf];
            app.K21EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K21EditField.Value = 11;
            app.K11Label = uilabel(app.InteractionsPanel);
            app.K11Label.Text = 'K11';
            app.K11EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K11EditField.Limits = [0 Inf];
            app.K11EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K12Label = uilabel(app.InteractionsPanel);
            app.K12Label.Text = 'K12';
            app.K12EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K12EditField.Limits = [0 Inf];
            app.K12EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K12EditField.Value = 10;
            app.K22Label = uilabel(app.InteractionsPanel);
            app.K22Label.Text = 'K22';
            app.K22EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K22EditField.Limits = [0 Inf];
            app.K22EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K22EditField.Value = 4;
            app.Label_2 = uilabel(app.InteractionsPanel);
            app.Label_2.Text = '1<-2';
            app.DropDown12 = uidropdown(app.InteractionsPanel);
            app.DropDown12.Items = {'Activation', 'Repression', 'None'};
            app.DropDown12.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown12.Value = 'Activation';
            app.Label_3 = uilabel(app.InteractionsPanel);
            app.Label_3.Text = '2<-1';
            app.DropDown21 = uidropdown(app.InteractionsPanel);
            app.DropDown21.Items = {'Activation', 'Repression', 'None'};
            app.DropDown21.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown21.Value = 'Repression';
            app.Label_4 = uilabel(app.InteractionsPanel);
            app.Label_4.Text = '2<-2';
            app.DropDown22 = uidropdown(app.InteractionsPanel);
            app.DropDown22.Items = {'Activation', 'Repression', 'None'};
            app.DropDown22.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown22.Value = 'Activation';
            app.Con1EditFieldLabel = uilabel(app.InteractionsPanel);
            app.Con1EditFieldLabel.Text = 'Con1';
            app.Con1EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.Con1EditField.Limits = [0 Inf];
            app.Con1EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con1EditField.Value = 18;
            app.Con2EditFieldLabel = uilabel(app.InteractionsPanel);
            app.Con2EditFieldLabel.Text = 'Con2';
            app.Con2EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.Con2EditField.Limits = [0 Inf];
            app.Con2EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con2EditField.Value = 16;
            app.SecretionratesLabel = uilabel(app.InteractionsPanel);
            app.SecretionratesLabel.Text = 'Secretion rates';
            app.Label = uilabel(app.InteractionsPanel);
            app.Label.Text = '1<-1';
            app.DropDown11 = uidropdown(app.InteractionsPanel);
            app.DropDown11.Items = {'Activation', 'Repression', 'None'};
            app.DropDown11.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown11.Value = 'None';
            app.ThresholdsLabel = uilabel(app.InteractionsPanel);
            app.ThresholdsLabel.Text = 'Thresholds';
            app.NoiseEditField_2Label = uilabel(app.signalsTab);
            app.NoiseEditField_2Label.Text = 'Noise';
            app.NoiseEditField_2 = uieditfield(app.signalsTab, 'numeric');
            app.NoiseEditField_2.Limits = [0 Inf];
            app.NoiseEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillcoefficientEditField_2Label = uilabel(app.signalsTab);
            app.HillcoefficientEditField_2Label.Text = 'Hill coefficient';
            app.HillcoefficientEditField_2 = uieditfield(app.signalsTab, 'numeric');
            app.HillcoefficientEditField_2.Limits = [0 Inf];
            app.HillcoefficientEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillcoefficientEditField_2.Value = Inf;
            app.InitialconditionsPanel = uipanel(app.signalsTab);
            app.InitialconditionsPanel.Title = 'Initial conditions';
            app.p1t0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.p1t0EditFieldLabel.Text = 'p1(t=0)';
            app.p1t0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.p1t0EditField.Limits = [0 1];
            app.p1t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p1t0EditField.Value = 0.5;
            app.p2t0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.p2t0EditFieldLabel.Text = 'p2(t=0)';
            app.p2t0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.p2t0EditField.Limits = [0 1];
            app.p2t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p2t0EditField.Value = 0.5;
            app.I1t0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.I1t0EditFieldLabel.Text = 'I1(t=0)';
            app.I1t0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.I1t0EditField.Limits = [-1 1];
            app.I1t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.I2t0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.I2t0EditFieldLabel.Text = 'I2(t=0)';
            app.I2t0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.I2t0EditField.Limits = [-1 1];
            app.I2t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ParametersLabel_2 = uilabel(app.signalsTab);
            app.ParametersLabel_2.Text = 'Parameters';
            app.CellsizeEditField_2Label = uilabel(app.signalsTab);
            app.CellsizeEditField_2Label.Text = 'Cell size';
            app.CellsizeEditField_2 = uieditfield(app.signalsTab, 'numeric');
            app.CellsizeEditField_2.Limits = [0.01 0.5];
            app.CellsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @CellsizeEditField_2ValueChanged, true);
            app.CellsizeEditField_2.Value = 0.2;
            app.MessagesTextAreaLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages ';
            app.MessagesTextArea = uitextarea(app.MulticellularModelSimulatorUIFigure);
            app.ButtonToStart = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonToStart.ButtonPushedFcn = createCallbackFcn(app, @ButtonToStartPushed, true);
            app.ButtonToStart.Icon = 'app_icons_media-skip-backward.png';
            app.ButtonToStart.Text = '';
            app.ButtonBack = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = 'app_icons_media-seek-backward.png';
            app.ButtonBack.Text = '';
            app.ButtonFwd = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonFwd.ButtonPushedFcn = createCallbackFcn(app, @ButtonFwdPushed, true);
            app.ButtonFwd.Icon = 'app_icons_media-seek-forward.png';
            app.ButtonFwd.Text = '';
            app.ButtonToEnd = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonToEnd.ButtonPushedFcn = createCallbackFcn(app, @ButtonToEndPushed, true);
            app.ButtonToEnd.Icon = 'app_icons_media-skip-forward.png';
            app.ButtonToEnd.Text = '';
            app.UIAxes = uiaxes(app.MulticellularModelSimulatorUIFigure);
            app.SpeedSlider = uislider(app.MulticellularModelSimulatorUIFigure);
            app.SpeedSlider.Limits = [1 20];
            app.SpeedSlider.MajorTicks = [1 20];
            app.SpeedSlider.MajorTickLabels = {'Low', 'High'};
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 5;
            app.SimulationspeedLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.SimulationspeedLabel.Text = 'Simulation speed';
            app.RandomizeLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.RandomizeLabel.Text = 'Randomize';
            app.MonteCarlostepsLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.MonteCarlostepsLabel.Text = 'Monte Carlo steps';
            app.MonteCarlostepsDropDown = uidropdown(app.MulticellularModelSimulatorUIFigure);
            app.MonteCarlostepsDropDown.Items = {'0', '10', '100', '1000', '10000'};
            app.MonteCarlostepsDropDown.Editable = 'on';
            app.MonteCarlostepsDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.MonteCarlostepsDropDown.Value = '0';
            app.DisplaysignalButtonGroup = uibuttongroup(app.MulticellularModelSimulatorUIFigure);
            app.DisplaysignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaysignalButtonGroupSelectionChanged, true);
            app.DisplaysignalButtonGroup.Title = 'Display signal';
            app.Button1 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button1.Text = '1';
            app.Button2 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button2.Text = '2';
            app.Button12 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button12.Text = 'Both';
            app.Button12.Value = true;
            app.MovingcellsLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.MovingcellsLabel.Text = 'Moving cells';
            app.Sigma_DEditFieldLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.Sigma_DEditFieldLabel.Text = 'Sigma_D';
            app.Sigma_DEditField = uieditfield(app.MulticellularModelSimulatorUIFigure, 'numeric');
            app.Sigma_DEditField.Limits = [0 Inf];
            app.Sigma_DEditField.ValueDisplayFormat = '%e';
            app.Sigma_DEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GrowingcellsLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.GrowingcellsLabel.Text = 'Growing cells';
            app.c_growthEditFieldLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.c_growthEditFieldLabel.Text = 'c_growth';
            app.c_growthEditField = uieditfield(app.MulticellularModelSimulatorUIFigure, 'numeric');
            app.c_growthEditField.Limits = [0 Inf];
            app.c_growthEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.k_growthEditFieldLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.k_growthEditFieldLabel.Text = 'k_growth';
            app.k_growthEditField = uieditfield(app.MulticellularModelSimulatorUIFigure, 'numeric');
            app.k_growthEditField.Limits = [0 Inf];
            app.k_growthEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.sigma_rcellEditFieldLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.sigma_rcellEditFieldLabel.Text = 'sigma_rcell';
            app.sigma_rcellEditField = uieditfield(app.MulticellularModelSimulatorUIFigure, 'numeric');
            app.sigma_rcellEditField.Limits = [0 Inf];
            app.sigma_rcellEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.InitialconfigurationLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.InitialconfigurationLabel.Text = 'Initial configuration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_R2_5_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MulticellularModelSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MulticellularModelSimulatorUIFigure)
        end
    end
end