classdef app_R2_4_v4_R2018a_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MulticellularModelSimulatorUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavetrajectoryMenu              matlab.ui.container.Menu
        LoadtrajectoryMenu              matlab.ui.container.Menu
        SavefigurespdfMenu              matlab.ui.container.Menu
        SavefiguresepsMenu              matlab.ui.container.Menu
        SavemovieaviMenu                matlab.ui.container.Menu
        SavesystemstatexlsMenu          matlab.ui.container.Menu
        CloseallfiguresMenu             matlab.ui.container.Menu
        CloseappMenu                    matlab.ui.container.Menu
        SimulationMenu                  matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        PauseMenu                       matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        MovetostartMenu                 matlab.ui.container.Menu
        BackstepMenu                    matlab.ui.container.Menu
        ForwardstepMenu                 matlab.ui.container.Menu
        MovetoendMenu                   matlab.ui.container.Menu
        SkiptotimeMenu                  matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        KymographMenu                   matlab.ui.container.Menu
        MeangeneexpressionptMenu        matlab.ui.container.Menu
        PopulationfractionspijtbinaryMenu  matlab.ui.container.Menu
        SpatialautocorrelationItMenu    matlab.ui.container.Menu
        SpatialautocorrelationThetatMenu  matlab.ui.container.Menu
        SpatialcrosscorrelationMenu     matlab.ui.container.Menu
        DtrajectoryptItMenu             matlab.ui.container.Menu
        DtrajectoryptThetatMenu         matlab.ui.container.Menu
        PseudoenergyhtMenu              matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ShowIMenu                       matlab.ui.container.Menu
        InitiatewithIt0Menu             matlab.ui.container.Menu
        ManuallyinputinitialstateMenu   matlab.ui.container.Menu
        PropertiesMenu                  matlab.ui.container.Menu
        CircuittopologyMenu             matlab.ui.container.Menu
        PhasediagramMenu                matlab.ui.container.Menu
        StatediagramsinglecellMenu      matlab.ui.container.Menu
        StatediagramfullsystemMenu      matlab.ui.container.Menu
        CellcolorlegendMenu             matlab.ui.container.Menu
        PeriodicitytestMenu             matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        Version24Menu                   matlab.ui.container.Menu
        ButtonPlay                      matlab.ui.control.Button
        ButtonStop                      matlab.ui.control.Button
        ButtonPause                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        signalTab                       matlab.ui.container.Tab
        GridsizeEditFieldLabel          matlab.ui.control.Label
        GridsizeEditField               matlab.ui.control.NumericEditField
        a0EditFieldLabel                matlab.ui.control.Label
        a0EditField                     matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        ConEditFieldLabel               matlab.ui.control.Label
        ConEditField                    matlab.ui.control.NumericEditField
        pt0EditFieldLabel               matlab.ui.control.Label
        pt0EditField                    matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        HillCoefficientEditFieldLabel   matlab.ui.control.Label
        HillCoefficientEditField        matlab.ui.control.NumericEditField
        CellstatesSwitchLabel           matlab.ui.control.Label
        CellstatesSwitch                matlab.ui.control.Switch
        InitialstateLabel               matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BinaryButton                    matlab.ui.control.ToggleButton
        MCrandomizedButton              matlab.ui.control.ToggleButton
        It0EditFieldLabel               matlab.ui.control.Label
        It0EditField                    matlab.ui.control.NumericEditField
        InteractionDropDownLabel        matlab.ui.control.Label
        InteractionDropDown             matlab.ui.control.DropDown
        ContinuoussystemLabel           matlab.ui.control.Label
        signalsmultTab                  matlab.ui.container.Tab
        GridsizeEditField_2Label        matlab.ui.control.Label
        GridsizeEditField_2             matlab.ui.control.NumericEditField
        a0EditField_2Label              matlab.ui.control.Label
        a0EditField_2                   matlab.ui.control.NumericEditField
        DiffusionconstratioEditFieldLabel  matlab.ui.control.Label
        DiffusionconstratioEditField    matlab.ui.control.NumericEditField
        InteractionsPanel               matlab.ui.container.Panel
        K21EditFieldLabel               matlab.ui.control.Label
        K21EditField                    matlab.ui.control.NumericEditField
        K11Label                        matlab.ui.control.Label
        K11EditField                    matlab.ui.control.NumericEditField
        K12Label                        matlab.ui.control.Label
        K12EditField                    matlab.ui.control.NumericEditField
        K22Label                        matlab.ui.control.Label
        K22EditField                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        DropDown12                      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        DropDown21                      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown22                      matlab.ui.control.DropDown
        Con1EditFieldLabel              matlab.ui.control.Label
        Con1EditField                   matlab.ui.control.NumericEditField
        Con2EditFieldLabel              matlab.ui.control.Label
        Con2EditField                   matlab.ui.control.NumericEditField
        SecretionratesLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DropDown11                      matlab.ui.control.DropDown
        p1t0EditFieldLabel              matlab.ui.control.Label
        p1t0EditField                   matlab.ui.control.NumericEditField
        p2t0EditFieldLabel              matlab.ui.control.Label
        p2t0EditField                   matlab.ui.control.NumericEditField
        NoiseEditField_2Label           matlab.ui.control.Label
        NoiseEditField_2                matlab.ui.control.NumericEditField
        HillCoeffEditFieldLabel         matlab.ui.control.Label
        HillCoeffEditField              matlab.ui.control.NumericEditField
        DisplaysignalButtonGroup        matlab.ui.container.ButtonGroup
        Button1                         matlab.ui.control.ToggleButton
        Button2                         matlab.ui.control.ToggleButton
        Button12                        matlab.ui.control.ToggleButton
        MultiplicativeinteractionANDLabel  matlab.ui.control.Label
        InitialconditionsLabel_3        matlab.ui.control.Label
        I1t0EditFieldLabel              matlab.ui.control.Label
        I1t0EditField                   matlab.ui.control.NumericEditField
        I2t0EditFieldLabel              matlab.ui.control.Label
        I2t0EditField                   matlab.ui.control.NumericEditField
        signalsaddTab                   matlab.ui.container.Tab
        DisplaysignalButtonGroup_2      matlab.ui.container.ButtonGroup
        Button1_2                       matlab.ui.control.ToggleButton
        Button2_2                       matlab.ui.control.ToggleButton
        Button12_2                      matlab.ui.control.ToggleButton
        AdditiveinteractionORLabel      matlab.ui.control.Label
        GridsizeEditField_3Label        matlab.ui.control.Label
        GridsizeEditField_3             matlab.ui.control.NumericEditField
        a0EditField_3Label              matlab.ui.control.Label
        a0EditField_3                   matlab.ui.control.NumericEditField
        NoiseEditField_3Label           matlab.ui.control.Label
        NoiseEditField_3                matlab.ui.control.NumericEditField
        HillCoeffEditField_2Label       matlab.ui.control.Label
        HillCoeffEditField_2            matlab.ui.control.NumericEditField
        DiffusionconstratioEditField_2Label  matlab.ui.control.Label
        DiffusionconstratioEditField_2  matlab.ui.control.NumericEditField
        InteractionsPanel_2             matlab.ui.container.Panel
        K21EditField_2Label             matlab.ui.control.Label
        K21EditField_2                  matlab.ui.control.NumericEditField
        K11EditField_2Label             matlab.ui.control.Label
        K11EditField_2                  matlab.ui.control.NumericEditField
        K12EditField_2Label             matlab.ui.control.Label
        K12EditField_2                  matlab.ui.control.NumericEditField
        K22EditField_2Label             matlab.ui.control.Label
        K22EditField_2                  matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        DropDown12_2                    matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        DropDown21_2                    matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        DropDown22_2                    matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        DropDown11_2                    matlab.ui.control.DropDown
        Con11EditFieldLabel             matlab.ui.control.Label
        Con11EditField                  matlab.ui.control.NumericEditField
        Con21EditFieldLabel             matlab.ui.control.Label
        Con21EditField                  matlab.ui.control.NumericEditField
        Con22EditFieldLabel             matlab.ui.control.Label
        Con22EditField                  matlab.ui.control.NumericEditField
        Con12EditFieldLabel             matlab.ui.control.Label
        Con12EditField                  matlab.ui.control.NumericEditField
        p1t0EditField_2Label            matlab.ui.control.Label
        p1t0EditField_2                 matlab.ui.control.NumericEditField
        p2t0EditField_2Label            matlab.ui.control.Label
        p2t0EditField_2                 matlab.ui.control.NumericEditField
        InitialconditionsLabel_4        matlab.ui.control.Label
        I1t0EditField_2Label            matlab.ui.control.Label
        I1t0EditField_2                 matlab.ui.control.NumericEditField
        I2t0EditField_2Label            matlab.ui.control.Label
        I2t0EditField_2                 matlab.ui.control.NumericEditField
        MessagesTextAreaLabel           matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        ButtonToStart                   matlab.ui.control.Button
        ButtonBack                      matlab.ui.control.Button
        ButtonFwd                       matlab.ui.control.Button
        ButtonToEnd                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SpeedSlider                     matlab.ui.control.Slider
        SimulationspeedLabel            matlab.ui.control.Label
        RandomizedLabel                 matlab.ui.control.Label
        LatticeLabel                    matlab.ui.control.Label
        MCstepsDropDownLabel            matlab.ui.control.Label
        MCstepsDropDown                 matlab.ui.control.DropDown
        CellSizeEditFieldLabel          matlab.ui.control.Label
        CellSizeEditField               matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        % app control variables
        SelectedTab = 1; % currently selected tab (set to app.TabGroup.SelectedTab later)
        
        % simulation control flow
        ContinueSimulation = 1; %When set to 0, simulation stops
        NewSimulation = 1; %When set to 0, simulation continues where it left off.
        ReplayTrajectory = 0; % When set to 1, simulator will show a loaded trajectory
        Changed = 1; %When set to 0, equilibrium state reached
        showI = 0; % Whether to show I on top of simulation (slower)
        InitiateI = 0; % Whether to initiate the system with given I
        ManualInputInitialState = 0; % Whether to manualyl input an initial state
        
        % simulation variables
        Time = 0;
        CellsOut = [];
        CellsHist = {}; % store trajectories
        %TimeFinal = 0; % final time of loaded simulation
        
        % Figures
        num_fig = 10; %number of figures in app
        PlotHandle = []; % handle for cell lattice plot (scatter)
        FigureHandles = {}; % stores all handles to plot figures
        
        % I/O
        DefaultSaveFolder = fullfile('..', '..', 'data', 'time_evolution'); % default (parent) folder for saving (simulation) data
        InputStateSaveFolder = fullfile('..', '..', 'data', 'system_states'); % folder for saving and loading input states (xls data)
        
        % simulation constants
        rCell = 0;
        tMax = 10^6;
        precision = 8; %precision for continuous system
        dI = 0.01; % Maximum allowed range for generated I (e.g. generate I within [I_min, I_min+dI]
        
        FigurePosition = [0.1 0.1 7 5]; % Default position and size of plotted figures
        SimID = {'one_signal', 'two_signal_mult', 'two_signal_add'}; % labels for simulation properties / tabs
        
        % save positions and distances
        dist = []; % distance matrix
        pos = [];  % position vector
    end

    methods (Access = private)
       
        function reset_cell_figure(app)
            ax = app.UIAxes;
            cla(ax);
            title(ax, 'Simulate dynamics', 'FontSize', 20);
            
            % set image properties
            set(ax, 'YTick', [], 'XTick', []);
            
            % Add color bar for Xi
            %{
            c = colorbar(ax);
            c.Ticks = 0:0.2:1;
            set(c, 'FontSize', 12);
            %c.Label.String = '$$X_i$$';
            %ylabel(c, '$$X_i$$', 'Interpreter', 'latex', 'FontSize', 16,...
            %    'Rotation', 90);
            map = repmat((1:-0.01:0)', 1, 3);
            %map = 'gray';
            colormap(ax, map);
            %}
        end
        
        function generate_lattice(app, n, mcsteps, new_lattice, hex_lattice)
            %hex_lattice: whether to plot lattice in the old way
            ax = app.UIAxes;
            hold(ax, 'on')
            if nargin<5
                hex_lattice = 0;
            end
            
            if new_lattice && mcsteps>0
                app.MessagesTextArea.Value{1} = 'Generating lattice...';
                [app.pos, app.dist, ~, rejections] = initial_cells_random_markov_periodic(n, mcsteps, app.rCell);
                app.MessagesTextArea.Value{2} = sprintf('MC steps: %d, rejections = %d', mcsteps, rejections);
            elseif new_lattice && mcsteps==0
                [app.pos, app.dist, ~, ~] = initial_cells_random_markov_periodic(n, mcsteps, app.rCell);
            end
            
            % Plot lattice: (1) hexagonal old style, (2) new style box
            if hex_lattice
                % Show hexagonal lattice
                [app.dist, app.pos] = init_dist_hex(n, n);
                
                % set image properties
                set(ax, 'YTick', [], 'XTick', []);
                xlim(ax, [-1 app.pos(end,1)+1]);
                ylim(ax, [-1 app.pos(end,2)+1]);
                
                %% calculate figure dimensions
                N = size(app.pos,1);
                gz = sqrt(N);
                Lx = app.UIAxes.Position(3); %512;
                %Ly = (sqrt(3)/2*(gz-1)+2)/(3/2*(gz+1))*Lx;
                a0 = Lx/(3/2*(gz+1));
                Rcell = app.rCell*a0;
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'Lx = ', num2str(Lx));
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'a0 = ', num2str(a0));
    
                % --plot cells--
                % colours
                c_all = ones(N, 3);
                clr_k = zeros(N, 3); % black boundaries
                %markers = {'o', 's'};
                
                app.PlotHandle = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, c_all, 'filled', 'o');
                scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, clr_k, 'o'); % plot cell boundaries
            else 
                % Generate new lattice
                d = 2*app.rCell*1/(n+1);
                xlim(ax, [-d 1+d]);
                ylim(ax, [-d sqrt(3)/2+d]);
                
                %% calculate figure dimensions
                N = n^2;
                Sx = app.UIAxes.InnerPosition(3); %512;
                %Sy = (sqrt(3)/2*(gz-1)+2)/(3/2*(gz+1))*Lx;
                a0 = Sx/(1+2*d)/(n+1);
                Rcell = app.rCell*a0;
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'Lx = ', num2str(Lx));
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'a0 = ', num2str(a0));
    
                % --plot cells--
                % colours
                c_all = ones(N, 3); % default: white cells
                app.PlotHandle = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, c_all, 'filled', 'o', 'MarkerEdgeColor', [0 0 0]);
                %scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, zeros(N,3), 'o'); % plot cell boundaries, not needed
                
                % plot round and square cells separately
                %{
                %markers = {'o', 's'};
                idx0 = (cell_type==0);
                p0 = scatter(ax,pos(idx0,1), pos(idx0,2), Rcell^2, c_all(idx0, :), 'filled', 'o');
                scatter(ax,pos(idx0,1), pos(idx0,2), Rcell^2, clr_k(idx0, :), 'o'); % plot cell boundaries
                
                idx1 = (cell_type==1);
                p1 = scatter(ax,pos(idx1,1), pos(idx1,2), Rcell^2, c_all(idx1, :), 'filled', 's');
                scatter(ax,pos(idx1,1), pos(idx1,2), Rcell^2, clr_k(idx1, :), 's'); % plot cell boundaries
                %}
                
                % Plot box outline
                Lx = 1; Ly = sqrt(3)/2;
                plot(ax,[0 Lx], [0 0], 'k--');
                plot(ax,[0 Lx], [Ly Ly], 'k--');
                plot(ax,[0 0], [0 Ly], 'k--');
                plot(ax,[Lx Lx], [0 Ly], 'k--');
                
                hold(ax, 'off');
            end
        end
        
        function update_cell_figure_continuum(app, dist, a0, cells, t, disp_mol)
            ax = app.UIAxes;
            
            if disp_mol==12 % plot both colors
                % no signal -> white
                % signal 1 -> yellow
                % signal 2 -> blue 
                % signals 1&2 -> black
                
                % plot title
                if app.showI %whether or not to display I (slow down simulation)
                    %title(ax, sprintf('t=%d, p = %.2f', t, mean(cells)), ...
                    %    'FontSize', 20);
                    this_p = mean(cells, 1);
                    this_I = [moranI(cells(:, 1), a0*dist) moranI(cells(:, 2), a0*dist)];
                    title(ax, sprintf('t=%d, p1 = %.2f, p2 = %.2f, I1 = %.2f, I2 = %.2f', t,...
                        this_p(1), this_p(2), this_I(1), this_I(2)), ...
                        'FontSize', 20);
                else
                    this_p = mean(cells, 1);
                    title(ax, sprintf('t=%d, p1 = %.2f, p2 = %.2f', t, this_p(1), this_p(2)), ...
                        'FontSize', 20);
                end
                
                % --update cells--
                clrs = 1-cells;
                c_all = zeros(size(cells, 1), 3); 
                c_all(:, 3) = clrs(:, 1); % signal 1 present -> Turn on blue channel
                c_all(:, 2) = clrs(:, 2); % signal 2 present -> Turn on green channel
                c_all(:, 1) = clrs(:, 2); % signal 2 present -> Turn on red channel
            else
                % show molecule number (not optimized)
                i_str = ''; 
                if ~isempty(disp_mol)
                    cells = cells(:, disp_mol);
                    i_str = num2str(disp_mol); 
                end
                
                % plot title
                if app.showI %whether or not to display I (slow down simulation)
                    %title(ax, sprintf('t=%d, p = %.2f', t, mean(cells)), ...
                    %    'FontSize', 20);
                    this_p = mean(cells);
                    this_I = moranI(cells, a0*dist);
                    title(ax, sprintf('t=%d, p%s = %.2f, I%s = %.2f', t, i_str, this_p, i_str, this_I), ...
                        'FontSize', 20);
                else
                    this_p = mean(cells);
                    title(ax, sprintf('t=%d, p%s = %.2f', t, i_str, this_p), ...
                        'FontSize', 20);
                end
                
                c_all = repmat(1-cells, 1, 3);
            end
            
            % --update cells--
            % Inefficient code: replot lattice at each time step
            %{
            N = size(app.pos,1);
            gz = sqrt(N);
            d = 2*app.rCell*1/(gz+1);
            Sx = app.UIAxes.InnerPosition(3); %512;
            %Sy = (sqrt(3)/2*(gz-1)+2)/(3/2*(gz+1))*Lx;
            a0 = Sx/(1+2*d)/(gz+1);
            Rcell = app.rCell*a0;
            
            hold(ax, 'on');
            app.PlotHandle = scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, c_all, 'filled', 'o');
            %scatter(ax, app.pos(:,1), app.pos(:,2), Rcell^2, zeros(N, 3), 'o'); % plot cell boundaries
            hold(ax, 'off');
            app.MessagesTextArea.Value = num2str(app.PlotHandle.CData(1,:));
            %}
            
            % Efficient code: update cell colors only
            %set(app.PlotHandle, 'CData', c_all);
            %
            %hold(ax, 'on');
            app.PlotHandle.CData = c_all;
            %app.PlotHandle.Marker = 'd';
            %hold(ax, 'off');
            %app.MessagesTextArea.Value = num2str(app.PlotHandle.CData(1,:));
            %}
            
            % to add: different cell types
                
            % pause before plotting next frame
            pauseTime = 1/app.SpeedSlider.Value;
            pause(pauseTime);
        end
        %}
        
        function simulate_cells_binary(app, N, dist, M_int, a0, Rcell, Con, K, noise, p0, I0, tmax, new_sim)
            if new_sim % start new simulation
                app.MessagesTextArea.Value{end+1} = 'New simulation;';
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'New simulation; ');
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('app.Changed = %d; ', app.Changed));
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; binary states');
                
                % reset variables 
                reset_app_vars(app);
                
                % initiate system
                if app.ManualInputInitialState
                    signal_count = 1;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                else
                    iniON = round(p0*N);
                    cells = zeros(N, 1);
                    cells(randperm(N, iniON)) = 1; 
                    if app.InitiateI
                        app.MessagesTextArea.Value{end+1} = 'Generating lattice with I(t=0)...';
                        [cells, test, I_ini, t_out] = generate_I_new(cells, I0, I0+app.dI, dist, a0);
                        app.MessagesTextArea.Value{end+1} = sprintf('Generated I: %.2f; in range (Y=1/N=0)? %d, t_out = %d;', I_ini, test, t_out);
                        %app.MessagesTextArea.Value{end+1} = sprintf('Generated initial I: %.2f; in range (Y=1/N=0)? %d;', I_ini, test);
                    end
                end
                
                % update figure and cells
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, [])
                [app.CellsOut, app.Changed] = update_cells_noise(cells, dist, M_int, Con, K, a0, Rcell, noise);
                
                % store ini vars
                app.CellsHist{end+1} = cells;
            elseif (app.Changed && app.Time < tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
            end
            
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('app.Changed = %d; ', app.Changed));
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('app.Time = %d; ', app.Time));
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('app.ContinueSimulation = %d; ', app.ContinueSimulation));
            
            % else: continue simulation
            % ax = app.UIAxes;
            while app.Changed && app.Time < tmax && app.ContinueSimulation
                %pause(0.2);
                app.Time = app.Time+1;
                update_cell_figure_continuum(app, dist, a0, app.CellsOut, app.Time, [])
                app.CellsHist{end+1} = app.CellsOut;
                cells = app.CellsOut;
                
                try % needed to prevent STOP / ResetButtonPushed event to mess up simulation
                    [app.CellsOut, app.Changed] = update_cells_noise(cells, dist, M_int, Con, K, a0, Rcell, noise);
                catch
                    % no real error
                end
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; size(cells) = ', num2str(numel(app.CellsOut)) );
            end
            
            if app.Time == tmax
                app.MessagesTextArea.Value{end+1} = 'max. simulation time reached;';
            end
        end
        
        function simulate_cells_continuous(app, N, dist, M_int, a0, Rcell, Con, K, noise, hill, prec, p0, tmax, new_sim)
            if new_sim % if starting new simulation
                app.MessagesTextArea.Value{end+1} = 'New simulation;';
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; continuous states');
                
                % reset variables 
                reset_app_vars(app);
                
                % initiate lattice
                if app.ManualInputInitialState
                    signal_count = 1;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                else
                    iniON = round(p0*N);
                    if app.BinaryButton.Value
                        %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; binary initial conditions');
                        % binary
                        cells = zeros(N,1);
                        cells(randperm(N,iniON)) = 1; % binary initial states
                    elseif app.MCrandomizedButton.Value
                        %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, '; MC randomized initial conditions');
                        % Monte Carlo randomized
                        cells = init_random_cells_montecarlo(N, p0);
                    else
                        app.MessagesTextArea.Value = 'Error (initial conditions button)';
                    end
                end
                
                % store variables
                app.CellsHist{end+1} = cells;
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, [])
                [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                    cells, dist, M_int, Con, K, a0, Rcell, noise, hill, prec); 
            elseif (app.Changed && app.Time < tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
            end
                        
            while app.Changed && app.Time < tmax && app.ContinueSimulation
                app.Time = app.Time+1;
                update_cell_figure_continuum(app, dist, a0, app.CellsOut, app.Time, [])
                app.CellsHist{end+1} = app.CellsOut;
                cells = app.CellsOut;
                try 
                    [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                        cells, dist, M_int, Con, K, a0, Rcell, noise, hill, prec);  
                catch
                end
            end
            
            if app.Time == tmax
                app.MessagesTextArea.Value{end+1} = 'max. simulation time reached; ';
            end

        end        
        
        function simulate_cells_two_signals_multiply(app, N, dist, M_int, lambda12, a0, Rcell,...
                Con, Coff, K, hill, noise, p0, I0, tmax, new_sim, disp_mol)
            lambda = [1 lambda12];
            if new_sim % if starting new simulation
                app.MessagesTextArea.Value{end+1} = 'New simulation;';
                
                % reset app variables 
                reset_app_vars(app);
                
                % generate initial lattice
                if app.ManualInputInitialState
                    % Manually input initial state
                    signal_count = 2;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                else
                    % Generate random lattice
                    iniON = round(p0*N);
                    cells = zeros(N, 2);
                    for i=1:numel(iniON)
                        cells(randperm(N,iniON(i)), i) = 1;
                        if app.InitiateI && hill==Inf
                            app.MessagesTextArea.Value{end+1} = sprintf('Generating lattice with I%d(t=0)...', i);
                            [cells_temp, test, I_ini] = generate_I_new(cells(:, i), I0(i), I0(i)+app.dI, dist, a0);
                            cells(:,i) = cells_temp;
                            app.MessagesTextArea.Value{end+1} = sprintf('Generated initial I%d: %.2f; in range (Y=1/N=0)? %d;', i, I_ini, test);
                        end
                    end
                end
                % store initial config
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, disp_mol);
                [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells, dist, M_int, a0,...
                    Rcell, Con, Coff, K, lambda, hill, noise);
            elseif (app.Changed && app.Time < tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
            end
            
            while app.Changed && app.Time < tmax && app.ContinueSimulation
                %pause(0.2);
                app.Time = app.Time+1;
                cells = app.CellsOut;
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, disp_mol);
                try
                    [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells, dist, M_int, a0,...
                        Rcell, Con, Coff, K, lambda, hill, noise);
                catch
                end
            end
            
            if app.Time == tmax
                app.MessagesTextArea.Value{end+1} = 'max. simulation time reached; ';
            end
            
        end
        
        function simulate_cells_two_signals_add(app, N, dist, M_int, lambda12, a0, Rcell,...
                Con, Coff, K, hill, noise, p0, I0, tmax, new_sim, disp_mol)
            lambda = [1 lambda12];
            if new_sim % if starting new simulation
                app.MessagesTextArea.Value{end+1} = 'New simulation; ';
                % reset app variables 
                reset_app_vars(app);
                
                if app.ManualInputInitialState
                    % Manually input initial state
                    signal_count = 2;
                    [status, cells] = manual_input_state(app, signal_count, N);
                    if ~status
                        return
                    end
                else
                    % Generate random lattice
                    iniON = round(p0*N);
                    cells = zeros(N, 2);
                    for i=1:numel(iniON)
                        cells(randperm(N,iniON(i)), i) = 1;
                        if app.InitiateI && hill==Inf
                            app.MessagesTextArea.Value{end+1} = sprintf('Generating lattice with I%d(t=0)...', i);
                            [cells_temp, test, I_ini] = generate_I_new(cells(:, i), I0(i), I0(i)+app.dI, dist, a0);
                            cells(:,i) = cells_temp;
                            app.MessagesTextArea.Value{end+1} = sprintf('Generated initial I%d: %.2f; in range (Y=1/N=0)? %d;', i, I_ini, test);
                        end
                    end
                end
                % store variables
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, disp_mol);
                [app.CellsOut, app.Changed] = update_cells_two_signals_add_finite_Hill(cells,...
                    dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
            elseif (app.Changed && app.Time < tmax && app.ContinueSimulation)
                app.MessagesTextArea.Value = 'Simulation continued;';
            end
            
            while app.Changed && app.Time < tmax && app.ContinueSimulation
                %pause(0.2);
                app.Time = app.Time+1;
                cells = app.CellsOut;
                app.CellsHist{end+1} = cells; %{cells(:, 1), cells(:, 2)};
                update_cell_figure_continuum(app, dist, a0, cells, app.Time, disp_mol);
                try 
                    [app.CellsOut, app.Changed] = update_cells_two_signals_add_finite_Hill(cells,...
                        dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                catch
                end
            end
            
            if app.Time == tmax
                app.MessagesTextArea.Value{end+1} = 'maximal simulation time reached; ';
            end            
        end
        
        function temp = dropDownConvert(app, input)
            % converts values from dropdown menu into booleans
            if strcmp(input, 'Activation')
                temp = 1; % store output as temporary value
            elseif strcmp(input, 'Repression')
                %app.tempValue = -1;
                temp = -1;
            elseif strcmp(input, 'None')
                temp = 0;
            else
                warning('Error message 2: dropDownConvert input of the wrong type');
            end
        end
        
        function reset_app_vars(app)
            % resets the app variables that store data
            % ! do not change variables that control simulation flow !
            % HAS TO BE UPDATED BY HAND after introducing new variables
            app.Changed = 1; %When set to 0, equilibrium state reached
            app.Time = 0;
            app.CellsOut = [];
            %app.tempValue = NaN; % store temporary values
            app.CellsHist = {}; % store trajectories
            app.FigureHandles = {}; % remove figure handles
        end
        
        function [gz, a0, disp_mol, K, Con, Coff, noise, hill, p0, I0, M_int, lambda12] = get_input_vars(app)
            tab = tab_to_num(app);
            switch tab
                case 1
                    gz = app.GridsizeEditField.Value;
                    a0 = app.a0EditField.Value;
                    K = app.KEditField.Value;
                    Con = app.ConEditField.Value;
                    noise = app.NoiseEditField.Value;
                    hill = app.HillCoefficientEditField.Value;
                    p0 = app.pt0EditField.Value;
                    I0 = app.It0EditField.Value;
                    
                    M_int = dropDownConvert(app, app.InteractionDropDown.Value);
                    % define irrelevant variables
                    Coff = 1;
                    disp_mol = 1;
                    lambda12 = [];
                    
                case 2
                    gz = app.GridsizeEditField_2.Value;
                    a0 = app.a0EditField_2.Value;
                    Con = [app.Con1EditField.Value app.Con2EditField.Value];
                    %Coff = [app.Coff1EditField.Value app.Coff2EditField.Value];
                    Coff = [1 1];
                    lambda12 = app.DiffusionconstratioEditField.Value;
                    noise = app.NoiseEditField_2.Value;
                    hill = app.HillCoeffEditField.Value; 
                    p0 = [app.p1t0EditField.Value app.p2t0EditField.Value];
                    I0 = [app.I1t0EditField.Value app.I2t0EditField.Value];
                    
                    M_int = zeros(2,2); % do not change order of commands in next 4 lines
                    %dropDownConvert(app, app.DropDown11.Value); M_int(1,1) = app.tempValue;
                    %dropDownConvert(app, app.DropDown12.Value); M_int(1,2) = app.tempValue; 
                    %dropDownConvert(app, app.DropDown21.Value); M_int(2,1) = app.tempValue; 
                    %dropDownConvert(app, app.DropDown22.Value); M_int(2,2) = app.tempValue; 
                    M_int(1,1) = dropDownConvert(app, app.DropDown11.Value);
                    M_int(1,2) = dropDownConvert(app, app.DropDown12.Value);
                    M_int(2,1) = dropDownConvert(app, app.DropDown21.Value);
                    M_int(2,2) = dropDownConvert(app, app.DropDown22.Value);
                    %app.tempValue = NaN; % reset temporary value
                    
                    K = zeros(2,2);
                    K(1,1) = app.K11EditField.Value;             
                    K(1,2) = app.K12EditField.Value;
                    K(2,1) = app.K21EditField.Value;
                    K(2,2) = app.K22EditField.Value;
                    
                    if app.Button1.Value
                        disp_mol = 1;
                    elseif app.Button2.Value
                        disp_mol = 2;
                    elseif app.Button12.Value
                        disp_mol = 12;
                    end
                case 3
                    gz = app.GridsizeEditField_3.Value;
                    a0 = app.a0EditField_3.Value;
                    lambda12 = app.DiffusionconstratioEditField_2.Value;
                    noise = app.NoiseEditField_3.Value;
                    hill = app.HillCoeffEditField_2.Value;
                    p0 = [app.p1t0EditField_2.Value app.p2t0EditField_2.Value];
                    I0 = [app.I1t0EditField_2.Value app.I2t0EditField_2.Value];
                    
                    M_int = zeros(2,2); % do not change order of commands in next 4 lines
                    M_int(1,1) = dropDownConvert(app, app.DropDown11.Value);
                    M_int(1,2) = dropDownConvert(app, app.DropDown12.Value);
                    M_int(2,1) = dropDownConvert(app, app.DropDown21.Value);
                    M_int(2,2) = dropDownConvert(app, app.DropDown22.Value);
                    
                    K = zeros(2,2);
                    K(1,1) = app.K11EditField.Value;
                    K(1,2) = app.K12EditField.Value;
                    K(2,1) = app.K21EditField.Value;
                    K(2,2) = app.K22EditField.Value;
                    
                    Con = zeros(2,2);
                    Con(1,1) = app.Con11EditField.Value;
                    Con(1,2) = app.Con12EditField.Value;
                    Con(2,1) = app.Con21EditField.Value;
                    Con(2,2) = app.Con22EditField.Value;
                    
                    Coff = ones(2,2);
                    %Coff = zeros(2,2);
                    %Coff(1,1) = app.Coff11EditField.Value;
                    %Coff(1,2) = app.Coff12EditField.Value;
                    %Coff(2,1) = app.Coff21EditField.Value;
                    %Coff(2,2) = app.Coff22EditField.Value;
                    
                    if app.Button1_2.Value
                        disp_mol = 1;
                    elseif app.Button2_2.Value
                        disp_mol = 2;
                    elseif app.Button12_2.Value
                        disp_mol = 12;
                    end
            end
        end
        
        function set_input_vars(app, tab, s, p_ini) %gz, a0, K, Con, Coff, noise, hill, p_ini, lambda12, M_int, hill_ID, ini_ID)
            % upon loading a file, sets the entries in the box fields to the values from the loaded file
            % s = save_consts_struct must be a structure
            
            % set variables
            gz = sqrt(s.N);
            %app.MessagesTextArea.Value = strcat(sprintf('gz = %s', num2str(gz)), ';');
            switch tab % distinguish between tabs
                case 1
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'case 1;');
                    app.GridsizeEditField.Value = gz;
                    app.a0EditField.Value = s.a0;
                    app.KEditField.Value = s.K;
                    app.ConEditField.Value = s.Con;
                    app.NoiseEditField.Value = s.noise;
                    app.HillCoefficientEditField.Value = s.hill;
                    app.pt0EditField.Value = s.p_ini;
                    % default values for I
                    app.InitiatewithIt0Menu.Checked = 'off';
                    app.InitiateI = 0;
                    app.It0EditField.Value = 0;
                    % load initial I; first check whether I_ini exists
                    s_fields = fieldnames(s);
                    app.InitiatewithIt0Menu.Checked = 'off'; %default = off
                    app.InitiateI = 0;
                    for i=1:numel(s_fields)
                        if strcmp(s_fields{i},'I_ini_str')
                            if ~isempty(s.I_ini_str)
                                app.It0EditField.Value = s.I_ini;
                                app.InitiatewithIt0Menu.Checked = 'on';
                                app.InitiateI = 1;
                            end
                        end
                    end
                    if strcmp(s.hill_ID, 'binary')
                        app.CellstatesSwitch.Value = 'Binary';
                    elseif strcmp(s.hill_ID, 'continuous')
                        app.CellstatesSwitch.Value = 'Continuous';
                        if strcmp(s.ini_ID, 'ini_binaryrand')
                            app.BinaryButton.Value = 1;
                        elseif strcmp(s.ini_ID, 'ini_MCrand')
                            app.MCrandomizedButton.Value = 1;
                        end
                    end
                    
                case 2
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'case 2;');
                    app.GridsizeEditField_2.Value = gz;
                    app.a0EditField_2.Value = s.a0;
                    app.DiffusionconstratioEditField.Value = s.lambda12;
                    app.NoiseEditField_2.Value = s.noise;
                    app.HillCoeffEditField.Value = s.hill;
                    app.p1t0EditField.Value = p_ini(1);
                    app.p2t0EditField.Value = p_ini(2);
                        
                    % default values for I
                    app.InitiatewithIt0Menu.Checked = 'off';
                    app.InitiateI = 0;
                    app.I1t0EditField.Value = 0;
                    app.I2t0EditField.Value = 0; 
                    % load initial I; first check whether I_ini exists
                    s_fields = fieldnames(s); 
                    for i=1:numel(s_fields)
                        if strcmp(s_fields{i},'I_ini_str')
                            if ~isempty(s.I_ini_str)
                                app.I1t0EditField.Value = s.I_ini(1);
                                app.I2t0EditField.Value = s.I_ini(2);
                                app.InitiatewithIt0Menu.Checked = 'on';
                                app.InitiateI = 1;
                            end
                        end
                    end
                    
                    app.K11EditField.Value = s.K(1,1);             
                    app.K12EditField.Value = s.K(1,2);
                    app.K21EditField.Value = s.K(2,1);
                    app.K22EditField.Value = s.K(2,2);  
                    app.Con1EditField.Value = s.Con(1);
                    app.Con2EditField.Value = s.Con(2);
                    %app.Coff1EditField.Value = Coff(1);
                    %app.Coff2EditField.Value = Coff(2);
                    
                    % set dropdown boxes
                    M_int_idx = s.M_int + 2; %convert index values
                    dditems = {app.DropDown11.Items{2}, app.DropDown11.Items{3}, app.DropDown11.Items{1}};
                    
                    app.DropDown11.Value = dditems{M_int_idx(1,1)};
                    app.DropDown12.Value = dditems{M_int_idx(1,2)};
                    app.DropDown21.Value = dditems{M_int_idx(2,1)};
                    app.DropDown22.Value = dditems{M_int_idx(2,2)};
                    
                case 3
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'case 3;');
                    app.GridsizeEditField_3.Value = s.gz;
                    app.a0EditField_3.Value = s.a0;
                    app.DiffusionconstratioEditField.Value = s.lambda12;
                    app.NoiseEditField_2.Value = s.noise;
                    app.HillCoeffEditField_2.Value = s.hill;
                    app.p1t0EditField_2.Value = s.p_ini(1);
                    app.p2t0EditField_2.Value = s.p_ini(2);
                    % default values for I
                    app.InitiatewithIt0Menu.Checked = 'off';
                    app.InitiateI = 0;
                    app.I1t0EditField.Value = 0;
                    app.I2t0EditField.Value = 0; 
                    % load initial I; first check whether I_ini exists
                    s_fields = fieldnames(s);
                    for i=1:numel(s_fields)
                        if strcmp(s_fields{i},'I_ini_str')
                            if ~isempty(s.I_ini_str)
                                app.I1t0EditField.Value = s.I_ini(1);
                                app.I2t0EditField.Value = s.I_ini(2);
                                app.InitiatewithIt0Menu.Checked = 'on';
                                app.InitiateI = 1;
                            end
                        end
                    end
                    
                    app.K11EditField_2.Value = s.K(1,1);             
                    app.K12EditField_2.Value = s.K(1,2);
                    app.K21EditField_2.Value = s.K(2,1);
                    app.K22EditField_2.Value = s.K(2,2); 
                    
                    app.Con11EditField.Value = s.Con(1,1);
                    app.Con12EditField.Value = s.Con(1,2);
                    app.Con21EditField.Value = s.Con(2,1);
                    app.Con22EditField.Value = s.Con(2,2);
                    
                    %app.Coff11EditField.Value = Coff(1,1);
                    %app.Coff12EditField.Value = Coff(1,2);
                    %app.Coff21EditField.Value = Coff(2,1);
                    %app.Coff22EditField.Value = Coff(2,2);
                    
                    % set dropdown boxes
                    M_int_idx = s.M_int + 2; %convert index values
                    dditems = {app.DropDown11.Items{2}, app.DropDown11.Items{3}, app.DropDown11.Items{1}};
                    
                    app.DropDown11.Value = dditems{M_int_idx(1,1)};
                    app.DropDown12.Value = dditems{M_int_idx(1,2)};
                    app.DropDown21.Value = dditems{M_int_idx(2,1)};
                    app.DropDown22.Value = dditems{M_int_idx(2,2)};
            end                   
        end
        
        function tab = tab_to_num(app)
            % Convert SelectedTab into number 
            if app.TabGroup.SelectedTab == app.signalTab
                tab = 1;
            elseif app.TabGroup.SelectedTab == app.signalsmultTab
                tab = 2;
            elseif app.TabGroup.SelectedTab == app.signalsaddTab
                tab = 3;
            end
        end
        
        function [fname, save_vars_struct] = get_save_file(app, folder, label, ext)
            % outputs 'fname', a string giving the default name for saving files (with folder, without extension)
            % outputs a structure array 'save_vars_struct' containing other variables (constants) to save
            % input 'folder' as the default folder to save in.
            % input 'ext' as extension for file (e.g. '.mat' or '.pdf').
            
            % v16: omit version control if ext is not certain (when saving figures).
            % Standard output: '-v1'.
            
            % folder to save in
            %folder = fullfile(app.DefaultSaveFolder, 'time_evolution'); %default folder
            if exist(folder, 'dir') ~= 7
                app.MessagesTextArea.Value{end+1} = 'Folder does not exist! ';
            end
            
            % common save variables
            rcell = app.rCell;
            tmax = app.tMax;
            prec = app.precision;
            mcsteps = app.MCstepsDropDown.Value;
            t_out = numel(app.CellsHist)-1;
            
            [period_ub, ~] = periodicity_test_short(app.CellsHist, Inf); 
            if period_ub < Inf
                pause(0.01);
                [period, ~] = periodicity_test_detailed(app.CellsHist,...
                    period_ub, Inf);
            else
                period = Inf;
            end
            
            tab = tab_to_num(app);
            % tab-specific save variables
            if app.SelectedTab == app.signalTab
                % (A) One signalling molecule 
                %tab = 1;
                % define variables from app input
                [gz, a0, ~, K, Con, ~, noise, hill, p_ini, I_ini] = get_input_vars(app);
                
                % additional input variables
                N = gz^2;
                
                % filename 
                sim_ID = app.SimID{tab};
                switch app.CellstatesSwitch.Value % distinguish between binary and continuous system
                    case app.CellstatesSwitch.Items(1) % binary system
                        hill_ID = 'binary'; 
                        ini_ID = '';
                        I_ini_str = '';
                        if app.InitiateI
                            I_ini_str = sprintf('_I_ini_%.2f', I_ini);
                        end
                        filename = strrep(sprintf('%s_%s_N%d_a0_%.2f_K%.2f_Con%.2f_noise%.2f_p_ini%.2f%s_rcell%.2f_tmax%d',...
                            sim_ID, hill_ID, N, a0, K, Con, noise, p_ini, I_ini_str, rcell, tmax), '.', 'p');
                        
                    case app.CellstatesSwitch.Items(2) % Continuous system
                        hill_ID = 'continuous';
                        if app.BinaryButton.Value
                            ini_ID = 'ini_binaryrand';
                        elseif app.MCrandomizedButton.Value
                            ini_ID = 'ini_MCrand';
                        end
                        I_ini_str = '';
                        filename = strrep(sprintf('%s_%s_%s_hill%.2f_N%d_a0_%.2f_K%.2f_Con%.2f_noise%.2f_p_ini%.2f_rcell%.2f_tmax%d_prec%d',...
                            sim_ID, hill_ID, ini_ID, hill, N, a0, K, Con, noise, p_ini, rcell, tmax, prec), '.', 'p');
                end
                
                % shortened name
                %filename = strrep(sprintf('%s_%s_N%d_', sim_ID, hill_ID, N), '.', 'p');
                
                % check if filename already exists
                i=1;
                fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                while exist(fname, 'file') == 2
                    i=i+1;
                    fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                end
                
                save_vars = {N, a0, K, Con, hill, noise, p_ini, I_ini, rcell, tmax, prec,...
                    sim_ID, hill_ID, ini_ID, I_ini_str, mcsteps};
                save_vars_lbl = {'N', 'a0', 'K', 'Con', 'hill', 'noise', 'p_ini', 'I_ini', 'rcell', 'tmax', 'prec',...
                    'sim_ID', 'hill_ID', 'ini_ID', 'I_ini_str', 'mcsteps'};
                save_vars_struct = cell2struct(save_vars, save_vars_lbl, 2);
                    
            elseif app.SelectedTab == app.signalsmultTab
                % (B) 2 signals, multiplicative 
                %tab = 2;
                
                % define variables from app input
                [gz, a0, ~, K, Con, Coff, noise, hill, p_ini, I_ini, M_int, lambda12] = get_input_vars(app);
                
                % additional input variables
                N = gz^2;
                
                % filename long
                sim_ID = app.SimID{tab};
                M_int_str = sprintf('M_int%d_%d_%d_%d', M_int(1,1), M_int(1,2), M_int(2,1), M_int(2,2));
                I_ini_str = '';
                if app.InitiateI
                    I_ini_str = sprintf('_I1_I2_%.2f_%.2f', I_ini(1), I_ini(2));
                end
                
                %{
                K_str = sprintf('K%.1f_%.1f_%.1f_%.1f', K(1,1), K(1,2), K(2,1), K(2,2));
                Con_str = sprintf('Con%.1f_%.1f', Con(1), Con(2));
                Coff_str = sprintf('Coff%.1f_%.1f', Coff(1), Coff(2));
                p_ini_str = sprintf('p_ini%.2f_%.2f', p_ini(1), p_ini(2));
                
                %app.MessagesTextArea.Value{end+1} = sim_ID;
                %app.MessagesTextArea.Value{end+1} = M_int_str;
                %app.MessagesTextArea.Value{end+1} = K_str;
                %app.MessagesTextArea.Value{end+1} = Con_str;
                %app.MessagesTextArea.Value{end+1} = Coff_str;
                %app.MessagesTextArea.Value{end+1} = p_ini_str;
                %app.MessagesTextArea.Value{end+1} = I_ini_str;
                
                %filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s_noise%.1f_%s%s_l12_%.1f_rcell%.1f_tmax%d',...
                %    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str, noise, p_ini_str, I_ini_str, lambda12, rcell, tmax), '.', 'p');
                
                % semi-long
                filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s',...
                    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str), '.', 'p');
                %}
                
                % shortened name
                filename = strrep(sprintf('%s_t_out_%d_period_%d', M_int_str, t_out, period), '.', 'p');    
                
                % check if filename already exists
                i=1;
                fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                while exist(fname, 'file') == 2
                    i=i+1;
                    fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                end
                
                save_vars = {N, a0, K, Con, Coff, M_int, hill, noise, p_ini, I_ini, rcell,...
                    tmax, lambda12, sim_ID, I_ini_str, mcsteps};
                save_vars_lbl = {'N', 'a0', 'K', 'Con', 'Coff', 'M_int', 'hill', 'noise', 'p_ini', 'I_ini', 'rcell',...
                    'tmax', 'lambda12', 'sim_ID', 'I_ini_str', 'mcsteps'};
                save_vars_struct = cell2struct(save_vars, save_vars_lbl, 2);
                    
            elseif app.SelectedTab == app.signalsaddTab
                % (C) 2 signals, additive
                %tab = 3;
                % define variables from app input
                [gz, a0, ~, K, Con, Coff, noise, hill, p_ini, I_ini, M_int, lambda12] = get_input_vars(app);
                
                % additional input variables
                N = gz^2;
                
                % filename long
                sim_ID = app.SimID{tab};
                M_int_str = sprintf('M_int%d_%d_%d_%d', M_int(1,1), M_int(1,2), M_int(2,1), M_int(2,2));
                I_ini_str = '';
                if app.InitiateI
                    I_ini_str = sprintf('_I1_I2_%.2f_%.2f', I_ini(1), I_ini(2));
                end
                %
                K_str = sprintf('K%.1f_%.1f_%.1f_%.1f', K(1,1), K(1,2), K(2,1), K(2,2));
                Con_str = sprintf('Con%.1f_%.1f_%.1f_%.1f', Con(1,1), Con(1,2), Con(2,1), Con(2,2));
                Coff_str = sprintf('Coff%.1f_%.1f_%.1f_%.1f', Coff(1,1), Coff(1,2), Coff(2,1), Coff(2,2));
                p_ini_str = sprintf('p_ini%.2f_%.2f', p_ini(1), p_ini(2));
                %filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s_noise%.1f_%s%s_l12_%.1f_rcell%.1f_tmax%d',...
                %    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str, noise, p_ini_str, I_ini_str, lambda12, rcell, tmax), '.', 'p');
                % semi-long
                filename = strrep(sprintf('%s_%s_hill%.2f_N%d_a0_%.2f_%s_%s_%s',...
                    sim_ID, M_int_str, hill, N, a0, K_str, Con_str, Coff_str), '.', 'p');
                %}
                
                % shortened name
                %filename = strrep(sprintf('%s_%s_hill%.2f_N%d_', sim_ID, M_int_str, hill, N), '.', 'p');    
                
                % check if filename already exists
                i=1;
                fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                while exist(fname, 'file') == 2
                    i=i+1;
                    fname = fullfile(folder, strcat(filename, '-v', num2str(i), label, ext));
                end
                
                save_vars = {N, a0, K, Con, Coff, M_int, hill, noise, p_ini, I_ini, rcell,...
                    tmax, lambda12, sim_ID, I_ini_str, mcsteps};
                save_vars_lbl = {'N', 'a0', 'K', 'Con', 'Coff', 'M_int', 'hill', 'noise', 'p_ini', 'I_ini', 'rcell',...
                    'tmax', 'lambda12', 'sim_ID', 'I_ini_str', 'mcsteps'};
                save_vars_struct = cell2struct(save_vars, save_vars_lbl, 2);
            end
        end

        function save_figures(app, folder, ext)
            % Sves all opened figures made by the app
            for i=1:numel(app.FigureHandles)
                this_handle = app.FigureHandles{i};
                fig_pos = app.FigurePosition;% Position and size of figure
                if ishandle(this_handle)%~isempty(h.Children)
                    this_label = this_handle.Name; %fig_label{i};
                    fname = get_save_file(app, folder, strcat('_', this_label), ext);
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, fname);
                    [file, path] = uiputfile(strcat(fname, ext));
                    
                    if ~file % if user pressed cancel, continue to next fig
                        continue;
                    end
                    [~, name, ext] = fileparts(file); %separate file name from extension
                    
                    fname_out = fullfile(path, name);
                    save_figure(this_handle, fig_pos(3), fig_pos(4), fname_out, ext); % use current figure size
                    
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Saved %s plot as %s file;', this_label, ext);
                end
            end
        end
        
        function [status, cells] = manual_input_state(app, signal_count, N)
            % used in simulate_cells_... functions when manual input state is called
            app.MessagesTextArea.Value{end+1} = 'Manually input initial state...';
                    
            folder = app.InputStateSaveFolder; %fullfile('..', '..', 'data', 'input_states');
            [file, path] = uigetfile({'*.xls'; '*.xlsx'; '*.*'}, 'Load initial lattice configuration', folder );
            try
                cells_in = cell(2, 1);
                cells_in{1} = xlsread(fullfile(path, file), 1);
                cells_in{2} = xlsread(fullfile(path, file), 2);
            catch
                try 
                    cells_in = cell(2, 1);
                    cells_in{1} = xlsread(fullfile(path, file), 1);
                catch 
                    app.MessagesTextArea.Value = 'Wrong input initial state, operation aborted';
                    status = 0;
                    cells = [];
                    return
                end
            end
            
            [status, cells] = InputCellStateParser(app, cells_in, signal_count, N);
            if ~status
                app.MessagesTextArea.Value{end+1} = 'Wrong input data format';
                return
            end
        end

        function [status, cells_parsed] = InputCellStateParser(app, cells, signal_count, N)
            % Checks whether input cells state (loaded from xlsx file) is in the right format 
            % If so, parse cell states
            %[gz, ~] = get_input_vars(app);
            %N = gz^2;
            gz = sqrt(N);
            status = 1; % Default
            if signal_count==1
                
                % one signal
                format1 = (size(cells, 1)==N && size(cells,2)==1);
                format2 = (size(cells, 1)==gz && size(cells,2)==gz);
                if format1 % N x 1 arrangement
                    cells_parsed = cells;
                elseif format2 % gz x gz arrangement
                    cells_parsed = cells(:);
                else
                    cells_parsed = [];
                    status = 0; % cells not good
                end
            elseif signal_count==2
                % two signals
                format1 = (size(cells{1}, 1)==N && size(cells{1},2)==2);
                format2 = ( all(size(cells{1})==[gz gz]) && all(size(cells{2})==[gz gz]) );
                if format1 % N x 2 arrangement
                    cells_parsed = cells{1};
                elseif format2 % two sheets, gz x gz arrangement
                    cells_parsed = zeros(N, 2);
                    cells_parsed(:, 1) = reshape(cells{1}, N, 1);
                    cells_parsed(:, 2) = reshape(cells{2}, N, 1);
                else
                    cells_parsed = [];
                    status = 0; % cells not good
                end
            end
        end
    %-------------------------END OF ALL FUNCTIONS----------------------
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % set directories
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            [filepath, ~, ~] = fileparts(mfilename('fullpath'));
            cd(filepath);
            app.MessagesTextArea.Value = {'Current directory: ', pwd};
            % add subdirectories
            % addpath('app_icons'); % subdirectory for app icons
            
            % set default save folder
            %app.DefaultSaveFolder = fullfile(pwd, 'data', 'time_evolution');
            
            % clear axes
            ax = app.UIAxes; %figure handle
            cla(ax);
            
            set(0, 'DefaultTextInterpreter', 'none')
            
            % check if default save folders exist. If not, make new folder
            if exist(app.DefaultSaveFolder, 'dir') ~= 7
                app.MessagesTextArea.Value = ('Make new folder; ');
                %out = regexp(app.DefaultSaveFolder,'\','split');
                %make_folder = fullfile(pwd, out{end-1}, out{end});
                [status, msg] = mkdir(app.DefaultSaveFolder);
                make_folder = fullfile(app.DefaultSaveFolder);
                
                if status
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Successfully created folder %s ;',...
                            make_folder);
                else
                    % print 'Couldn't create folder';
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Could not create folder %s ;',...
                            make_folder);
                    app.MessagesTextArea.Value{end+1} = msg;
                end
            end
            
            if exist(app.InputStateSaveFolder, 'dir') ~= 7
                app.MessagesTextArea.Value = ('Make new folder; ');
                [status, msg] = mkdir(app.InputStateSaveFolder);
                
                make_folder = fullfile(app.InputStateSaveFolder);
                if status
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Successfully created folder %s ;',...
                           make_folder);
                else
                    % print 'Couldn't create folder';
                    app.MessagesTextArea.Value{end+1} = ...
                        sprintf('Could not create folder %s ;',...
                            make_folder);
                    app.MessagesTextArea.Value{end+1} = msg;
                end
            end
            %}
            
            % set default variables
            app.ContinueSimulation = 1;
            app.NewSimulation = 1;
            app.ReplayTrajectory = 0;
            app.Changed = 1;
            app.SelectedTab = app.TabGroup.SelectedTab;
            
            % define variables from input
            %gz = app.GridsizeEditField.Value;
            %a0 = app.a0EditField.Value;
            app.rCell = app.CellSizeEditField.Value;
            
            %N = gz^2;
            %[~, pos] = init_dist_hex(gz, gz);
            %cell_type = zeros(N, 1);

            % display binary system with all cells off
            reset_cell_figure(app)
            
            % disables zooming in and out by scrolling over plot (introduced in R2018b)
            %disableDefaultInteractivity(app.UIAxes);
        end

        % Callback function: ButtonPlay, RunMenu
        function RunButtonPushed(app, event)
            app.ContinueSimulation = 1;
            app.SelectedTab = app.TabGroup.SelectedTab;
            
            % clear axes
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            %ax = app.UIAxes; %figure handle
            %cla(ax);
            
            % get current tab
            tab = tab_to_num(app);
            
            % get all parameters
            [gz, a0, disp_mol, K, Con, Coff, noise, hill, p0, I0, M_int, lambda12] = get_input_vars(app);
            N = gz^2;
            Rcell = app.rCell*a0;
            %cell_type = zeros(N, 1);
            %[dist, ~] = init_dist_hex(gz, gz);
            lambda = [1 lambda12];
            t_final = length(app.CellsHist)-1;
            
            % Replay trajectory
            if app.ReplayTrajectory && app.Time <= t_final
                app.MessagesTextArea.Value = 'Simulation replay; ';
                %app.MessagesTextArea.Value{end+1} = 'Way 1; ';
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('Length(cells_hist): %s', num2str(size(app.CellsHist)) ));
                
                % to be expanded:
                %cell_type = zeros(N, 1); 
                
                %app.MessagesTextArea.Value{end+1} = 'CP1;';
                %app.MessagesTextArea.Value{end+1} = sprintf('app.Time = %d', app.Time);
                
                while app.Time <= t_final && app.ContinueSimulation
                    %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, ' time = ', num2str(app.Time), ';');
                    cells = app.CellsHist{app.Time+1};
                    update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                    app.Time = app.Time + 1;
                end
                %app.MessagesTextArea.Value = strcat('Replay finished; ');
                
                % skip running another simulation in next section
                app.NewSimulation = 0;
                
                % Check whether the final state after replay is in equilibrium
                switch tab
                    case 1
                        switch app.CellstatesSwitch.Value % distinguish between binary and continuous system
                            case app.CellstatesSwitch.Items(1) % binary system
                                [app.CellsOut, app.Changed] = update_cells_noise(cells, app.dist, Con, K, a0, Rcell, noise);
                            case app.CellstatesSwitch.Items(2) % Continuous system
                                [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                                    cells, app.app.dist, Con, K, a0, Rcell, noise, hill, prec); 
                        end
                    case 2
                        [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells,...
                            app.dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                    case 3
                        [app.CellsOut, app.Changed] = update_cells_two_signals_add_finite_Hill(cells,...
                            app.dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                end
                %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, 'app.Changed = ', num2str(app.Changed), ';');
            end
            
            % if you reach the end in the replay, run the following code:
            %{
            if app.Time == t_final
                cells = app.CellsHist{end};
                switch tab
                    case 1
                        switch app.CellstatesSwitch.Value % distinguish between binary and continuous system
                            case app.CellstatesSwitch.Items(1) % binary system
                                [app.CellsOut, app.Changed] = update_cells_noise(cells, dist, Con, K, a0, Rcell, noise);
                            case app.CellstatesSwitch.Items(2) % Continuous system
                                [app.CellsOut, app.Changed] = update_cells_noise_hill(...
                                    cells, dist, Con, K, a0, Rcell, noise, hill, prec); 
                        end
                    case 2
                        [app.CellsOut, app.Changed] = update_cells_two_signals_multiply_finite_Hill(cells,...
                            dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                    case 3
                        [app.CellsOut, app.Changed] = update_cells_two_signals_add_finite_Hill(cells,...
                            dist, M_int, a0, Rcell, Con, Coff, K, lambda, hill, noise);
                end
            end
            %}
            
            % ---- run a new simulation / continue current simulation ----
            % conditions under which the following code should be run:
            % (1) new simulation
            cond1 = app.NewSimulation;
            % (2) resume after pause
            cond2 = (~app.NewSimulation)&&(~app.ReplayTrajectory);
            % (3) after replay & run, the system has not reached equilibrium
            cond3 = (app.ReplayTrajectory)&&(app.Changed);
            
            if cond1
                mcsteps = str2double(app.MCstepsDropDown.Value);
                mcsteps = floor(mcsteps);
                %app.MessagesTextArea.Value{end+1} = sprintf('MC steps: %d', mcsteps);
                new_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice);
            end
            
            if cond1 || cond2 || cond3
                %app.MessagesTextArea.Value{end+1} = strcat('cond1', num2str(cond1) );
                %app.MessagesTextArea.Value{end+1} = strcat('cond2', num2str(cond2) );
                %app.MessagesTextArea.Value{end+1} = strcat('cond3', num2str(cond3) );
                switch tab
                    case 1
                        switch app.CellstatesSwitch.Value % distinguish between binary and continuous system
                            case app.CellstatesSwitch.Items(1) % binary system
                                simulate_cells_binary(app, N, app.dist, M_int, a0, Rcell, Con, K, noise, p0, I0, app.tMax, app.NewSimulation)
                            case app.CellstatesSwitch.Items(2) % Continuous system
                                simulate_cells_continuous(app, N, app.dist, M_int, a0, Rcell, Con, K, noise, hill, app.precision, p0, app.tMax, app.NewSimulation);
                        end
                    case 2
                        simulate_cells_two_signals_multiply(app, N, app.dist, M_int, lambda12, a0, Rcell,...
                            Con, Coff, K, hill, noise, p0, I0, app.tMax, app.NewSimulation, disp_mol);
                    case 3
                        simulate_cells_two_signals_add(app, N, app.dist, M_int, lambda12, a0, Rcell,...
                            Con, Coff, K, hill, noise, p0, I0, app.tMax, app.NewSimulation, disp_mol);
                end
            end
            
            % if the trajectory reached equilibrium at the end, pressing RUN again should replay the trajectory
            if app.Changed == 0
                app.MessagesTextArea.Value{end+1} = 'Equilbrium state reached; ';
                %app.ReplayTrajectory = 1;
                %app.Time = 0; % reset time
                app.NewSimulation = 0;
            end
            
        end

        % Callback function: ButtonGroup, ButtonStop, 
        % CellstatesSwitch, Con11EditField, Con12EditField, 
        % Con1EditField, Con21EditField, Con22EditField, Con2EditField, 
        % ConEditField, DiffusionconstratioEditField, 
        % DiffusionconstratioEditField_2, DropDown11, DropDown11_2, 
        % DropDown12, DropDown12_2, DropDown21, DropDown21_2, 
        % DropDown22, DropDown22_2, GridsizeEditField, 
        % GridsizeEditField_2, GridsizeEditField_3, HillCoeffEditField, 
        % HillCoeffEditField_2, HillCoefficientEditField, I1t0EditField, 
        % I1t0EditField_2, I2t0EditField, I2t0EditField_2, 
        % InteractionDropDown, It0EditField, K11EditField, 
        % K11EditField_2, K12EditField, K12EditField_2, K21EditField, 
        % K21EditField_2, K22EditField, K22EditField_2, KEditField, 
        % MCstepsDropDown, NoiseEditField, NoiseEditField_2, 
        % NoiseEditField_3, ResetMenu, TabGroup, a0EditField, 
        % a0EditField_2, a0EditField_3, p1t0EditField, p1t0EditField_2, 
        % p2t0EditField, p2t0EditField_2, pt0EditField
        function ResetButtonPushed(app, event)
            app.ContinueSimulation = 0;
            app.NewSimulation = 1;
            app.ReplayTrajectory = 0;
            
            % reset selected tab
            % N.B. changing the tab runs ResetButtonPushed, so this code has to be placed here
            app.SelectedTab = app.TabGroup.SelectedTab;
            %{
            switch app.SelectedTab
                case app.signalTab
                    gz = app.GridsizeEditField.Value;
                case app.signalsmultTab
                    gz = app.GridsizeEditField_2.Value;
                case app.signalsaddTab
                    gz = app.GridsizeEditField_3.Value;
            end
            %}
            
            % clear saved variables (not done in previous version)
            reset_app_vars(app);
            
            % clear figure to standard configuration
            %[~, pos] = init_dist_hex(gz, gz);
            reset_cell_figure(app)
            
            % clear output textbox 
            app.MessagesTextArea.Value = ''; 
            
            % close all generated figures
            app.CloseallfiguresMenuSelected(app);
        end

        % Callback function: ButtonPause, PauseMenu
        function PauseButtonPushed(app, event)
            if app.ContinueSimulation % only do something if the simulation is still running
                app.ContinueSimulation = 0;
                app.NewSimulation = 0;
                if app.Changed
                    app.MessagesTextArea.Value = 'Simulation paused; ';
                end
            end
        end

        % Menu selected function: SavetrajectoryMenu
        function SavetrajectoryMenuSelected(app, event)
            % Save trajectory

            if isempty(app.CellsHist)
                app.MessagesTextArea.Value = 'No simulation data to save!';
                return
            end
            % folder to save in
            folder = app.DefaultSaveFolder; 
            ext = '.mat';
            label = '';
            [fname, save_consts_struct] = get_save_file(app, folder, label, ext);
            
            % simulation data to save
            cells_hist = app.CellsHist;
            t_out = length(cells_hist); %app.TimeFinal;
            changed = app.Changed;
            
            %vars_out = {'save_consts_struct', 'cells_hist', 't_out', 'changed'};
            %uisave(vars_out, fname);
            [file, path] = uiputfile(fname);
            if isnumeric(file) && isnumeric(path) % if user clicked "cancel"
                %app.MessagesTextArea.Value = sprintf('Simulation not loaded.');
                return
            end
            positions = app.pos;
            distances = app.dist;
            save(fullfile(path, file), 'save_consts_struct',...
                'cells_hist', 't_out', 'changed', 'positions', 'distances');
            
            app.MessagesTextArea.Value{end+1} = ...
                sprintf('Saved simulation: %s ;', fullfile(path, file));
            
            %app.MessagesTextArea.Value{end+1} = ...
            %    sprintf('Saved simulation: %s ;', fname);
            
            % to do: change view to app after saving / loading file
            %app.MulticellularModelSimulatorUIFigure;
        end

        % Menu selected function: LoadtrajectoryMenu
        function LoadtrajectoryMenuSelected(app, event)
            app.ReplayTrajectory = 1;
            app.NewSimulation = 0;
            
            % reset vars
            app.CellsOut = [];
            app.Changed = 1;
            app.Time = 0;
            
            % load saved trajectory from folder
            %folder = app.DefaultSaveFolder; %fullfile(pwd, 'data', 'time_evolution');
            %folder = 'L:\BN\HY\Shared\Yiteng\two_signals\travelling_wave_analysis\vs_p0_set2';
            %folder = 'K:\bn\hy\Shared\Yiteng\Multicellularity\videos\selected\data';
            %folder = 'N:\tnw\BN\HY\Shared\Yiteng\two_signals\batch_sim_all_topologies_run2\selected';
            %folder = 'L:\BN\HY\Shared\Yiteng\two_signals\travelling_wave_analysis\vs_Hill';
            folder = 'H:\My Documents\Multicellular automaton\data\two_signals\parameter_gradient';
            %folder = 'H:\My Documents\Multicellular automaton\app\data\time_evolution\patterns';
            
            %[file, path] = uigetfile(folder, 'Load saved simulation');
            [fname, folder_out] = uigetfile(fullfile(folder, '\*.mat'), 'Load saved simulation');

            %app.MessagesTextArea.Value = sprintf('file = %s, path = %s', file, path);
            if isnumeric(fname) && isnumeric(folder_out) % if user clicked "cancel"
                %app.MessagesTextArea.Value = sprintf('Simulation not loaded.');
                return
            end
            load(fullfile(folder_out, fname));
            app.MessagesTextArea.Value = sprintf('Loaded simulation: %s; ', fullfile(folder_out, fname));
            
            % store loaded variables in app environment 
            % !REMEMBER TO CHANGE BACK TO DEFAULT!
            app.CellsHist = cells_hist;
            app.CellsOut = cells_hist{end};
            % TEMPORARY MANUAL INPUT:
            %app.CellSizeEditField.Value = 0.2;
            %app.rCell = 0.2;
            app.CellSizeEditField.Value = save_consts_struct.rcell;
            app.rCell = save_consts_struct.rcell; % also change stored property
            
            % Generate lattice
            new_lattice = 0;
            mcsteps = 0; %variable not used 
            reset_cell_figure(app); 
            gz = sqrt(save_consts_struct.N);
            % load positions or get new ones
            if exist('positions', 'var') % display lattice according to loaded positions
                app.pos = positions;
                app.dist = distances;
                
                % display lattice with correct size
                %gz = get_input_vars(app);
                %[~, pos] = init_dist_hex(gz, gz);
                hex_lattice = 0;
                generate_lattice(app, gz, mcsteps, new_lattice, hex_lattice);
            else % display default hexagonal lattice
                hex_lattice = 1;
                generate_lattice(app, gz, mcsteps, new_lattice, hex_lattice);
            end
            
            % optional loading: only load if variable exists
            names = fieldnames(save_consts_struct);
            for i=1:numel(names)
                if strcmp(names{i}, 'mcsteps')
                    app.MCstepsDropDown.Value = num2str(save_consts_struct.mcsteps);
                end
            end
            
            % = length(cells_hist)-1; %t_out;
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, sprintf('Length(cells_hist): %s', num2str(size(app.CellsHist)) ));
            
            % switch to right tab
            if strcmp(save_consts_struct.sim_ID, app.SimID{1})
                app.TabGroup.SelectedTab = app.signalTab;
                %tab = 1;
            elseif strcmp(save_consts_struct.sim_ID, app.SimID{2})
                app.TabGroup.SelectedTab = app.signalsmultTab;
                %tab = 2;
            elseif strcmp(save_consts_struct.sim_ID, app.SimID{3})
                app.TabGroup.SelectedTab = app.signalsaddTab;
                %tab = 3;
            end
            
            %pause(2);
            tab = tab_to_num(app);
            %app.MessagesTextArea.Value = sprintf('Tab = %s', num2str(tab));
            %app.MessagesTextArea.Value = sprintf('Size(s) = %s', num2str(size(save_consts_struct)));
            
            % change data fields
            p_ini = mean(cells_hist{1}, 1);
            set_input_vars(app, tab, save_consts_struct, p_ini); 
            
            % app.MulticellularModelSimulatorUIFigure;
            % to do: change view to app after saving / loading file
        end

        % Menu selected function: MeangeneexpressionptMenu
        function MeangeneexpressionptMenuSelected(app, event)
            % make a graph of p(t)
            [msg, h] = plot_p_vs_t(app.CellsHist, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: SpatialautocorrelationItMenu
        function SpatialautocorrelationItMenuSelected(app, event)
            % make a graph of I(t)
            [~, a0] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            [msg, h] = plot_I_vs_t(app.CellsHist, a0, app.dist, 1, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: SpatialautocorrelationThetatMenu
        function SpatialautocorrelationThetatMenuSelected(app, event)
            % make a graph of Theta(t)
            [~, a0] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            [msg,h] = plot_I_vs_t(app.CellsHist, a0, app.dist, 2, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DtrajectoryptItMenu
        function TrajectoryptItMenuSelected(app, event)
            % make a graph of (p(t), I(t))
            % get input vars
            [~, a0, ~, K, Con] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            % calculate fN
            %dist = round(dist, 5);
            Rcell = 0.2*a0;
            dist_vec = a0*app.dist(1,:);
            r = dist_vec(dist_vec>0); % exclude self influence
            fN = sum(sinh(Rcell)*sum(exp(Rcell-r)./r)); % calculate signaling strength
            
            [msg, h] = plot_p_I_vs_t(app.CellsHist, app.dist, a0, Con, K, fN, 1, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DtrajectoryptThetatMenu
        function TrajectoryptThetatMenuSelected(app, event)
            % make a graph of (p(t), Theta(t))
            % get input vars
            [~, a0, ~, K, Con] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            % calculate fN
            %dist = round(dist, 5);
            Rcell = 0.2*a0;
            dist_vec = a0*app.dist(1,:);
            r = dist_vec(dist_vec>0); % exclude self influence
            fN = sum(sinh(Rcell)*sum(exp(Rcell-r)./r)); % calculate signaling strength
            
            [msg, h] = plot_p_I_vs_t(app.CellsHist, app.dist, a0, Con, K, fN, 2, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web('https://github.com/YitengD/Multicellularity-app', '-browser');
        end

        % Menu selected function: PseudoenergyhtMenu
        function PseudoenergyhtMenuSelected(app, event)
            % make a graph of h(t)
            % get input vars
            [~, a0, ~, K, Con, ~, noise] = get_input_vars(app);
            %[dist, ~] = init_dist_hex(gz, gz);
            
            [msg, h] = plot_h_vs_t(app.CellsHist, app.dist, a0, Con, K, noise, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
        end

        % Menu selected function: CloseallfiguresMenu
        function CloseallfiguresMenuSelected(app, event)
            % closes all opened figures from simulation
            %close all;
            
            %for i=1:app.num_fig
            %    h = figure(i); close(h);
            %end
            
            for i=1:numel(app.FigureHandles)
                this_handle = app.FigureHandles{i};
                %app.MessagesTextArea.Value{end+1} = sprintf('Handle %d', this_handle.Number);
                if ishandle(this_handle) % checks whether handle points to existing figure
                    y = figure(this_handle.Number);
                    close(y);
                end
            end
        end

        % Callback function: BackstepMenu, ButtonBack
        function ButtonBackPushed(app, event)
            % go back 1 time step
            if ~isempty(app.CellsHist) && app.Time>0
                app.ReplayTrajectory = 1;
                app.Time = app.Time-1;
                
                % display lattice
                cells = app.CellsHist{app.Time+1};
                
                [gz, a0, disp_mol] = get_input_vars(app);
                N = gz^2;
                %[dist, ~] = init_dist_hex(gz, gz);
                %cell_type = zeros(N,1);
                
                update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
            
                % display msg
                % app.MessagesTextArea.Value = 'Rewinded simulation to start;';
                app.MessagesTextArea.Value = 'Replay mode;';
            end
        end

        % Callback function: ButtonFwd, ForwardstepMenu
        function ButtonFwdPushed(app, event)
            % go fwd 1 time step (in playback mode)
            % possible adjustment: also forward evolve 1 time step when at end of playback           
            
            if ~isempty(app.CellsHist)
                t_final = length(app.CellsHist)-1; 
                if app.Time < t_final
                    app.Time = app.Time+1;
                    
                    % display lattice
                    cells = app.CellsHist{app.Time+1};
                    
                    [~, a0, disp_mol] = get_input_vars(app);
                    %N = gz^2;
                    %[dist, ~] = init_dist_hex(gz, gz);
                    %cell_type = zeros(N,1);
                    
                    update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                
                    % display msg
                    % app.MessagesTextArea.Value = 'Rewinded simulation to start;';
                    app.MessagesTextArea.Value = 'Replay mode;';
                end
            end
        end

        % Callback function: ButtonToStart, MovetostartMenu
        function ButtonToStartPushed(app, event)
            if ~isempty(app.CellsHist)
                % set simulation to t=0 frame
                app.ReplayTrajectory = 1;
                
                % play from start until equilibrium / tmax reached
                app.Time = 0;
                
                % display initial configuration
                cells = app.CellsHist{1};
                [gz, a0, disp_mol]  = get_input_vars(app);
                N = gz^2;
                %[dist, ~] = init_dist_hex(gz, gz);
                %cell_type = zeros(N,1);
                
                update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                
                % display msg
                app.MessagesTextArea.Value = 'Replay mode;';
            end
        end

        % Callback function: ButtonToEnd, MovetoendMenu
        function ButtonToEndPushed(app, event)
            % move to end of simulation
            if ~isempty(app.CellsHist)
                t_final = length(app.CellsHist)-1;
                if app.Time < t_final
                    app.Time = t_final;
                    
                    %app.ReplayTrajectory = 1;
                    
                    % display configuration
                    cells = app.CellsHist{end};
                    [~, a0, disp_mol]  = get_input_vars(app);
                    %N = gz^2;
                    %[dist, ~] = init_dist_hex(gz, gz);
                    %cell_type = zeros(N,1);
                    update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                
                    % display msg
                    %app.MessagesTextArea.Value = 'Replay mode;';
                end
            end
        end

        % Menu selected function: ShowIMenu
        function ShowIMenuSelected(app, event)
            % toggle setting (whether to show I on top of figures)
            app.showI = ~app.showI;
            if app.showI
                app.ShowIMenu.Checked = 'on';
            else
                app.ShowIMenu.Checked = 'off';
            end
        end

        % Menu selected function: InitiatewithIt0Menu
        function InitiatewithIt0MenuSelected(app, event)
            % whether or not to initiate the lattice with a specific (target) value of I
            app.InitiateI = ~app.InitiateI;
            if app.InitiateI
                app.InitiatewithIt0Menu.Checked = 'on';
            else
                app.InitiatewithIt0Menu.Checked = 'off';
            end
        end

        % Selection changed function: DisplaysignalButtonGroup, 
        % DisplaysignalButtonGroup_2
        function DisplaysignalButtonGroupSelectionChanged(app, event)
            % Toggle molecule field displayed
            % selectedButton = app.DisplaysignalButtonGroup.SelectedObject;
            if ~isempty(app.CellsHist)
                
                % display configuration
                if app.Time < numel(app.CellsHist)
                    cells = app.CellsHist{app.Time+1};
                elseif app.Time == numel(app.CellsHist) %special case for equilibrium state
                    cells = app.CellsHist{app.Time};
                end
                [~, a0, disp_mol]  = get_input_vars(app);
                %N = gz^2;
                %[dist, ~] = init_dist_hex(gz, gz);
                %cell_type = zeros(N,1);
                update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
            
                % display message
                app.MessagesTextArea.Value{end+1} = sprintf('Switched to molecule %d', disp_mol);
            end
        end

        % Menu selected function: CloseappMenu
        function CloseappMenuSelected(app, event)
            % closes all figures and closes the app
            CloseallfiguresMenuSelected(app, event);
            delete(app);
        end

        % Menu selected function: SavefiguresepsMenu
        function SavefiguresepsMenuSelected(app, event)
            % Saves opened figures as eps
            folder = fullfile(app.DefaultSaveFolder);
            ext = '.eps';
            save_figures(app, folder, ext);
        end

        % Menu selected function: SavefigurespdfMenu
        function SavefigurespdfMenuSelected(app, event)
            % Saves opened figures as pdf
            folder = fullfile(app.DefaultSaveFolder);
            ext = '.pdf';
            save_figures(app, folder, ext);
        end

        % Menu selected function: SkiptotimeMenu
        function SkiptotimeMenuSelected(app, event)
            % skips to time user inputs
            prompt = 'Skip to time:';
            answer = inputdlg(prompt);
            if ~isempty(answer)
                input_time = str2double(answer{1});
                if (input_time>-1)&&(input_time<numel(app.CellsHist))
                    app.Time = input_time;
                        
                    % display configuration
                    cells = app.CellsHist{app.Time+1};
                    [~, a0, disp_mol]  = get_input_vars(app);
                    %N = gz^2;
                    %[dist, ~] = init_dist_hex(gz, gz);
                    %cell_type = zeros(N,1);
                    update_cell_figure_continuum(app, app.dist, a0, cells, app.Time, disp_mol);
                
                    % display msg
                    app.MessagesTextArea.Value = 'Replay mode;';
                end
            else 
                app.MessagesTextArea.Value{end+1} = 'Invalid time;';
            end
        end

        % Menu selected function: CircuittopologyMenu
        function CircuittopologyMenuSelected(app, event)
            % Show circuit topology
            [gz, a0, ~, K, Con, Coff, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            h = plot_circuit(gz, a0, app.rCell, M_int, Con, Coff, K, lambda12);
            app.FigureHandles{end+1} = h;
            
            app.MessagesTextArea.Value{end+1} = 'Circuit topology plotted';
            app.MessagesTextArea.Value{end+1} = 'Dotted line: interaction always below threshold';
            app.MessagesTextArea.Value{end+1} = 'Dashed line: interaction always above threshold';
            app.MessagesTextArea.Value{end+1} = 'Normal line: no restrictions';
        end

        % Menu selected function: StatediagramsinglecellMenu
        function StatediagramMenuSelected(app, event)
            [~, ~, ~, K, Con, Coff, ~, ~, ~, ~, M_int, ~] = get_input_vars(app);
            [~, ~, h] = plot_state_diagram_onecell(M_int, Con, Coff, K);
            %{
            if numel(M_int)==1
                h = plot_state_diagram_onecell(M_int, Con, Coff, K);
                %app.MessagesTextArea.Value{end+1} = 'Unable to plot for one signal.';
            elseif numel(M_int)==4
                h = plot_state_diagram_onecell(M_int, Con, Coff, K);
            end
            %}
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted state diagram (single cell)';
        end

        % Menu selected function: PhasediagramMenu
        function PhasediagramMenuSelected(app, event)
            % Plots the phase diagram of K, Con for each interaction
            [gz, a0, ~, K, Con, ~, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            h = plot_phase_diagram(gz, a0, app.rCell, M_int, K, Con, lambda12);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted phase diagram';
        end

        % Menu selected function: StatediagramfullsystemMenu
        function StatediagramfullsystemMenuSelected(app, event)
            [gz, a0, ~, K, Con, Coff, ~, ~, ~, ~, M_int, lambda12] = get_input_vars(app);
            if numel(M_int)==1
                %app.MessagesTextArea.Value{end+1} = 'Unable to plot for one signal.';
                h = plot_state_diagram_multicell_one_signal(gz, a0, app.rCell, M_int, Con, Coff, K);
            elseif numel(M_int)==4
                [phase, A, h] = plot_state_diagram_multicell(gz, a0, app.rCell, M_int, Con, Coff, K, lambda12);
                
                cycles=all_topologies_cycle_finder(A);
                app.MessagesTextArea.Value{end+1} = 'Found the following cycles:';
                for i=1:numel(cycles)
                    cycle = cycles{i};
                    app.MessagesTextArea.Value{end+1} = num2str(cycle);
                end
                % Check correct phase
                app.MessagesTextArea.Value{end+1} = sprintf('phase = [%d %d; %d %d]', ...
                    phase(1,1), phase(1,2), phase(2,1), phase(2,2));
            end
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = 'Successfully plotted state diagram (multi-cell)';
        end

        % Menu selected function: SavemovieaviMenu
        function SavemovieaviMenuSelected(app, event)
            % Replay trajectory in external fig
            %frames = struct('cdata',[],'colormap',[]);
            
            % get all parameters
            %[gz, a0, disp_mol, K, Con, Coff, noise, hill, p0, I0, M_int, lambda12] = get_input_vars(app);
            [gz, ~, disp_mol] = get_input_vars(app);
            N = gz^2;
            cell_type = zeros(N, 1);
            %[~, pos] = init_dist_hex(gz, gz);
            
            % get frame rate
            %{
            prompt = {'Enter frame rate:'};
            title = 'User input';
            dims = [1];
            definput = {'1'};
            answer = inputdlg(prompt,title,dims,definput);
            frame_rate = str2double(answer);
            %}
            frame_rate = app.SpeedSlider.Value;
            
            % Get filename from user
            folder = app.DefaultSaveFolder;
            ext = '.avi';
            fname = get_save_file(app, folder, '_movie', ext);
            %app.MessagesTextArea.Value = strcat(app.MessagesTextArea.Value, fname);
            [file, path] = uiputfile(strcat(fname, ext));
            
            if file % if user pressed cancel, do nothing
                [~, name, ~] = fileparts(file); %separate file name from extension
                
                fname_out = fullfile(path, name);
                
                app.MessagesTextArea.Value{end+1} = ...
                    sprintf('Movies frames: %d;', length(app.CellsHist) );
                save_movie(app.CellsHist, app.pos, cell_type, disp_mol, fname_out, frame_rate, app.rCell);
                
                app.MessagesTextArea.Value{end+1} = ...
                    sprintf('Saved movie %s;', fname_out);
            end
        end

        % Value changed function: CellSizeEditField
        function CellSizeEditFieldValueChanged(app, event)
            app.rCell = app.CellSizeEditField.Value;
            app.ResetButtonPushed;
        end

        % Menu selected function: CellcolorlegendMenu
        function CellcolorlegendMenuSelected(app, event)
            [~, ~, disp_mol] = get_input_vars(app);
            if disp_mol==12
                h = plot_color_map_2D(2)
            else
                h = plot_color_map_2D(1)
            end
            app.FigureHandles{end+1} = h;
        end

        % Menu selected function: PeriodicitytestMenu
        function PeriodicitytestMenuSelected(app, event)
            app.MessagesTextArea.Value{end+1} = 'Testing trajectory for periodicity...';
            pause(0.01);
            
            % Rounding cell states
            tab = tab_to_num(app);
            switch tab
                case 1 % 1 signal
                    if strcmp(app.CellstatesSwitch.Value,'Binary')
                        hill = Inf;
                    else
                        hill = app.HillCoefficientEditField.Value;
                    end
                case 2 % 2 signals
                    hill = app.HillCoeffEditField.Value;
                case 3
                    hill = app.HillCoeffEditField_2.Value;
            end
            
            %app.MessagesTextArea.Value{end+1} = sprintf('%d', hill);
            if hill<Inf
                decimals = 5;
            else 
                decimals = Inf;
            end
            
            % periodicity test
            [period_ub, ~] = periodicity_test_short(app.CellsHist, decimals); 
            if period_ub < Inf
                app.MessagesTextArea.Value{end+1} = sprintf('Periodicity found! Upper bound: %d. Finding period...', period_ub) ;
                pause(0.01);
                [period, t_onset] = periodicity_test_detailed(app.CellsHist,...
                    period_ub, decimals);
                app.MessagesTextArea.Value{end+1} = sprintf('Period %d, starting at t=%d', period, t_onset);
            else
                app.MessagesTextArea.Value{end+1} = sprintf('No periodicity found');
            end
            
            %}
        end

        % Menu selected function: PopulationfractionspijtbinaryMenu
        function PopulationfractionspijtbinaryMenuSelected(app, event)
            % make a graph of p_ij(t)
            [msg, h] = plot_p_ij_vs_t(app.CellsHist, app.FigurePosition);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
            
        end

        % Menu selected function: SpatialcrosscorrelationMenu
        function SpatialcrosscorrelationMenuSelected(app, event)
            tab = tab_to_num(app);
            % only for 2 signals
            if tab==2 || tab==3
                [~, ~, h] = plot_spatial_cross_corr(app.CellsHist, app.FigurePosition);
                app.FigureHandles{end+1} = h;
                app.MessagesTextArea.Value{end+1} = 'Successfully plotted spatial cross-correlation';
            end
        end

        % Menu selected function: ManuallyinputinitialstateMenu
        function ManuallyinputinitialstateMenuSelected(app, event)
            app.ManualInputInitialState = ~app.ManualInputInitialState;
            if app.ManualInputInitialState
                app.ManuallyinputinitialstateMenu.Checked = 'on';
            else
                app.ManuallyinputinitialstateMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavesystemstatexlsMenu
        function SavesystemstatexlsMenuSelected(app, event)
            % Save the system's state at a given time (user input) as xlsx file.
            % Data is given in such a way that it can be easily re-imported into the app.
            
            % ask for time from user
            prompt = 'Save system state at time: ';
            input_time = str2double(inputdlg(prompt));
            
            % check that the given input time is valid
            if isempty(input_time) || input_time<0 || input_time>length(app.CellsHist)-1
                app.MessagesTextArea.Value{end+1} = 'Invalid input time!';
                return
            end
            
            % save data
            cells = app.CellsHist{input_time+1};
            %app.MessagesTextArea.Value{end+1} = num2str(size(cells));
            save_folder = app.InputStateSaveFolder;
            fname_str = sprintf('snapshot_time%d', input_time);
            fname = fullfile(save_folder,fname_str);
            
            [file, path] = uiputfile(strcat(fname, '.xls')); % asks user for output file folder and name
            
            [status, msg] = xlswrite(fullfile(path, file), cells); % save data
            
            % messages
            if status
                app.MessagesTextArea.Value{end+1} = sprintf('Saved cell snapshot xlsx file %s', fname); 
            else
                app.MessagesTextArea.Value{end+1} = 'Save unsuccessful'; 
                app.MessagesTextArea.Value{end+1} = msg.message;
            end
        end

        % Menu selected function: KymographMenu
        function KymographMenuSelected(app, event)
            % Plots a kymograph of the dynamics (space-time plot) 
            % cells are aligned 1:N according to their variable numbering 
            % In practice this corresponds to starting in the upper left corner and counting down
            [~, ~, disp_mol] = get_input_vars(app);
            if disp_mol==1
                genes=1;
            elseif disp_mol==2
                genes=2;
            elseif disp_mol==12
                genes=12;
            end
            [~, msg, h] = plot_time_state_heatmap(app.CellsHist, genes);
            app.FigureHandles{end+1} = h;
            app.MessagesTextArea.Value{end+1} = msg;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MulticellularModelSimulatorUIFigure = uifigure;
            app.MulticellularModelSimulatorUIFigure.Name = 'Multicellular Model Simulator';
            app.FileMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.FileMenu.Text = 'File';
            app.SavetrajectoryMenu = uimenu(app.FileMenu);
            app.SavetrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetrajectoryMenuSelected, true);
            app.SavetrajectoryMenu.Accelerator = 'S';
            app.SavetrajectoryMenu.Text = 'Save trajectory';
            app.LoadtrajectoryMenu = uimenu(app.FileMenu);
            app.LoadtrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtrajectoryMenuSelected, true);
            app.LoadtrajectoryMenu.Accelerator = 'O';
            app.LoadtrajectoryMenu.Text = 'Load trajectory';
            app.SavefigurespdfMenu = uimenu(app.FileMenu);
            app.SavefigurespdfMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefigurespdfMenuSelected, true);
            app.SavefigurespdfMenu.Accelerator = 'F';
            app.SavefigurespdfMenu.Text = 'Save figures (pdf)';
            app.SavefiguresepsMenu = uimenu(app.FileMenu);
            app.SavefiguresepsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefiguresepsMenuSelected, true);
            app.SavefiguresepsMenu.Text = 'Save figures (eps)';
            app.SavemovieaviMenu = uimenu(app.FileMenu);
            app.SavemovieaviMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemovieaviMenuSelected, true);
            app.SavemovieaviMenu.Accelerator = 'M';
            app.SavemovieaviMenu.Text = 'Save movie (avi)';
            app.SavesystemstatexlsMenu = uimenu(app.FileMenu);
            app.SavesystemstatexlsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesystemstatexlsMenuSelected, true);
            app.SavesystemstatexlsMenu.Accelerator = 'X';
            app.SavesystemstatexlsMenu.Text = 'Save system state (xls)';
            app.CloseallfiguresMenu = uimenu(app.FileMenu);
            app.CloseallfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallfiguresMenuSelected, true);
            app.CloseallfiguresMenu.Separator = 'on';
            app.CloseallfiguresMenu.Text = 'Close all figures';
            app.CloseappMenu = uimenu(app.FileMenu);
            app.CloseappMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseappMenuSelected, true);
            app.CloseappMenu.Text = 'Close app';
            app.SimulationMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.RunMenu = uimenu(app.SimulationMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunMenu.Accelerator = 'R';
            app.RunMenu.Text = 'Run';
            app.PauseMenu = uimenu(app.SimulationMenu);
            app.PauseMenu.MenuSelectedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseMenu.Accelerator = 'P';
            app.PauseMenu.Text = 'Pause';
            app.ResetMenu = uimenu(app.SimulationMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetMenu.Accelerator = 'C';
            app.ResetMenu.Text = 'Reset';
            app.MovetostartMenu = uimenu(app.SimulationMenu);
            app.MovetostartMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonToStartPushed, true);
            app.MovetostartMenu.Separator = 'on';
            app.MovetostartMenu.Accelerator = '1';
            app.MovetostartMenu.Text = 'Move to start';
            app.BackstepMenu = uimenu(app.SimulationMenu);
            app.BackstepMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.BackstepMenu.Accelerator = '2';
            app.BackstepMenu.Text = 'Back step';
            app.ForwardstepMenu = uimenu(app.SimulationMenu);
            app.ForwardstepMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonFwdPushed, true);
            app.ForwardstepMenu.Accelerator = '3';
            app.ForwardstepMenu.Text = 'Forward step';
            app.MovetoendMenu = uimenu(app.SimulationMenu);
            app.MovetoendMenu.MenuSelectedFcn = createCallbackFcn(app, @ButtonToEndPushed, true);
            app.MovetoendMenu.Accelerator = '4';
            app.MovetoendMenu.Text = 'Move to end';
            app.SkiptotimeMenu = uimenu(app.SimulationMenu);
            app.SkiptotimeMenu.MenuSelectedFcn = createCallbackFcn(app, @SkiptotimeMenuSelected, true);
            app.SkiptotimeMenu.Accelerator = '5';
            app.SkiptotimeMenu.Text = 'Skip to time';
            app.PlotMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.PlotMenu.Text = 'Plot';
            app.KymographMenu = uimenu(app.PlotMenu);
            app.KymographMenu.MenuSelectedFcn = createCallbackFcn(app, @KymographMenuSelected, true);
            app.KymographMenu.Text = 'Kymograph';
            app.MeangeneexpressionptMenu = uimenu(app.PlotMenu);
            app.MeangeneexpressionptMenu.MenuSelectedFcn = createCallbackFcn(app, @MeangeneexpressionptMenuSelected, true);
            app.MeangeneexpressionptMenu.Text = 'Mean gene expression p(t)';
            app.PopulationfractionspijtbinaryMenu = uimenu(app.PlotMenu);
            app.PopulationfractionspijtbinaryMenu.MenuSelectedFcn = createCallbackFcn(app, @PopulationfractionspijtbinaryMenuSelected, true);
            app.PopulationfractionspijtbinaryMenu.Text = 'Population fractions pij(t) (binary)';
            app.SpatialautocorrelationItMenu = uimenu(app.PlotMenu);
            app.SpatialautocorrelationItMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialautocorrelationItMenuSelected, true);
            app.SpatialautocorrelationItMenu.Text = 'Spatial auto-correlation I(t)';
            app.SpatialautocorrelationThetatMenu = uimenu(app.PlotMenu);
            app.SpatialautocorrelationThetatMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialautocorrelationThetatMenuSelected, true);
            app.SpatialautocorrelationThetatMenu.Text = 'Spatial auto-correlation Theta(t)';
            app.SpatialcrosscorrelationMenu = uimenu(app.PlotMenu);
            app.SpatialcrosscorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialcrosscorrelationMenuSelected, true);
            app.SpatialcrosscorrelationMenu.Text = 'Spatial cross-correlation';
            app.DtrajectoryptItMenu = uimenu(app.PlotMenu);
            app.DtrajectoryptItMenu.MenuSelectedFcn = createCallbackFcn(app, @TrajectoryptItMenuSelected, true);
            app.DtrajectoryptItMenu.Text = '2D trajectory (p(t), I(t)) ';
            app.DtrajectoryptThetatMenu = uimenu(app.PlotMenu);
            app.DtrajectoryptThetatMenu.MenuSelectedFcn = createCallbackFcn(app, @TrajectoryptThetatMenuSelected, true);
            app.DtrajectoryptThetatMenu.Text = '2D trajectory (p(t), Theta(t))';
            app.PseudoenergyhtMenu = uimenu(app.PlotMenu);
            app.PseudoenergyhtMenu.MenuSelectedFcn = createCallbackFcn(app, @PseudoenergyhtMenuSelected, true);
            app.PseudoenergyhtMenu.Text = 'Pseudo-energy h(t)';
            app.OptionsMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ShowIMenu = uimenu(app.OptionsMenu);
            app.ShowIMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowIMenuSelected, true);
            app.ShowIMenu.Text = 'Show I';
            app.InitiatewithIt0Menu = uimenu(app.OptionsMenu);
            app.InitiatewithIt0Menu.MenuSelectedFcn = createCallbackFcn(app, @InitiatewithIt0MenuSelected, true);
            app.InitiatewithIt0Menu.Text = 'Initiate with I(t=0)';
            app.ManuallyinputinitialstateMenu = uimenu(app.OptionsMenu);
            app.ManuallyinputinitialstateMenu.MenuSelectedFcn = createCallbackFcn(app, @ManuallyinputinitialstateMenuSelected, true);
            app.ManuallyinputinitialstateMenu.Text = 'Manually input initial state';
            app.PropertiesMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.PropertiesMenu.Text = 'Properties';
            app.CircuittopologyMenu = uimenu(app.PropertiesMenu);
            app.CircuittopologyMenu.MenuSelectedFcn = createCallbackFcn(app, @CircuittopologyMenuSelected, true);
            app.CircuittopologyMenu.Text = 'Circuit topology';
            app.PhasediagramMenu = uimenu(app.PropertiesMenu);
            app.PhasediagramMenu.MenuSelectedFcn = createCallbackFcn(app, @PhasediagramMenuSelected, true);
            app.PhasediagramMenu.Text = 'Phase diagram';
            app.StatediagramsinglecellMenu = uimenu(app.PropertiesMenu);
            app.StatediagramsinglecellMenu.MenuSelectedFcn = createCallbackFcn(app, @StatediagramMenuSelected, true);
            app.StatediagramsinglecellMenu.Text = 'State diagram - single cell';
            app.StatediagramfullsystemMenu = uimenu(app.PropertiesMenu);
            app.StatediagramfullsystemMenu.MenuSelectedFcn = createCallbackFcn(app, @StatediagramfullsystemMenuSelected, true);
            app.StatediagramfullsystemMenu.Text = 'State diagram - full system';
            app.CellcolorlegendMenu = uimenu(app.PropertiesMenu);
            app.CellcolorlegendMenu.MenuSelectedFcn = createCallbackFcn(app, @CellcolorlegendMenuSelected, true);
            app.CellcolorlegendMenu.Text = 'Cell color legend';
            app.PeriodicitytestMenu = uimenu(app.PropertiesMenu);
            app.PeriodicitytestMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriodicitytestMenuSelected, true);
            app.PeriodicitytestMenu.Text = 'Periodicity test';
            app.HelpMenu = uimenu(app.MulticellularModelSimulatorUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.Version24Menu = uimenu(app.HelpMenu);
            app.Version24Menu.Enable = 'off';
            app.Version24Menu.Separator = 'on';
            app.Version24Menu.Text = 'Version 2.4';
            app.ButtonPlay = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ButtonPlay.Icon = 'app_icons_media-playback-start.png';
            app.ButtonPlay.Text = '';
            app.ButtonStop = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ButtonStop.Icon = 'app_icons_media-playback-stop.png';
            app.ButtonStop.Text = '';
            app.ButtonPause = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.ButtonPause.Icon = 'app_icons_media-playback-pause.png';
            app.ButtonPause.Text = '';
            app.TabGroup = uitabgroup(app.MulticellularModelSimulatorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.signalTab = uitab(app.TabGroup);
            app.signalTab.Title = '1 signal';
            app.GridsizeEditFieldLabel = uilabel(app.signalTab);
            app.GridsizeEditFieldLabel.Text = 'Gridsize';
            app.GridsizeEditField = uieditfield(app.signalTab, 'numeric');
            app.GridsizeEditField.Limits = [0 Inf];
            app.GridsizeEditField.RoundFractionalValues = 'on';
            app.GridsizeEditField.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GridsizeEditField.Value = 11;
            app.a0EditFieldLabel = uilabel(app.signalTab);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.signalTab, 'numeric');
            app.a0EditField.Limits = [0 Inf];
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.a0EditField.Value = 1.5;
            app.KEditFieldLabel = uilabel(app.signalTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.signalTab, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.KEditField.Value = 8;
            app.ConEditFieldLabel = uilabel(app.signalTab);
            app.ConEditFieldLabel.Text = 'Con';
            app.ConEditField = uieditfield(app.signalTab, 'numeric');
            app.ConEditField.Limits = [0 Inf];
            app.ConEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ConEditField.Value = 8;
            app.pt0EditFieldLabel = uilabel(app.signalTab);
            app.pt0EditFieldLabel.Text = 'p(t=0)';
            app.pt0EditField = uieditfield(app.signalTab, 'numeric');
            app.pt0EditField.Limits = [0 1];
            app.pt0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.pt0EditField.Value = 0.5;
            app.NoiseEditFieldLabel = uilabel(app.signalTab);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.signalTab, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.NoiseEditField.Value = 2;
            app.HillCoefficientEditFieldLabel = uilabel(app.signalTab);
            app.HillCoefficientEditFieldLabel.Text = 'Hill Coefficient';
            app.HillCoefficientEditField = uieditfield(app.signalTab, 'numeric');
            app.HillCoefficientEditField.Limits = [0 Inf];
            app.HillCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillCoefficientEditField.Value = 2;
            app.CellstatesSwitchLabel = uilabel(app.signalTab);
            app.CellstatesSwitchLabel.Text = 'Cell states';
            app.CellstatesSwitch = uiswitch(app.signalTab, 'slider');
            app.CellstatesSwitch.Items = {'Binary', 'Continuous'};
            app.CellstatesSwitch.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.CellstatesSwitch.Value = 'Binary';
            app.InitialstateLabel = uilabel(app.signalTab);
            app.InitialstateLabel.Text = 'Initial state';
            app.ButtonGroup = uibuttongroup(app.signalTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.BinaryButton = uitogglebutton(app.ButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.MCrandomizedButton = uitogglebutton(app.ButtonGroup);
            app.MCrandomizedButton.Text = 'MC randomized';
            app.MCrandomizedButton.Value = true;
            app.It0EditFieldLabel = uilabel(app.signalTab);
            app.It0EditFieldLabel.Text = 'I(t=0)';
            app.It0EditField = uieditfield(app.signalTab, 'numeric');
            app.It0EditField.Limits = [-1 1];
            app.It0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.InteractionDropDownLabel = uilabel(app.signalTab);
            app.InteractionDropDownLabel.Text = 'Interaction';
            app.InteractionDropDown = uidropdown(app.signalTab);
            app.InteractionDropDown.Items = {'Activation', 'Repression'};
            app.InteractionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.InteractionDropDown.Value = 'Activation';
            app.ContinuoussystemLabel = uilabel(app.signalTab);
            app.ContinuoussystemLabel.Text = 'Continuous system';
            app.signalsmultTab = uitab(app.TabGroup);
            app.signalsmultTab.Title = '2 signals mult.';
            app.GridsizeEditField_2Label = uilabel(app.signalsmultTab);
            app.GridsizeEditField_2Label.Text = 'Gridsize';
            app.GridsizeEditField_2 = uieditfield(app.signalsmultTab, 'numeric');
            app.GridsizeEditField_2.Limits = [0 Inf];
            app.GridsizeEditField_2.RoundFractionalValues = 'on';
            app.GridsizeEditField_2.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GridsizeEditField_2.Value = 15;
            app.a0EditField_2Label = uilabel(app.signalsmultTab);
            app.a0EditField_2Label.Text = 'a0';
            app.a0EditField_2 = uieditfield(app.signalsmultTab, 'numeric');
            app.a0EditField_2.Limits = [0 Inf];
            app.a0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.a0EditField_2.Value = 1.5;
            app.DiffusionconstratioEditFieldLabel = uilabel(app.signalsmultTab);
            app.DiffusionconstratioEditFieldLabel.Text = 'Diffusion const. ratio';
            app.DiffusionconstratioEditField = uieditfield(app.signalsmultTab, 'numeric');
            app.DiffusionconstratioEditField.Limits = [0 Inf];
            app.DiffusionconstratioEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DiffusionconstratioEditField.Value = 1.2;
            app.InteractionsPanel = uipanel(app.signalsmultTab);
            app.InteractionsPanel.Title = 'Interactions';
            app.K21EditFieldLabel = uilabel(app.InteractionsPanel);
            app.K21EditFieldLabel.Text = 'K21';
            app.K21EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K21EditField.Limits = [0 Inf];
            app.K21EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K21EditField.Value = 11;
            app.K11Label = uilabel(app.InteractionsPanel);
            app.K11Label.Text = 'K11';
            app.K11EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K11EditField.Limits = [0 Inf];
            app.K11EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K12Label = uilabel(app.InteractionsPanel);
            app.K12Label.Text = 'K12';
            app.K12EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K12EditField.Limits = [0 Inf];
            app.K12EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K12EditField.Value = 10;
            app.K22Label = uilabel(app.InteractionsPanel);
            app.K22Label.Text = 'K22';
            app.K22EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.K22EditField.Limits = [0 Inf];
            app.K22EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K22EditField.Value = 4;
            app.Label_2 = uilabel(app.InteractionsPanel);
            app.Label_2.Text = '1<-2';
            app.DropDown12 = uidropdown(app.InteractionsPanel);
            app.DropDown12.Items = {'Activation', 'Repression', 'None'};
            app.DropDown12.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown12.Value = 'Activation';
            app.Label_3 = uilabel(app.InteractionsPanel);
            app.Label_3.Text = '2<-1';
            app.DropDown21 = uidropdown(app.InteractionsPanel);
            app.DropDown21.Items = {'Activation', 'Repression', 'None'};
            app.DropDown21.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown21.Value = 'Repression';
            app.Label_4 = uilabel(app.InteractionsPanel);
            app.Label_4.Text = '2<-2';
            app.DropDown22 = uidropdown(app.InteractionsPanel);
            app.DropDown22.Items = {'Activation', 'Repression', 'None'};
            app.DropDown22.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown22.Value = 'Activation';
            app.Con1EditFieldLabel = uilabel(app.InteractionsPanel);
            app.Con1EditFieldLabel.Text = 'Con1';
            app.Con1EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.Con1EditField.Limits = [0 Inf];
            app.Con1EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con1EditField.Value = 18;
            app.Con2EditFieldLabel = uilabel(app.InteractionsPanel);
            app.Con2EditFieldLabel.Text = 'Con2';
            app.Con2EditField = uieditfield(app.InteractionsPanel, 'numeric');
            app.Con2EditField.Limits = [0 Inf];
            app.Con2EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con2EditField.Value = 16;
            app.SecretionratesLabel = uilabel(app.InteractionsPanel);
            app.SecretionratesLabel.Text = 'Secretion rates';
            app.Label = uilabel(app.InteractionsPanel);
            app.Label.Text = '1<-1';
            app.DropDown11 = uidropdown(app.InteractionsPanel);
            app.DropDown11.Items = {'Activation', 'Repression', 'None'};
            app.DropDown11.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown11.Value = 'None';
            app.p1t0EditFieldLabel = uilabel(app.signalsmultTab);
            app.p1t0EditFieldLabel.Text = 'p1(t=0)';
            app.p1t0EditField = uieditfield(app.signalsmultTab, 'numeric');
            app.p1t0EditField.Limits = [0 1];
            app.p1t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p1t0EditField.Value = 0.5;
            app.p2t0EditFieldLabel = uilabel(app.signalsmultTab);
            app.p2t0EditFieldLabel.Text = 'p2(t=0)';
            app.p2t0EditField = uieditfield(app.signalsmultTab, 'numeric');
            app.p2t0EditField.Limits = [0 1];
            app.p2t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p2t0EditField.Value = 0.5;
            app.NoiseEditField_2Label = uilabel(app.signalsmultTab);
            app.NoiseEditField_2Label.Text = 'Noise';
            app.NoiseEditField_2 = uieditfield(app.signalsmultTab, 'numeric');
            app.NoiseEditField_2.Limits = [0 Inf];
            app.NoiseEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillCoeffEditFieldLabel = uilabel(app.signalsmultTab);
            app.HillCoeffEditFieldLabel.Text = 'Hill Coeff.';
            app.HillCoeffEditField = uieditfield(app.signalsmultTab, 'numeric');
            app.HillCoeffEditField.Limits = [0 Inf];
            app.HillCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillCoeffEditField.Value = Inf;
            app.DisplaysignalButtonGroup = uibuttongroup(app.signalsmultTab);
            app.DisplaysignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaysignalButtonGroupSelectionChanged, true);
            app.DisplaysignalButtonGroup.Title = 'Display signal';
            app.Button1 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button1.Text = '1';
            app.Button2 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button2.Text = '2';
            app.Button12 = uitogglebutton(app.DisplaysignalButtonGroup);
            app.Button12.Text = 'Both';
            app.Button12.Value = true;
            app.MultiplicativeinteractionANDLabel = uilabel(app.signalsmultTab);
            app.MultiplicativeinteractionANDLabel.Text = 'Multiplicative interaction (AND)';
            app.InitialconditionsLabel_3 = uilabel(app.signalsmultTab);
            app.InitialconditionsLabel_3.Text = 'Initial conditions';
            app.I1t0EditFieldLabel = uilabel(app.signalsmultTab);
            app.I1t0EditFieldLabel.Text = 'I1(t=0)';
            app.I1t0EditField = uieditfield(app.signalsmultTab, 'numeric');
            app.I1t0EditField.Limits = [-1 1];
            app.I1t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.I2t0EditFieldLabel = uilabel(app.signalsmultTab);
            app.I2t0EditFieldLabel.Text = 'I2(t=0)';
            app.I2t0EditField = uieditfield(app.signalsmultTab, 'numeric');
            app.I2t0EditField.Limits = [-1 1];
            app.I2t0EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.signalsaddTab = uitab(app.TabGroup);
            app.signalsaddTab.Title = '2 signals add';
            app.DisplaysignalButtonGroup_2 = uibuttongroup(app.signalsaddTab);
            app.DisplaysignalButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DisplaysignalButtonGroupSelectionChanged, true);
            app.DisplaysignalButtonGroup_2.Title = 'Display signal';
            app.Button1_2 = uitogglebutton(app.DisplaysignalButtonGroup_2);
            app.Button1_2.Text = '1';
            app.Button2_2 = uitogglebutton(app.DisplaysignalButtonGroup_2);
            app.Button2_2.Text = '2';
            app.Button12_2 = uitogglebutton(app.DisplaysignalButtonGroup_2);
            app.Button12_2.Text = 'Both';
            app.Button12_2.Value = true;
            app.AdditiveinteractionORLabel = uilabel(app.signalsaddTab);
            app.AdditiveinteractionORLabel.Text = 'Additive interaction (OR)';
            app.GridsizeEditField_3Label = uilabel(app.signalsaddTab);
            app.GridsizeEditField_3Label.Text = 'Gridsize';
            app.GridsizeEditField_3 = uieditfield(app.signalsaddTab, 'numeric');
            app.GridsizeEditField_3.Limits = [0 Inf];
            app.GridsizeEditField_3.RoundFractionalValues = 'on';
            app.GridsizeEditField_3.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField_3.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.GridsizeEditField_3.Value = 15;
            app.a0EditField_3Label = uilabel(app.signalsaddTab);
            app.a0EditField_3Label.Text = 'a0';
            app.a0EditField_3 = uieditfield(app.signalsaddTab, 'numeric');
            app.a0EditField_3.Limits = [0 Inf];
            app.a0EditField_3.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.a0EditField_3.Value = 1.5;
            app.NoiseEditField_3Label = uilabel(app.signalsaddTab);
            app.NoiseEditField_3Label.Text = 'Noise';
            app.NoiseEditField_3 = uieditfield(app.signalsaddTab, 'numeric');
            app.NoiseEditField_3.Limits = [0 Inf];
            app.NoiseEditField_3.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillCoeffEditField_2Label = uilabel(app.signalsaddTab);
            app.HillCoeffEditField_2Label.Text = 'Hill Coeff.';
            app.HillCoeffEditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.HillCoeffEditField_2.Limits = [0 Inf];
            app.HillCoeffEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.HillCoeffEditField_2.Value = Inf;
            app.DiffusionconstratioEditField_2Label = uilabel(app.signalsaddTab);
            app.DiffusionconstratioEditField_2Label.Text = 'Diffusion const. ratio';
            app.DiffusionconstratioEditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.DiffusionconstratioEditField_2.Limits = [0 Inf];
            app.DiffusionconstratioEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DiffusionconstratioEditField_2.Value = 1.2;
            app.InteractionsPanel_2 = uipanel(app.signalsaddTab);
            app.InteractionsPanel_2.Title = 'Interactions';
            app.K21EditField_2Label = uilabel(app.InteractionsPanel_2);
            app.K21EditField_2Label.Text = 'K21';
            app.K21EditField_2 = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.K21EditField_2.Limits = [0 Inf];
            app.K21EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K21EditField_2.Value = 35;
            app.K11EditField_2Label = uilabel(app.InteractionsPanel_2);
            app.K11EditField_2Label.Text = 'K11';
            app.K11EditField_2 = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.K11EditField_2.Limits = [0 Inf];
            app.K11EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K11EditField_2.Value = 8;
            app.K12EditField_2Label = uilabel(app.InteractionsPanel_2);
            app.K12EditField_2Label.Text = 'K12';
            app.K12EditField_2 = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.K12EditField_2.Limits = [0 Inf];
            app.K12EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.K12EditField_2.Value = 25;
            app.K22EditField_2Label = uilabel(app.InteractionsPanel_2);
            app.K22EditField_2Label.Text = 'K22';
            app.K22EditField_2 = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.K22EditField_2.Limits = [0 Inf];
            app.K22EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Label_5 = uilabel(app.InteractionsPanel_2);
            app.Label_5.Text = '1<-2';
            app.DropDown12_2 = uidropdown(app.InteractionsPanel_2);
            app.DropDown12_2.Items = {'Activation', 'Repression', 'None'};
            app.DropDown12_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown12_2.Value = 'Repression';
            app.Label_6 = uilabel(app.InteractionsPanel_2);
            app.Label_6.Text = '2<-1';
            app.DropDown21_2 = uidropdown(app.InteractionsPanel_2);
            app.DropDown21_2.Items = {'Activation', 'Repression', 'None'};
            app.DropDown21_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown21_2.Value = 'Activation';
            app.Label_7 = uilabel(app.InteractionsPanel_2);
            app.Label_7.Text = '2<-2';
            app.DropDown22_2 = uidropdown(app.InteractionsPanel_2);
            app.DropDown22_2.Items = {'Activation', 'Repression', 'None'};
            app.DropDown22_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown22_2.Value = 'None';
            app.Label_8 = uilabel(app.InteractionsPanel_2);
            app.Label_8.Text = '1<-1';
            app.DropDown11_2 = uidropdown(app.InteractionsPanel_2);
            app.DropDown11_2.Items = {'Activation', 'Repression', 'None'};
            app.DropDown11_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.DropDown11_2.Value = 'Activation';
            app.Con11EditFieldLabel = uilabel(app.InteractionsPanel_2);
            app.Con11EditFieldLabel.Text = 'Con11';
            app.Con11EditField = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.Con11EditField.Limits = [0 Inf];
            app.Con11EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con11EditField.Value = 35;
            app.Con21EditFieldLabel = uilabel(app.InteractionsPanel_2);
            app.Con21EditFieldLabel.Text = 'Con21';
            app.Con21EditField = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.Con21EditField.Limits = [0 Inf];
            app.Con21EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con21EditField.Value = 35;
            app.Con22EditFieldLabel = uilabel(app.InteractionsPanel_2);
            app.Con22EditFieldLabel.Text = 'Con22';
            app.Con22EditField = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.Con22EditField.Limits = [0 Inf];
            app.Con22EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con12EditFieldLabel = uilabel(app.InteractionsPanel_2);
            app.Con12EditFieldLabel.Text = 'Con12';
            app.Con12EditField = uieditfield(app.InteractionsPanel_2, 'numeric');
            app.Con12EditField.Limits = [0 Inf];
            app.Con12EditField.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Con12EditField.Value = 15;
            app.p1t0EditField_2Label = uilabel(app.signalsaddTab);
            app.p1t0EditField_2Label.Text = 'p1(t=0)';
            app.p1t0EditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.p1t0EditField_2.Limits = [0 1];
            app.p1t0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p1t0EditField_2.Value = 0.2;
            app.p2t0EditField_2Label = uilabel(app.signalsaddTab);
            app.p2t0EditField_2Label.Text = 'p2(t=0)';
            app.p2t0EditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.p2t0EditField_2.Limits = [0 1];
            app.p2t0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.p2t0EditField_2.Value = 0.6;
            app.InitialconditionsLabel_4 = uilabel(app.signalsaddTab);
            app.InitialconditionsLabel_4.Text = 'Initial conditions';
            app.I1t0EditField_2Label = uilabel(app.signalsaddTab);
            app.I1t0EditField_2Label.Text = 'I1(t=0)';
            app.I1t0EditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.I1t0EditField_2.Limits = [-1 1];
            app.I1t0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.I2t0EditField_2Label = uilabel(app.signalsaddTab);
            app.I2t0EditField_2Label.Text = 'I2(t=0)';
            app.I2t0EditField_2 = uieditfield(app.signalsaddTab, 'numeric');
            app.I2t0EditField_2.Limits = [-1 1];
            app.I2t0EditField_2.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.MessagesTextAreaLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages ';
            app.MessagesTextArea = uitextarea(app.MulticellularModelSimulatorUIFigure);
            app.ButtonToStart = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonToStart.ButtonPushedFcn = createCallbackFcn(app, @ButtonToStartPushed, true);
            app.ButtonToStart.Icon = 'app_icons_media-skip-backward.png';
            app.ButtonToStart.Text = '';
            app.ButtonBack = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = 'app_icons_media-seek-backward.png';
            app.ButtonBack.Text = '';
            app.ButtonFwd = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonFwd.ButtonPushedFcn = createCallbackFcn(app, @ButtonFwdPushed, true);
            app.ButtonFwd.Icon = 'app_icons_media-seek-forward.png';
            app.ButtonFwd.Text = '';
            app.ButtonToEnd = uibutton(app.MulticellularModelSimulatorUIFigure, 'push');
            app.ButtonToEnd.ButtonPushedFcn = createCallbackFcn(app, @ButtonToEndPushed, true);
            app.ButtonToEnd.Icon = 'app_icons_media-skip-forward.png';
            app.ButtonToEnd.Text = '';
            app.UIAxes = uiaxes(app.MulticellularModelSimulatorUIFigure);
            app.SpeedSlider = uislider(app.MulticellularModelSimulatorUIFigure);
            app.SpeedSlider.Limits = [1 20];
            app.SpeedSlider.MajorTicks = [1 20];
            app.SpeedSlider.MajorTickLabels = {'Low', 'High'};
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 5;
            app.SimulationspeedLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.SimulationspeedLabel.Text = 'Simulation speed';
            app.RandomizedLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.RandomizedLabel.Text = 'Randomized';
            app.LatticeLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.LatticeLabel.Text = 'Lattice';
            app.MCstepsDropDownLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.MCstepsDropDownLabel.Text = 'MC steps';
            app.MCstepsDropDown = uidropdown(app.MulticellularModelSimulatorUIFigure);
            app.MCstepsDropDown.Items = {'0', '10', '100', '1000', '10000'};
            app.MCstepsDropDown.Editable = 'on';
            app.MCstepsDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.MCstepsDropDown.Value = '0';
            app.CellSizeEditFieldLabel = uilabel(app.MulticellularModelSimulatorUIFigure);
            app.CellSizeEditFieldLabel.Text = 'Cell Size';
            app.CellSizeEditField = uieditfield(app.MulticellularModelSimulatorUIFigure, 'numeric');
            app.CellSizeEditField.Limits = [0.01 0.5];
            app.CellSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CellSizeEditFieldValueChanged, true);
            app.CellSizeEditField.Value = 0.2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_R2_4_v4_R2018a_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MulticellularModelSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MulticellularModelSimulatorUIFigure)
        end
    end
end