classdef time_evolution_app_R1_0_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        RunButton                      matlab.ui.control.Button
        GridsizeEditFieldLabel         matlab.ui.control.Label
        GridsizeEditField              matlab.ui.control.NumericEditField
        a0EditFieldLabel               matlab.ui.control.Label
        a0EditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel                matlab.ui.control.Label
        KEditField                     matlab.ui.control.NumericEditField
        ConEditFieldLabel              matlab.ui.control.Label
        ConEditField                   matlab.ui.control.NumericEditField
        InitialpEditFieldLabel         matlab.ui.control.Label
        InitialpEditField              matlab.ui.control.NumericEditField
        NoiseEditFieldLabel            matlab.ui.control.Label
        NoiseEditField                 matlab.ui.control.NumericEditField
        HillcoefficientEditFieldLabel  matlab.ui.control.Label
        HillcoefficientEditField       matlab.ui.control.NumericEditField
        CellstatesSwitchLabel          matlab.ui.control.Label
        CellstatesSwitch               matlab.ui.control.Switch
        StopButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        ContinueSimulation = 1; %When set to 0, simulation stops
    end

    methods (Access = private)

        function update_cell_figure(app, pos, a0, cells, t, I, InitFlag)
            ax = app.UIAxes; % axes
            
            % clear figure and hold on to keep the graphics
            %clf(app.UIFigure,'reset');
            %title(ax, sprintf('$$t=%d, N_{ON} = %d, I=%.2f$$',...
            %    t, sum(cells), I),'FontSize', 24, 'Interpreter', 'latex');
            if ~InitFlag
                title(ax, sprintf('t=%d, Non = %d', t, sum(cells)), ...
                'FontSize', 24);
            else 
                title(ax, 'Simulate dynamics')
            end
            set(ax,'YTick',[],'XTick',[]);
                
            %box on
            %hold on
            
            % set the diameter of the graphics (unitary)
            diameter = 0.5;
            r = diameter/2;
            curv = [1 1]; % ignore cell type for now
            
            N = size(pos, 1);
            face_clr_all = repmat(1-cells, 1, 3);
            position_all = a0.*[pos-r diameter*ones(N,1) diameter*ones(N,1)];
            
            for i = 1:size(pos,1)
                %switch cell_type(i)
                %    case 0
                %        curv = [1 1];
                %    otherwise
                %        curv = [0 0];
                %end
                
                %if cells(i) == 0
                %    r = 0.914;
                %    g = 0.914;
                %    b = 0.914;
                %    face_clr = [r g b];
                %else
                %    face_clr = 'k';
                %end
               
                position = position_all(i,:); 
                face_clr = face_clr_all(i,:);
                % draw the rectangle in the figure with handle hin
                rectangle(ax, 'Position', position, 'FaceColor', face_clr, ...
                    'EdgeColor', 'k', 'Curvature', curv);
            end
            
            % set hold off and draw figure
            %hold off
            drawnow;
        end
        
        function update_cell_figure_continuum(app, pos, a0, cells, t, I)
            ax = app.UIAxes;
            
            % clear figure and hold on to keep the graphics
            %clf(hin,'reset');
            title(ax, sprintf('t=%d, p = %.2f', t, mean(cells)), ...
                'FontSize', 24);
            %title(sprintf('Noff = %d, Non =  %d,  Time: %d', sum(~cells), sum(cells), t), ...
            %    'FontSize', 24, 'Interpreter', 'latex');
            set(ax,'YTick',[],'XTick',[]);
            
            % set the diameter of the graphics (unitary)
            diameter = 0.5;
            r = diameter/2;
            curv = [1 1]; %ignore cell type
            
            N = size(pos, 1);
            face_clr_all = repmat(1-cells, 1, 3);
            position_all = a0.*[pos-r diameter*ones(N,1) diameter*ones(N,1)];
            for i = 1:size(pos,1)
                %switch cell_type(i)
                %    case 0
                %        curv = [1 1];
                %    otherwise
                %        curv = [0 0];
                %end
                
                % greyscale, cells(i) = Xi indicates greyness
                face_clr = face_clr_all(i, :);
                position = position_all(i, :);
                
                % draw the rectangle in the figure with handle hin
                rectangle(ax, 'Position', position, 'FaceColor', face_clr, ...
                    'EdgeColor', 'k', 'Curvature', curv);
            end
            
            % set hold off and draw figure
            drawnow;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ContinueSimulation = 1;
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            ax = app.UIAxes; %figure handle
            cla(ax);
            
            % define variables from input
            gz = app.GridsizeEditField.Value;
            a0 = app.a0EditField.Value;
            
            N = gz^2;
            [dist, pos] = init_dist_hex(gz, gz);
            %cell_type = zeros(N, 1);

            % binary system
            cells = zeros(N,1);
            
            time = 0;
            this_I = 0;

            cells_hist = {};
            cells_hist{end+1} = cells;
            
            update_cell_figure(app, pos, a0, cells_hist{time+1}, time, this_I, 1); 
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ContinueSimulation = 1;
            %cd C:\Users\Yiteng\Documents\Multicellularity\simulation_app
            ax = app.UIAxes; %figure handle
            cla(ax);
            
            % define variables from input
            gz = app.GridsizeEditField.Value;
            a0 = app.a0EditField.Value;
            K = app.KEditField.Value;
            Con = app.ConEditField.Value;
            noise = app.NoiseEditField.Value;
            hill = app.HillcoefficientEditField.Value;
            p = app.InitialpEditField.Value;
            N = gz^2;
            Rcell = 0.2*a0;
            [dist, pos] = init_dist_hex(gz, gz);
            %cell_type = zeros(N, 1);
            
            % simulation
            switch app.CellstatesSwitch.Value % distinguish between binary and continuous system
                case app.CellstatesSwitch.Items(1)
                    % binary system
                    iniON = round(p*N);
                    cells = zeros(N,1);
                    cells(randperm(N,iniON)) = 1;
                    
                    t = 0;
                    tmax = 1000;
                    this_I = 0;
                    %update_cell_figure(app, pos, a0, cells, t, this_I);
        
                    %I = [];
                    %Non = [];
                    cells_hist = {};
                    cells_hist{end+1} = cells;
                    %Non(end+1) = sum(cells);
                    %this_I = moranI(cells, a0*dist);
                    %I(end+1) = this_I;  
                    [cells_out, changed] = update_cells_noise(cells, dist, Con, K, a0, Rcell, noise);
                    while changed && t < tmax && app.ContinueSimulation
                        %pause(0.2);
                        t = t+1;
                        %update_cell_figure(app, pos, a0, cells_out, t, this_I);
                        cells_hist{end+1} = cells_out;
                        %this_I = moranI(cells_out, a0*dist);
                        %I(end+1) = this_I; 
                        %Non(end+1) = sum(cells_out);
                        cells = cells_out;
                        [cells_out, changed] = update_cells_noise(cells, dist, Con, K, a0, Rcell, noise);
                    end
                    
                    time=0;
                    while app.ContinueSimulation && time < t+1
                        update_cell_figure(app, pos, a0, cells_hist{time+1}, time, this_I, 0); 
                        time = time+1;
                    end
                    
                case app.CellstatesSwitch.Items(2)
                    % Continuous system
                    iniON = round(p*N);
                    cells = zeros(N,1);
                    cells(randperm(N,iniON)) = 1;
                    
                    t = 0;
                    tmax = 1000;
                    prec = 8; %precision for comparing two states 
                    this_I = 0;
                    update_cell_figure_continuum(app, pos, a0, cells, t, this_I);
        
                    %I = [];
                    %Non = [];
                    cells_hist = {};
                    cells_hist{end+1} = cells;
                    %Non(end+1) = sum(cells);
                    %this_I = moranI(cells, a0*dist);
                    %I(end+1) = this_I;
                    
                    [cells_out, changed] = update_cells_noise_hill(...
                        cells, dist, Con, K, a0, Rcell, noise, hill, prec);
            
                    while changed && t < tmax && app.ContinueSimulation
                        t = t+1;
                        update_cell_figure_continuum(app, pos, a0, cells_out, t, this_I);
                        cells_hist{end+1} = cells_out;
                        %this_I = moranI(cells_out, a0*dist);
                        %I(end+1) = this_I; 
                        %Non(end+1) = sum(cells_out);
                        cells = cells_out;
                        [cells_out, changed] = update_cells_noise_hill(...
                            cells, dist, Con, K, a0, Rcell, noise, hill, prec);
                    end
                    
                    %for time=0:t
                        %pause(1);
                    %    update_cell_figure_continuum(app, pos, a0, cells_hist{time+1}, time, this_I);
                    %end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.ContinueSimulation = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulate dynamics')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GridsizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridsizeEditFieldLabel.Text = 'Gridsize';
            app.GridsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridsizeEditField.Limits = [0 Inf];
            app.GridsizeEditField.RoundFractionalValues = 'on';
            app.GridsizeEditField.ValueDisplayFormat = '%.0f';
            app.GridsizeEditField.Value = 11;
            app.a0EditFieldLabel = uilabel(app.UIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.UIFigure, 'numeric');
            app.a0EditField.Limits = [0 Inf];
            app.a0EditField.Value = 0.5;
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.Value = 16;
            app.ConEditFieldLabel = uilabel(app.UIFigure);
            app.ConEditFieldLabel.Text = 'Con';
            app.ConEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConEditField.Limits = [0 Inf];
            app.ConEditField.Value = 8;
            app.InitialpEditFieldLabel = uilabel(app.UIFigure);
            app.InitialpEditFieldLabel.Text = 'Initial p';
            app.InitialpEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialpEditField.Limits = [0 1];
            app.InitialpEditField.Value = 0.5;
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.NoiseEditField.Value = 1;
            app.HillcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.HillcoefficientEditFieldLabel.Text = 'Hill coefficient';
            app.HillcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.HillcoefficientEditField.Limits = [0 Inf];
            app.HillcoefficientEditField.Value = 2;
            app.CellstatesSwitchLabel = uilabel(app.UIFigure);
            app.CellstatesSwitchLabel.Text = 'Cell states';
            app.CellstatesSwitch = uiswitch(app.UIFigure, 'slider');
            app.CellstatesSwitch.Items = {'Binary', 'Continuous'};
            app.CellstatesSwitch.Value = 'Binary';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = time_evolution_app_R1_0_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end